classdef EphysDLCApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ComfirmallButton                matlab.ui.control.Button
        RemoveallButton                 matlab.ui.control.Button
        SetRemoveButton                 matlab.ui.control.Button
        RemoveEditField2                matlab.ui.control.EditField
        RemoveEditField1                matlab.ui.control.EditField
        AutoFillStartButton             matlab.ui.control.Button
        RemovethispointButton           matlab.ui.control.Button
        ConfirmthispointButton          matlab.ui.control.Button
        CamviewDropDown                 matlab.ui.control.DropDown
        CamviewDropDownLabel            matlab.ui.control.Label
        SkipButton                      matlab.ui.control.Button
        BodypartsDropDown               matlab.ui.control.DropDown
        BodypartsDropDown_2Label        matlab.ui.control.Label
        ManualtrackButton               matlab.ui.control.Button
        HighestEditField                matlab.ui.control.EditField
        HighestEditFieldLabel           matlab.ui.control.Label
        StartEditField                  matlab.ui.control.EditField
        StartEditFieldLabel             matlab.ui.control.Label
        HandButtonGroup                 matlab.ui.container.ButtonGroup
        BothButton                      matlab.ui.control.RadioButton
        RightButton                     matlab.ui.control.RadioButton
        LeftButton                      matlab.ui.control.RadioButton
        FillHighestButton               matlab.ui.control.Button
        FillStartButton                 matlab.ui.control.Button
        PerformanceEditField            matlab.ui.control.EditField
        PerformanceEditFieldLabel       matlab.ui.control.Label
        NextvideoButton                 matlab.ui.control.Button
        SavedatatomatButton             matlab.ui.control.Button
        FramenumEditField               matlab.ui.control.EditField
        FramenumEditFieldLabel          matlab.ui.control.Label
        MetafileEditField               matlab.ui.control.EditField
        MetafileEditFieldLabel          matlab.ui.control.Label
        VidnameEditField                matlab.ui.control.EditField
        VidnameEditFieldLabel           matlab.ui.control.Label
        LoadvideoButton                 matlab.ui.control.Button
        CenterPanel                     matlab.ui.container.Panel
        Next10FramesButton              matlab.ui.control.Button
        Previous10FramesButton          matlab.ui.control.Button
        NextButton                      matlab.ui.control.Button
        PreviousButton                  matlab.ui.control.Button
        Slider                          matlab.ui.control.Slider
        UIAxes_likelihood               matlab.ui.control.UIAxes
        UIAxes_image                    matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        InterpolateButton               matlab.ui.control.Button
        SetButton                       matlab.ui.control.Button
        InterpolaterangeEditFieldEnd    matlab.ui.control.EditField
        InterpolaterangeEditFieldStart  matlab.ui.control.EditField
        InterpolaterangeEditFieldLabel  matlab.ui.control.Label
        ShowVideoButton                 matlab.ui.control.Button
        UIAxes_y                        matlab.ui.control.UIAxes
        UIAxes_x                        matlab.ui.control.UIAxes
        UIAxes_points                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        ind_frame
        ind_bodypart
        VideoInfo
        vfilename
        metaname
        isGoodTracking
        vidObj

        count_interpolate = 0;
        count_remove = 0;
    end
    
    methods (Access = private)
        
        function saveData(app)
           if app.StartEditField.Visible
               if ~isempty(app.StartEditField.Value) && ~strcmp(app.StartEditField.Value,'NaN')
                    app.VideoInfo.LiftStartFrameNum = str2double(app.StartEditField.Value);
                    app.VideoInfo.LiftStartTime = app.VideoInfo.VideoFrameTime(app.VideoInfo.LiftStartFrameNum);
               else
                   app.VideoInfo.LiftStartFrameNum = NaN;
                   app.VideoInfo.LiftStartTime = NaN;
               end
           end
           if app.HighestEditField.Visible
               if ~isempty(app.HighestEditField.Value) && ~strcmp(app.HighestEditField.Value,'NaN')
                    app.VideoInfo.LiftHighestFrameNum = str2double(app.HighestEditField.Value);
                    app.VideoInfo.LiftHighestTime = app.VideoInfo.VideoFrameTime(app.VideoInfo.LiftHighestFrameNum);
               else
                    app.VideoInfo.LiftHighestFrameNum = NaN;
                    app.VideoInfo.LiftHighestTime = NaN;
               end
           end
           if app.HandButtonGroup.Visible
                app.VideoInfo.Hand = app.HandButtonGroup.SelectedObject.Text;
           end
           app.VideoInfo.isGoodTracking = app.isGoodTracking;

           VideoInfo = app.VideoInfo;
           save(app.MetafileEditField.Value,"VideoInfo");            
        end
        
        function refreshIndframeChanged(app)
            app.FramenumEditField.Value =  num2str(app.ind_frame);
            app.Slider.Value = app.ind_frame;
            
            this_frame = app.vidObj.read(app.ind_frame);
            app.UIAxes_image.UserData{3}.CData = this_frame;
            
            x_pos   =   app.VideoInfo.Tracking.Coordinates_x{app.ind_bodypart};
            y_pos   =   app.VideoInfo.Tracking.Coordinates_y{app.ind_bodypart};
            lh      =   app.VideoInfo.Tracking.Coordinates_p{app.ind_bodypart};
%             t_frames =  posdata(:, 4);


            if lh(app.ind_frame) < 0.8
                set(app.UIAxes_image.UserData{2}, 'XData', x_pos(app.ind_frame), 'YData', y_pos(app.ind_frame), 'color', 'r' )
                set(app.UIAxes_likelihood.UserData{2}, 'XData', app.ind_frame, 'YData', lh(app.ind_frame), 'color', 'r')
            else
                set(app.UIAxes_image.UserData{2}, 'XData', x_pos(app.ind_frame), 'YData', y_pos(app.ind_frame), 'color', 'c' )
                set(app.UIAxes_likelihood.UserData{2}, 'XData', app.ind_frame, 'YData', lh(app.ind_frame), 'color', 'c')
            end

            set(app.UIAxes_points.UserData{1}, 'XData', x_pos(app.ind_frame), 'YData', y_pos(app.ind_frame), 'marker', '+', 'markersize', 15, 'linewidth', 2)
            set(app.UIAxes_x.UserData{2}, 'XData', app.ind_frame, 'YData', x_pos(app.ind_frame))
            set(app.UIAxes_y.UserData{2}, 'XData', app.ind_frame, 'YData', y_pos(app.ind_frame))            
        end
        
        function refreshIndbodypartChanged(app)
            lh = app.VideoInfo.Tracking.Coordinates_p{app.ind_bodypart};
            x_pos = app.VideoInfo.Tracking.Coordinates_x{app.ind_bodypart};
            y_pos = app.VideoInfo.Tracking.Coordinates_y{app.ind_bodypart};
            
            % plot first frame on the video axis
%             cla(app.UIAxes_image)
            
            this_frame = app.vidObj.read(app.ind_frame);
%             app.FramenumEditField.Value =  num2str(app.ind_frame);
%             
%             vidpos = app.UIAxes_image.Position;
%             height = vidpos(3);
%             newwidth = height*size(this_frame, 1)/size(this_frame, 2);
%             vidpos(4) = newwidth;
%             disp(size(this_frame))
%             set(app.UIAxes_image,'position', vidpos,  'nextplot', 'add',...
%                 'ylim',[0 size(this_frame, 1)], 'xlim', [0 size(this_frame, 2)], 'ydir','reverse')
%             
%             app.UIAxes_image.UserData{3} = image(app.UIAxes_image, this_frame);
            
            % Likelihood
            cla(app.UIAxes_likelihood)
            app.UIAxes_likelihood.UserData{1} = plot(app.UIAxes_likelihood, lh, 'ko-');
            set(app.UIAxes_likelihood, 'ylim', [-0.2 1.2])
            yline(app.UIAxes_likelihood, 0.8, 'color', 'g', 'linestyle', ':')
            set(app.UIAxes_likelihood, 'nextplot', 'add')
            
            app.UIAxes_likelihood.XLabel.String = 'Frame #';
            app.UIAxes_likelihood.YLabel.String = 'Likelihood';
            
            if lh(app.ind_frame) < 0.8
                set(app.UIAxes_image.UserData{2}, 'XData', x_pos(app.ind_frame), 'YData', y_pos(app.ind_frame), 'color', 'r' )
                app.UIAxes_likelihood.UserData{2} = plot(app.UIAxes_likelihood, app.ind_frame, lh(app.ind_frame), 'mo', 'markersize', 6, 'markerfacecolor', 'm', 'linewidth', 1);
            else
                set(app.UIAxes_image.UserData{2}, 'XData', x_pos(app.ind_frame), 'YData', y_pos(app.ind_frame), 'color', 'c' )
                app.UIAxes_likelihood.UserData{2} = plot(app.UIAxes_likelihood, app.ind_frame, lh(app.ind_frame), 'mo', 'markersize', 6 , 'markerfacecolor', 'm','linewidth', 1);
            end

            % plot pose in ha_traj
            
            cla(app.UIAxes_points)
            set(app.UIAxes_points, 'nextplot', 'add')
            
            for i =1:length(lh)
                if lh(i)>0.8
                    plot(app.UIAxes_points, x_pos(i), y_pos(i), 'c+', 'linewidth', 1)
                else
                    plot(app.UIAxes_points, x_pos(i), y_pos(i), 'k+', 'linewidth', 1)
                end
            end
            
            app.UIAxes_points.UserData{1} = plot(app.UIAxes_points, x_pos(app.ind_frame), y_pos(app.ind_frame), 'm+', 'markersize', 15,'markerfacecolor', 'm', 'linewidth', 2);
            
            trajpos = app.UIAxes_points.Position;
            
            width = trajpos(3);
            newheight = width*size(this_frame, 2)/size(this_frame, 1);
            trajpos(4) = newheight;
            
            app.UIAxes_points.Position = trajpos;
%             app.UIAxes_points.XLim = [100 900];
%             app.UIAxes_points.YLim = [100 800];
            % plot pose  x vs t
            cla(app.UIAxes_x)
            app.UIAxes_x.UserData = [];
            set(app.UIAxes_x, 'nextplot', 'add')
            app.UIAxes_x.UserData{1} = plot(app.UIAxes_x, x_pos, 'ko');
            app.UIAxes_x.YLabel.String = 'X Position';
            app.UIAxes_x.XLabel.String = 'Frames';
            app.UIAxes_x.UserData{2} = plot(app.UIAxes_x, app.ind_frame, x_pos(app.ind_frame), 'm+', 'markersize', 15, 'linewidth', 2);
%             app.UIAxes_x.XLim    = [t_frames(1) t_frames(end)];
            
            
            % plot pose  y vs t
            cla(app.UIAxes_y)
            app.UIAxes_y.UserData = [];
            set(app.UIAxes_y, 'nextplot', 'add')
            app.UIAxes_y.UserData{1} = plot(app.UIAxes_y, y_pos, 'ko');
            app.UIAxes_y.YLabel.String = 'Y Position';
            app.UIAxes_y.XLabel.String = 'Frames';
            app.UIAxes_y.UserData{2} = plot(app.UIAxes_y, app.ind_frame, y_pos(app.ind_frame),  'm+', 'markersize', 15, 'linewidth', 2);
%             app.UIAxes_y.XLim    = [t_frames(1) t_frames(end)];          
        end
        
        function initialization(app)  
            app.VidnameEditField.Value = app.vfilename;
            app.MetafileEditField.Value = app.metaname;
            
            lh = app.VideoInfo.Tracking.Coordinates_p{app.ind_bodypart};
            x_pos = app.VideoInfo.Tracking.Coordinates_x{app.ind_bodypart};
            y_pos = app.VideoInfo.Tracking.Coordinates_y{app.ind_bodypart};
            
            n_allframes = app.VideoInfo.total_frames;
            app.Slider.UserData{1} = n_allframes;
            app.PerformanceEditField.Value = app.VideoInfo.Performance;
            
            if isfield(app.VideoInfo,'LiftStartFrameNum')
                app.StartEditField.Value = num2str(app.VideoInfo.LiftStartFrameNum);
            else
                app.StartEditField.Value = '';
            end
            
            if isfield(app.VideoInfo,'LiftHighestFrameNum')
                app.HighestEditField.Value = num2str(app.VideoInfo.LiftHighestFrameNum);
            else
                app.HighestEditField.Value = '';
            end

            if isfield(app.VideoInfo,'Hand')
                if strcmp(app.VideoInfo.Hand,'Left')
                    app.LeftButton.Value = 1;
                elseif strcmp(app.VideoInfo.Hand,'Right')
                    app.RightButton.Value = 1;
                else
                    app.BothButton.Value = 1;
                end
            else
                app.LeftButton.Value = 0;
                app.RightButton.Value = 0;
                app.BothButton.Value = 0;
            end

            app.InterpolaterangeEditFieldStart.Value = '';
            app.InterpolaterangeEditFieldEnd.Value = '';

%             % plot first frame on the video axis
            cla(app.UIAxes_image)
%             
            this_video = app.vfilename;
            vidObj = VideoReader(this_video);
            
            this_frame = vidObj.read(1);
%             tic
%             AllFrames = read(vidObj, [1, n_allframes]);
%             app.UIAxes_image.UserData{1} = AllFrames;
%             toc
%             
%             this_frame = AllFrames(:, :, :, app.ind_frame);
%             app.FramenumEditField.Value =  num2str(app.ind_frame);
%             
%             
%             vidpos = app.UIAxes_image.Position;
%             height = vidpos(3);
%             newwidth = height*size(this_frame, 1)/size(this_frame, 2);
%             vidpos(4) = newwidth;
%             disp(size(this_frame))
%             set(app.UIAxes_image,'position', vidpos,  'nextplot', 'add',...
%                 'ylim',[0 size(this_frame, 1)], 'xlim', [0 size(this_frame, 2)], 'ydir','reverse')
%             
%             app.UIAxes_image.UserData{3} = image(app.UIAxes_image, this_frame);
            
            % Likelihood
            cla(app.UIAxes_likelihood)
            app.UIAxes_likelihood.UserData{1} = plot(app.UIAxes_likelihood, lh, 'ko-');
            set(app.UIAxes_likelihood, 'ylim', [-0.2 1.2])
            yline(app.UIAxes_likelihood, 0.8, 'color', 'g', 'linestyle', ':')
            set(app.UIAxes_likelihood, 'nextplot', 'add')
            
            app.UIAxes_likelihood.XLabel.String = 'Frame #';
            app.UIAxes_likelihood.YLabel.String = 'Likelihood';
            
            if lh(app.ind_frame) < 0.8
%                 app.UIAxes_image.UserData{2} = plot(app.UIAxes_image, x_pos(app.ind_frame), y_pos(app.ind_frame), 'ro', 'markersize', 6, 'linewidth', 1);
                app.UIAxes_likelihood.UserData{2} = plot(app.UIAxes_likelihood, app.ind_frame, lh(app.ind_frame), 'mo', 'markersize', 6, 'markerfacecolor', 'm', 'linewidth', 1);
            else
%                 app.UIAxes_image.UserData{2} = plot(app.UIAxes_image, x_pos(app.ind_frame), y_pos(app.ind_frame), 'co', 'markersize', 6, 'linewidth', 1);
                app.UIAxes_likelihood.UserData{2} = plot(app.UIAxes_likelihood, app.ind_frame, lh(app.ind_frame), 'mo', 'markersize', 6 , 'markerfacecolor', 'm','linewidth', 1);
            end
            
            
            % update slider data
            app.Slider.Limits = [1 n_allframes];
            app.Slider.Value = app.ind_frame;
            
            % dropdown
            app.BodypartsDropDown.Items = app.VideoInfo.Tracking.BodyParts;
            app.BodypartsDropDown.Value = app.VideoInfo.Tracking.BodyParts{app.ind_bodypart};
            
            % plot pose in ha_traj
            
            cla(app.UIAxes_points)
            set(app.UIAxes_points, 'nextplot', 'add')
            
            for i =1:length(lh)
                if lh(i)>0.8
                    plot(app.UIAxes_points, x_pos(i), y_pos(i), 'c+', 'linewidth', 1)
                else
                    plot(app.UIAxes_points, x_pos(i), y_pos(i), 'k+', 'linewidth', 1)
                end
            end
            
            app.UIAxes_points.UserData{1} = plot(app.UIAxes_points, x_pos(app.ind_frame), y_pos(app.ind_frame), 'm+', 'markersize', 15,'markerfacecolor', 'm', 'linewidth', 2);
            
            trajpos = app.UIAxes_points.Position;
            
            width = trajpos(3);
            newheight = width*size(this_frame, 2)/size(this_frame, 1);
            trajpos(4) = newheight;
            
            app.UIAxes_points.Position = trajpos;
%             app.UIAxes_points.XLim = [100 900];
%             app.UIAxes_points.YLim = [100 800];
            % plot pose  x vs t
            cla(app.UIAxes_x)
            app.UIAxes_x.UserData = [];
            set(app.UIAxes_x, 'nextplot', 'add')
            app.UIAxes_x.UserData{1} = plot(app.UIAxes_x, x_pos, 'ko');
            app.UIAxes_x.YLabel.String = 'X Position';
            app.UIAxes_x.XLabel.String = 'Frames';
            app.UIAxes_x.UserData{2} = plot(app.UIAxes_x, app.ind_frame, x_pos(app.ind_frame), 'm+', 'markersize', 15, 'linewidth', 2);
%             app.UIAxes_x.XLim    = [t_frames(1) t_frames(end)];
            
            
            % plot pose  y vs t
            cla(app.UIAxes_y)
            app.UIAxes_y.UserData = [];
            set(app.UIAxes_y, 'nextplot', 'add')
            app.UIAxes_y.UserData{1} = plot(app.UIAxes_y, y_pos, 'ko');
            app.UIAxes_y.YLabel.String = 'Y Position';
            app.UIAxes_y.XLabel.String = 'Frames';
            app.UIAxes_y.UserData{2} = plot(app.UIAxes_y, app.ind_frame, y_pos(app.ind_frame),  'm+', 'markersize', 15, 'linewidth', 2);
%             app.UIAxes_y.XLim    = [t_frames(1) t_frames(end)];
            
%             % axis_traj
%             cla(app.UIAxes_traj)
% %             app.UIAxes6.XLim = [100 900];
% %             app.UIAxes6.YLim = [100 800];
%             app.UIAxes_traj.NextPlot = 'Add';            
            
        end
        function showVideo(app)
            n_allframes = app.VideoInfo.total_frames;
            % plot first frame on the video axis
            cla(app.UIAxes_image)
            
            this_video = app.vfilename;
            app.vidObj = VideoReader(this_video);
            
            tic
%             AllFrames = read(vidObj, [1, n_allframes]);
%             app.UIAxes_image.UserData{1} = AllFrames;
            toc       
            
%             this_frame = AllFrames(:, :, :, app.ind_frame);
            this_frame = app.vidObj.read(app.ind_frame);
            app.FramenumEditField.Value =  num2str(app.ind_frame);
            
            
            vidpos = app.UIAxes_image.Position;
            height = vidpos(3);
            newwidth = height*size(this_frame, 1)/size(this_frame, 2);
            vidpos(4) = newwidth;
            disp(size(this_frame))
            set(app.UIAxes_image,'position', vidpos,  'nextplot', 'add',...
                'ylim',[0 size(this_frame, 1)], 'xlim', [0 size(this_frame, 2)], 'ydir','reverse')
            
            app.UIAxes_image.UserData{3} = image(app.UIAxes_image, this_frame);
            
            x_pos   =   app.VideoInfo.Tracking.Coordinates_x{app.ind_bodypart};
            y_pos   =   app.VideoInfo.Tracking.Coordinates_y{app.ind_bodypart};
            lh      =   app.VideoInfo.Tracking.Coordinates_p{app.ind_bodypart};

            if lh(app.ind_frame) < 0.8
                app.UIAxes_image.UserData{2} = plot(app.UIAxes_image, x_pos(app.ind_frame), y_pos(app.ind_frame), 'ro', 'markersize', 6, 'linewidth', 1);
            else
                app.UIAxes_image.UserData{2} = plot(app.UIAxes_image, x_pos(app.ind_frame), y_pos(app.ind_frame), 'co', 'markersize', 6, 'linewidth', 1);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if contains(version,'R2022b')
                app.UIAxes_image.Position(2) = app.UIAxes_image.Position(2)-140;
            end
        end

        % Button pushed function: LoadvideoButton
        function LoadvideoButtonPushed(app, event)
            set(app.UIFigure,'renderer','opengl')
            
            % open selection
            [vfilename, ~] = uigetfile('RawVideo/*.avi', 'Select a file') ;
            meta_filename = ['MatFile/',vfilename(1:end-4),'.mat'];
            vfilename = fullfile('RawVideo/',vfilename);

            app.vfilename = vfilename;
            app.metaname = meta_filename;
            
            load(app.metaname);
            % Global Data
            app.ind_bodypart = 1;
            app.ind_frame = 1;            
            app.VideoInfo = VideoInfo;
            app.isGoodTracking = true;
            
            app.initialization();
            app.showVideo();
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;

            clc
            app.ind_frame = round(value);
            if app.count_interpolate == 2
                app.InterpolaterangeEditFieldStart.Value = num2str(app.ind_frame);
                app.count_interpolate = 1;
            elseif app.count_remove == 1
                app.InterpolaterangeEditFieldEnd.Value = num2str(app.ind_frame);
                app.count_interpolate = 0;
            end

            if app.count_remove == 2
                app.RemoveEditField1.Value = num2str(app.ind_frame);
                app.count_remove = 1;
            elseif app.count_interpolate == 1
                app.RemoveEditField2.Value = num2str(app.ind_frame);
                app.count_remove = 0;
            end

            app.refreshIndframeChanged();
            
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            verticalScrollCount = event.VerticalScrollCount;
            value = app.Slider.Value;
            ind_this = round(value+verticalScrollCount);
            if ind_this > app.Slider.UserData{1}
                ind_this = app.Slider.UserData{1};
            elseif ind_this <= 0
                ind_this = 1;
            end
            app.ind_frame = ind_this;
            app.refreshIndframeChanged();  
        end

        % Callback function
        function SelectrangeButtonPushed(app, event)
            % select the rannge
            rect = getrect(app.UIAxes2);
            
            posdata = app.BodypartsDropDown.UserData;
            
            x_pos   =   posdata(:, 1);
            y_pos   =   posdata(:, 2);
            lh      =   posdata(:, 3);
            frs     =   (1:length(lh))';
            ind_chosen = find(frs>=rect(1) & frs<=sum(rect([1 3])) & lh>=rect(2) & lh <= sum(rect([2 4])));
            
            if isempty(app.SelectrangeButton.UserData)
                app.SelectrangeButton.UserData{1} = ind_chosen;
                app.SelectrangeButton.UserData{2} = plot(app.UIAxes2, frs(ind_chosen), lh(ind_chosen), 'g.', 'markersize', 5);
                app.UIAxes_points.UserData{2} = plot(app.UIAxes_points, x_pos(ind_chosen), y_pos(ind_chosen), 'go', 'markersize', 4);
            else
                app.SelectrangeButton.UserData{1} = ind_chosen;
                set(app.SelectrangeButton.UserData{2}, 'XData', frs(ind_chosen), 'YData', lh(ind_chosen));
                set(app.UIAxes_points.UserData{2}, 'XData',  x_pos(ind_chosen), 'YData', y_pos(ind_chosen))
            end
            
            %             %% plot trajectory
            %
            %             t_chosen = 1:length(ind_chosen);
            %             x_chosen = x_pos(ind_chosen);
            %             y_chosen = y_pos(ind_chosen);
            %
            %             tnew = resample(t_chosen, 10, 1);
            %             x_chosennew = interp1(t_chosen, x_chosen, tnew);
            %             y_chosennew = interp1(t_chosen, y_chosen, tnew);
            %             app.UIAxes_points.UserData{3} = plot(app.UIAxes_points, x_chosennew, y_chosennew, 'color', [1 0.8 0.2]);
            
        end

        % Button pushed function: FillStartButton
        function FillStartButtonPushed(app, event)
            app.StartEditField.Value = num2str(app.ind_frame);
        end

        % Callback function
        function FillEndButtonPushed(app, event)

        end

        % Callback function
        function PlottrajectoryButtonPushed(app, event)

        end

        % Button pushed function: SavedatatomatButton
        function SavedatatomatButtonPushed(app, event)
            app.saveData();
        end

        % Button pushed function: NextvideoButton
        function NextvideoButtonPushed(app, event)
            app.saveData();
           
            [~,name,ext] = fileparts(app.vfilename);
            temp_vfilename = [name,ext];
            AllFiles = dir('RawVideo/*Press*.avi');
            AllVidFiles=arrayfun(@(x)x.name, AllFiles, 'UniformOutput', false);
            current_indx = find(strcmp(AllVidFiles,temp_vfilename));
            
            if current_indx == length(AllVidFiles)
                disp('Done!')
                return
            end
            
            next_indx = current_indx + 1;
            
            temp_filename = AllVidFiles{next_indx};
            vfilename = fullfile('RawVideo/',temp_filename);
            meta_filename = fullfile('MatFile/',[temp_filename(1:end-4),'.mat']);
            
            load(meta_filename)
            while ~strcmp(VideoInfo.Performance,'Correct')
                next_indx = next_indx + 1;
                if next_indx > length(AllVidFiles)
                    disp('Done!')
                    return
                end
                temp_filename = AllVidFiles{next_indx};
                vfilename = fullfile('RawVideo/',temp_filename);
                meta_filename = fullfile('MatFile/',[temp_filename(1:end-4),'.mat']);
                load(meta_filename)
            end

            app.vfilename = vfilename;
            app.metaname = meta_filename;            
            
            load(app.metaname);
            % Global Data
            app.ind_frame = 1;            
            app.VideoInfo = VideoInfo;
            app.isGoodTracking = true;
            
            app.initialization();
            app.showVideo();
        end

        % Button pushed function: FillHighestButton
        function FillHighestButtonPushed(app, event)
            app.HighestEditField.Value = num2str(app.ind_frame);
        end

        % Callback function
        function FillLiftButtonPushed(app, event)
            app.LiftEditField.Value = num2str(app.ind_frame);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            value = app.Slider.Value;
            ind_this = round(value+1);
            if ind_this > app.Slider.UserData{1}
                ind_this = app.Slider.UserData{1};
            end
            app.ind_frame = ind_this;
            app.refreshIndframeChanged();
        end

        % Button pushed function: Next10FramesButton
        function Next10FramesButtonPushed(app, event)
            value = app.Slider.Value;
            ind_this = round(value+10);
            if ind_this > app.Slider.UserData{1}
                ind_this = app.Slider.UserData{1};
            end
            app.ind_frame = ind_this;
            app.refreshIndframeChanged();
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            value = app.Slider.Value;
            ind_this = round(value-1);
            if ind_this <= 0
                ind_this = 1;
            end
            app.ind_frame = ind_this;
            app.refreshIndframeChanged();
        end

        % Button pushed function: Previous10FramesButton
        function Previous10FramesButtonPushed(app, event)
            value = app.Slider.Value;
            ind_this = round(value-10);
            if ind_this <= 0
                ind_this = 1;
            end
            app.ind_frame = ind_this;
            app.refreshIndframeChanged();
        end

        % Button pushed function: ManualtrackButton
        function ManualtrackButtonPushed(app, event)
            % change the location of body part
            
            % draw point on UIAxes
            h = drawpoint(app.UIAxes_image,  'Visible', 'off'); % user-defined body location

            x_pos   =   app.VideoInfo.Tracking.Coordinates_x{app.ind_bodypart};
            y_pos   =   app.VideoInfo.Tracking.Coordinates_y{app.ind_bodypart};
            lh      =   app.VideoInfo.Tracking.Coordinates_p{app.ind_bodypart};

            x_pos(app.ind_frame) = h.Position(1);
            y_pos(app.ind_frame) = h.Position(2);
            lh(app.ind_frame)    = 1;
            
            app.VideoInfo.Tracking.Coordinates_x{app.ind_bodypart} = x_pos;
            app.VideoInfo.Tracking.Coordinates_y{app.ind_bodypart} = y_pos;
            app.VideoInfo.Tracking.Coordinates_p{app.ind_bodypart} = lh;
            
            app.refreshIndbodypartChanged();        
        end

        % Value changed function: BodypartsDropDown
        function BodypartsDropDownValueChanged(app, event)
            value = app.BodypartsDropDown.Value;
            app.ind_bodypart = find(strcmp(app.VideoInfo.Tracking.BodyParts,value));
            app.refreshIndbodypartChanged();
        end

        % Button pushed function: SkipButton
        function SkipButtonPushed(app, event)
            app.isGoodTracking = false;
            app.saveData();
            
            [~,name,ext] = fileparts(app.vfilename);
            temp_vfilename = [name,ext];
            AllFiles = dir('RawVideo/*Press*.avi');
            AllVidFiles=arrayfun(@(x)x.name, AllFiles, 'UniformOutput', false);
            current_indx = find(strcmp(AllVidFiles,temp_vfilename));
            
            if current_indx == length(AllVidFiles)
                disp('Done!')
                return
            end
            
            next_indx = current_indx + 1;
            
            temp_filename = AllVidFiles{next_indx};
            vfilename = fullfile('RawVideo/',temp_filename);
            meta_filename = fullfile('MatFile/',[temp_filename(1:end-4),'.mat']);
            
            load(meta_filename)
            while ~strcmp(VideoInfo.Performance,'Correct')
                next_indx = next_indx + 1;
                if next_indx > length(AllVidFiles)
                    disp('Done!')
                    return
                end
                temp_filename = AllVidFiles{next_indx};
                vfilename = fullfile('RawVideo/',temp_filename);
                meta_filename = fullfile('MatFile/',[temp_filename(1:end-4),'.mat']);
                load(meta_filename)
            end

            app.vfilename = vfilename;
            app.metaname = meta_filename;            
            
            load(app.metaname);
            % Global Data
            app.ind_bodypart = 1;
            app.ind_frame = 1;            
            app.VideoInfo = VideoInfo;
            app.isGoodTracking = true;
            
            app.initialization();
            app.showVideo();
        end

        % Value changed function: CamviewDropDown
        function CamviewDropDownValueChanged(app, event)
            value = app.CamviewDropDown.Value;
            if strcmp(value,'top')
                app.StartEditField.Visible = false;
                app.StartEditFieldLabel.Visible = false;
                app.HighestEditField.Visible = false;
                app.HighestEditFieldLabel.Visible = false;
                app.FillStartButton.Visible = false;
                app.AutoFillStartButton.Visible = false;
                app.FillHighestButton.Visible = false;
                app.HandButtonGroup.Visible = false;
            elseif strcmp(value,'side')
                app.StartEditField.Visible = true;
                app.StartEditFieldLabel.Visible = true;
                app.HighestEditField.Visible = true;
                app.HighestEditFieldLabel.Visible = true;  
                app.FillStartButton.Visible = true;
                app.AutoFillStartButton.Visible = true;
                app.FillHighestButton.Visible = true;         
                app.HandButtonGroup.Visible = true;
            end
        end

        % Button pushed function: ConfirmthispointButton
        function ConfirmthispointButtonPushed(app, event)
            lh      =   app.VideoInfo.Tracking.Coordinates_p{app.ind_bodypart};
            lh(app.ind_frame)    = 1;
            app.VideoInfo.Tracking.Coordinates_p{app.ind_bodypart} = lh;
            
            app.refreshIndbodypartChanged();
        end

        % Button pushed function: ShowVideoButton
        function ShowVideoButtonPushed(app, event)
            app.showVideo();
        end

        % Button pushed function: RemovethispointButton
        function RemovethispointButtonPushed(app, event)
            lh      =   app.VideoInfo.Tracking.Coordinates_p{app.ind_bodypart};
            lh(app.ind_frame)    = 0;
            app.VideoInfo.Tracking.Coordinates_p{app.ind_bodypart} = lh;
            
            app.refreshIndbodypartChanged();            
        end

        % Button pushed function: AutoFillStartButton
        function AutoFillStartButtonPushed(app, event)
            idx_press = round(-app.VideoInfo.t_pre/10);
            X = app.VideoInfo.Tracking.Coordinates_x{app.ind_bodypart}(1:idx_press);
            Y = app.VideoInfo.Tracking.Coordinates_y{app.ind_bodypart}(1:idx_press);
            app.StartEditField.Value = num2str(getLiftStartFrameAuto(X,Y));
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            c = event.Character;
            if c == ' '
                app.NextButtonPushed();
            elseif c == 'a'
                app.PreviousButtonPushed();            
            elseif c == 's'
                app.ConfirmthispointButtonPushed();
            elseif c == 'd'
                app.NextButtonPushed();
            elseif c == 'w'
                app.ManualtrackButtonPushed();
            elseif c == 'q'
                app.RemovethispointButtonPushed();
            elseif c == 'f'
                app.NextvideoButtonPushed();
            end
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            app.count_interpolate = 2;
        end

        % Button pushed function: InterpolateButton
        function InterpolateButtonPushed(app, event)
            frame_start = str2double(app.InterpolaterangeEditFieldStart.Value);
            frame_end = str2double(app.InterpolaterangeEditFieldEnd.Value);
            if frame_start > frame_end
                temp = frame_start;
                frame_start = frame_end;
                frame_end = temp;
            end

            if isempty(frame_start) || isempty(frame_end) || isnan(frame_start) || isnan(frame_end)
                return
            end

            x_pos   =   app.VideoInfo.Tracking.Coordinates_x{app.ind_bodypart};
            y_pos   =   app.VideoInfo.Tracking.Coordinates_y{app.ind_bodypart};
            lh      =   app.VideoInfo.Tracking.Coordinates_p{app.ind_bodypart};

            lh_raw = lh;

            % keep the frames outside unchanged
            if frame_start > 1
                lh(1:frame_start-1) = 1;
            end
            if frame_end < length(lh)
                lh(frame_end+1:end) = 1;
            end

            t = 1:length(x_pos);
            if size(x_pos,2) == 1
                t = t';
            end

            idx_good = find(lh>=0.8);
            x_pos_good = x_pos(idx_good);
            y_pos_good = y_pos(idx_good);
            t_good = t(idx_good);

            x_pos = interp1(t_good, x_pos_good, t);
            y_pos = interp1(t_good, y_pos_good, t);
            lh(frame_start:frame_end) = 1;

            % keep the frames outside unchanged
            if frame_start > 1
                lh(1:frame_start-1) = lh_raw(1:frame_start-1);
            end
            if frame_end < length(lh)
                lh(frame_end+1:end) = lh_raw(frame_end+1:end);
            end

            app.VideoInfo.Tracking.Coordinates_x{app.ind_bodypart} = x_pos;
            app.VideoInfo.Tracking.Coordinates_y{app.ind_bodypart} = y_pos;
            app.VideoInfo.Tracking.Coordinates_p{app.ind_bodypart} = lh;
            
            app.refreshIndbodypartChanged();
        end

        % Button down function: UIAxes_likelihood
        function UIAxes_likelihoodButtonDown(app, event)
            value = event.IntersectionPoint(1);
            if value<app.UIAxes_likelihood.XLim(1) || value>app.UIAxes_likelihood.XLim(2) 
                return
            end

            app.ind_frame = round(value);
            if app.count_interpolate == 2
                app.InterpolaterangeEditFieldStart.Value = num2str(app.ind_frame);
                app.count_interpolate = 1;
            elseif app.count_interpolate == 1
                app.InterpolaterangeEditFieldEnd.Value = num2str(app.ind_frame);
                app.count_interpolate = 0;
            end

            if app.count_remove == 2
                app.RemoveEditField1.Value = num2str(app.ind_frame);
                app.count_remove = 1;
            elseif app.count_remove == 1
                app.RemoveEditField2.Value = num2str(app.ind_frame);
                app.count_remove = 0;
            end

            app.refreshIndframeChanged();
        end

        % Button pushed function: SetRemoveButton
        function SetRemoveButtonPushed(app, event)
            app.count_remove = 2;
        end

        % Button pushed function: RemoveallButton
        function RemoveallButtonPushed(app, event)
            frame_start = str2double(app.RemoveEditField1.Value);
            frame_end = str2double(app.RemoveEditField2.Value);
            if frame_start > frame_end
                temp = frame_start;
                frame_start = frame_end;
                frame_end = temp;
            end

            if isempty(frame_start) || isempty(frame_end) || isnan(frame_start) || isnan(frame_end)
                return
            end

            lh      =   app.VideoInfo.Tracking.Coordinates_p{app.ind_bodypart};
            lh(frame_start:frame_end) = 0;

            app.VideoInfo.Tracking.Coordinates_p{app.ind_bodypart} = lh;
            
            app.refreshIndbodypartChanged();
        end

        % Button pushed function: ComfirmallButton
        function ComfirmallButtonPushed(app, event)
            frame_start = str2double(app.RemoveEditField1.Value);
            frame_end = str2double(app.RemoveEditField2.Value);
            if frame_start > frame_end
                temp = frame_start;
                frame_start = frame_end;
                frame_end = temp;
            end

            if isempty(frame_start) || isempty(frame_end) || isnan(frame_start) || isnan(frame_end)
                return
            end

            lh      =   app.VideoInfo.Tracking.Coordinates_p{app.ind_bodypart};
            lh(frame_start:frame_end) = 1;

            app.VideoInfo.Tracking.Coordinates_p{app.ind_bodypart} = lh;
            
            app.refreshIndbodypartChanged();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {766, 766, 766};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {766, 766};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {247, '1x', 332};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadvideoButton = uibutton(app.LeftPanel, 'push');
            app.LoadvideoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadvideoButtonPushed, true);
            app.LoadvideoButton.Text = 'Load video';
            app.VidnameEditFieldLabel = uilabel(app.LeftPanel);
            app.VidnameEditFieldLabel.Tag = 'vidname';
            app.VidnameEditFieldLabel.Text = 'Vid name';
            app.VidnameEditField = uieditfield(app.LeftPanel, 'text');
            app.VidnameEditField.Tag = 'vidfilename';
            app.MetafileEditFieldLabel = uilabel(app.LeftPanel);
            app.MetafileEditFieldLabel.Text = 'Meta file';
            app.MetafileEditField = uieditfield(app.LeftPanel, 'text');
            app.FramenumEditFieldLabel = uilabel(app.LeftPanel);
            app.FramenumEditFieldLabel.Text = {'Frame num'; ''};
            app.FramenumEditField = uieditfield(app.LeftPanel, 'text');
            app.FramenumEditField.Tag = 'framenum';
            app.SavedatatomatButton = uibutton(app.LeftPanel, 'push');
            app.SavedatatomatButton.ButtonPushedFcn = createCallbackFcn(app, @SavedatatomatButtonPushed, true);
            app.SavedatatomatButton.Text = {'Save data to mat'; ''};
            app.NextvideoButton = uibutton(app.LeftPanel, 'push');
            app.NextvideoButton.ButtonPushedFcn = createCallbackFcn(app, @NextvideoButtonPushed, true);
            app.NextvideoButton.Text = 'Next video';
            app.PerformanceEditFieldLabel = uilabel(app.LeftPanel);
            app.PerformanceEditFieldLabel.Text = 'Performance';
            app.PerformanceEditField = uieditfield(app.LeftPanel, 'text');
            app.PerformanceEditField.Tag = 'DLCfile';
            app.FillStartButton = uibutton(app.LeftPanel, 'push');
            app.FillStartButton.ButtonPushedFcn = createCallbackFcn(app, @FillStartButtonPushed, true);
            app.FillStartButton.Visible = 'off';
            app.FillStartButton.Text = 'Fill Start';
            app.FillHighestButton = uibutton(app.LeftPanel, 'push');
            app.FillHighestButton.ButtonPushedFcn = createCallbackFcn(app, @FillHighestButtonPushed, true);
            app.FillHighestButton.Visible = 'off';
            app.FillHighestButton.Text = 'Fill Highest';
            app.HandButtonGroup = uibuttongroup(app.LeftPanel);
            app.HandButtonGroup.Title = 'Hand';
            app.HandButtonGroup.Visible = 'off';
            app.LeftButton = uiradiobutton(app.HandButtonGroup);
            app.LeftButton.Text = 'Left';
            app.LeftButton.Value = true;
            app.RightButton = uiradiobutton(app.HandButtonGroup);
            app.RightButton.Text = 'Right';
            app.BothButton = uiradiobutton(app.HandButtonGroup);
            app.BothButton.Text = 'Both';
            app.StartEditFieldLabel = uilabel(app.LeftPanel);
            app.StartEditFieldLabel.Visible = 'off';
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.LeftPanel, 'text');
            app.StartEditField.Visible = 'off';
            app.HighestEditFieldLabel = uilabel(app.LeftPanel);
            app.HighestEditFieldLabel.Visible = 'off';
            app.HighestEditFieldLabel.Text = 'Highest';
            app.HighestEditField = uieditfield(app.LeftPanel, 'text');
            app.HighestEditField.Visible = 'off';
            app.ManualtrackButton = uibutton(app.LeftPanel, 'push');
            app.ManualtrackButton.ButtonPushedFcn = createCallbackFcn(app, @ManualtrackButtonPushed, true);
            app.ManualtrackButton.Text = 'Manual track';
            app.BodypartsDropDown_2Label = uilabel(app.LeftPanel);
            app.BodypartsDropDown_2Label.Text = 'Body parts';
            app.BodypartsDropDown = uidropdown(app.LeftPanel);
            app.BodypartsDropDown.Items = {};
            app.BodypartsDropDown.ValueChangedFcn = createCallbackFcn(app, @BodypartsDropDownValueChanged, true);
            app.BodypartsDropDown.Tag = 'BodyParts';
            app.BodypartsDropDown.Value = {};
            app.SkipButton = uibutton(app.LeftPanel, 'push');
            app.SkipButton.ButtonPushedFcn = createCallbackFcn(app, @SkipButtonPushed, true);
            app.SkipButton.Text = 'Skip';
            app.CamviewDropDownLabel = uilabel(app.LeftPanel);
            app.CamviewDropDownLabel.Text = 'Camview';
            app.CamviewDropDown = uidropdown(app.LeftPanel);
            app.CamviewDropDown.Items = {'top', 'side'};
            app.CamviewDropDown.ValueChangedFcn = createCallbackFcn(app, @CamviewDropDownValueChanged, true);
            app.CamviewDropDown.Tag = 'BodyParts';
            app.CamviewDropDown.Value = 'top';
            app.ConfirmthispointButton = uibutton(app.LeftPanel, 'push');
            app.ConfirmthispointButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmthispointButtonPushed, true);
            app.ConfirmthispointButton.Text = 'Confirm this point';
            app.RemovethispointButton = uibutton(app.LeftPanel, 'push');
            app.RemovethispointButton.ButtonPushedFcn = createCallbackFcn(app, @RemovethispointButtonPushed, true);
            app.RemovethispointButton.Text = 'Remove this point';
            app.AutoFillStartButton = uibutton(app.LeftPanel, 'push');
            app.AutoFillStartButton.ButtonPushedFcn = createCallbackFcn(app, @AutoFillStartButtonPushed, true);
            app.AutoFillStartButton.Visible = 'off';
            app.AutoFillStartButton.Text = 'Auto';
            app.RemoveEditField1 = uieditfield(app.LeftPanel, 'text');
            app.RemoveEditField2 = uieditfield(app.LeftPanel, 'text');
            app.SetRemoveButton = uibutton(app.LeftPanel, 'push');
            app.SetRemoveButton.ButtonPushedFcn = createCallbackFcn(app, @SetRemoveButtonPushed, true);
            app.SetRemoveButton.Text = 'Set';
            app.RemoveallButton = uibutton(app.LeftPanel, 'push');
            app.RemoveallButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveallButtonPushed, true);
            app.RemoveallButton.Text = 'Remove all';
            app.ComfirmallButton = uibutton(app.LeftPanel, 'push');
            app.ComfirmallButton.ButtonPushedFcn = createCallbackFcn(app, @ComfirmallButtonPushed, true);
            app.ComfirmallButton.Text = 'Comfirm all';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes_image = uiaxes(app.CenterPanel);
            app.UIAxes_image.YDir = 'reverse';
            app.UIAxes_image.XTick = [];
            app.UIAxes_image.YTick = [];
            app.UIAxes_likelihood = uiaxes(app.CenterPanel);
            title(app.UIAxes_likelihood, {'Tracking quality'; ''})
            xlabel(app.UIAxes_likelihood, 'FrameNum')
            ylabel(app.UIAxes_likelihood, 'Likelihood')
            app.UIAxes_likelihood.ButtonDownFcn = createCallbackFcn(app, @UIAxes_likelihoodButtonDown, true);
            app.UIAxes_likelihood.Tag = 'LabelingQuality';
            app.Slider = uislider(app.CenterPanel);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Tag = 'videoslider';
            app.PreviousButton = uibutton(app.CenterPanel, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.CenterPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.Previous10FramesButton = uibutton(app.CenterPanel, 'push');
            app.Previous10FramesButton.ButtonPushedFcn = createCallbackFcn(app, @Previous10FramesButtonPushed, true);
            app.Previous10FramesButton.Text = 'Previous 10 Frames';
            app.Next10FramesButton = uibutton(app.CenterPanel, 'push');
            app.Next10FramesButton.ButtonPushedFcn = createCallbackFcn(app, @Next10FramesButtonPushed, true);
            app.Next10FramesButton.Text = 'Next 10 Frames';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_points = uiaxes(app.RightPanel);
            app.UIAxes_points.YDir = 'reverse';
            app.UIAxes_points.XTick = [];
            app.UIAxes_points.YTick = [];
            app.UIAxes_points.Tag = 'ha_traj';
            app.UIAxes_x = uiaxes(app.RightPanel);
            ylabel(app.UIAxes_x, 'X')
            app.UIAxes_x.XTick = [];
            app.UIAxes_x.YTick = [];
            app.UIAxes_y = uiaxes(app.RightPanel);
            xlabel(app.UIAxes_y, 'FrameNum')
            ylabel(app.UIAxes_y, 'Y')
            app.UIAxes_y.XTick = [];
            app.UIAxes_y.YTick = [];
            app.ShowVideoButton = uibutton(app.RightPanel, 'push');
            app.ShowVideoButton.ButtonPushedFcn = createCallbackFcn(app, @ShowVideoButtonPushed, true);
            app.ShowVideoButton.Text = 'Show Video';
            app.InterpolaterangeEditFieldLabel = uilabel(app.RightPanel);
            app.InterpolaterangeEditFieldLabel.Tag = 'vidname';
            app.InterpolaterangeEditFieldLabel.Text = 'Interpolate range';
            app.InterpolaterangeEditFieldStart = uieditfield(app.RightPanel, 'text');
            app.InterpolaterangeEditFieldStart.Tag = 'vidfilename';
            app.InterpolaterangeEditFieldEnd = uieditfield(app.RightPanel, 'text');
            app.InterpolaterangeEditFieldEnd.Tag = 'vidfilename';
            app.SetButton = uibutton(app.RightPanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.InterpolateButton = uibutton(app.RightPanel, 'push');
            app.InterpolateButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolateButtonPushed, true);
            app.InterpolateButton.Text = 'Interpolate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EphysDLCApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end