classdef EphysDLCAppTwoViews < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EphysDLCAppTwoViewsUIFigure     matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        NewversionvideoCheckBox         matlab.ui.control.CheckBox
        FrameDirectoryEditField         matlab.ui.control.EditField
        SavethisframeButton             matlab.ui.control.Button
        SavetoButton                    matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        BodypartsDropDown_2Label        matlab.ui.control.Label
        BodypartsDropDown               matlab.ui.control.DropDown
        EndLabel                        matlab.ui.control.Label
        EndEditField                    matlab.ui.control.EditField
        StartEditFieldLabel             matlab.ui.control.Label
        StartEditField                  matlab.ui.control.EditField
        PerformanceEditFieldLabel       matlab.ui.control.Label
        PerformanceEditField            matlab.ui.control.EditField
        FramenumEditFieldLabel          matlab.ui.control.Label
        FramenumEditField               matlab.ui.control.EditField
        MetafileEditFieldLabel          matlab.ui.control.Label
        MetafileEditField               matlab.ui.control.EditField
        VidnameEditFieldLabel           matlab.ui.control.Label
        VidnameEditField                matlab.ui.control.EditField
        LoadvideoButton                 matlab.ui.control.Button
        SavedatatomatButton             matlab.ui.control.Button
        NextvideoButton                 matlab.ui.control.Button
        FillStartButton                 matlab.ui.control.Button
        FillEndButton                   matlab.ui.control.Button
        HandButtonGroup                 matlab.ui.container.ButtonGroup
        BothButton                      matlab.ui.control.RadioButton
        RightButton                     matlab.ui.control.RadioButton
        LeftButton                      matlab.ui.control.RadioButton
        SkipButton                      matlab.ui.control.Button
        AutoFillStartButton             matlab.ui.control.Button
        PlottrajectoryButton            matlab.ui.control.Button
        AutoFillEndButton               matlab.ui.control.Button
        DropDown_trajectory             matlab.ui.control.DropDown
        TrackednumberLabel              matlab.ui.control.Label
        CheckBox_1                      matlab.ui.control.CheckBox
        CheckBox_2                      matlab.ui.control.CheckBox
        CheckBox_3                      matlab.ui.control.CheckBox
        CheckBox_4                      matlab.ui.control.CheckBox
        CheckBox_5                      matlab.ui.control.CheckBox
        CheckBox_6                      matlab.ui.control.CheckBox
        CheckBox_7                      matlab.ui.control.CheckBox
        CheckBox_8                      matlab.ui.control.CheckBox
        CheckBox_9                      matlab.ui.control.CheckBox
        CheckBox_10                     matlab.ui.control.CheckBox
        CheckBox_11                     matlab.ui.control.CheckBox
        CheckBox_12                     matlab.ui.control.CheckBox
        CheckBox_13                     matlab.ui.control.CheckBox
        CheckBox_14                     matlab.ui.control.CheckBox
        CheckBox_15                     matlab.ui.control.CheckBox
        CheckBox_16                     matlab.ui.control.CheckBox
        UIAxes_AllTrajectories          matlab.ui.control.UIAxes
        CenterPanel                     matlab.ui.container.Panel
        OmitgoodpointsCheckBox          matlab.ui.control.CheckBox
        FillEndButtonInterp             matlab.ui.control.Button
        FillStartButtonInterp           matlab.ui.control.Button
        InterpolaterangeEditFieldStart  matlab.ui.control.EditField
        InterpolaterangeEditFieldLabel  matlab.ui.control.Label
        InterpolateButton               matlab.ui.control.Button
        SetButton                       matlab.ui.control.Button
        InterpolaterangeEditFieldEnd    matlab.ui.control.EditField
        ManualtrackButton               matlab.ui.control.Button
        Next10FramesButton              matlab.ui.control.Button
        Previous10FramesButton          matlab.ui.control.Button
        NextButton                      matlab.ui.control.Button
        PreviousButton                  matlab.ui.control.Button
        Slider                          matlab.ui.control.Slider
        UIAxes_likelihood               matlab.ui.control.UIAxes
        UIAxes_image                    matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        UIAxes_trajectory               matlab.ui.control.UIAxes
        UIAxes_y                        matlab.ui.control.UIAxes
        UIAxes_x                        matlab.ui.control.UIAxes
        UIAxes_points                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        ind_frame % Description
        ind_bodypart
        VideoInfo
        vfilename
        metaname
        vpath
        dlcfilename
        vidObj
        Tracking
        enabledTrackingIndex
        DLCTrackingOut
        SideviewBorder
        TopviewBorder
        FrameStart
        FrameEnd

        count_interpolate = 0;
    end
    
    properties (Access = public)
        Nodes    matlab.ui.container.TreeNode
    end
    
    methods (Access = private)
        
        function saveData(app)
            TrackingOut = app.Tracking;
            TrackingOut.FrameStart = app.FrameStart;
            TrackingOut.FrameEnd = app.FrameEnd;
            TrackingOut.PressIndex = app.VideoInfo.IndPress;
            TrackingOut.VideoFilename = app.vfilename;
            TrackingOut.VidMeta = app.VideoInfo;
            
%             if ~isempty(app.StartEditField.Value) && ~strcmp(app.StartEditField.Value,'NaN')
%                 TrackingOut.FrameStart = str2double(app.StartEditField.Value);
%             else
%                 error('Please enter the number of start frame')
%             end
%             
%             
%             if ~isempty(app.EndEditField.Value) && ~strcmp(app.EndEditField.Value,'NaN')
%                 TrackingOut.FrameEnd = str2double(app.EndEditField.Value);
%             else
%                 error('Please enter the number of end frame')
%             end 

            TrackingOut.Hand = app.HandButtonGroup.SelectedObject.Text;
         
            TrackingOut.Performance = app.VideoInfo.Outcome;
            
            % merge to DLCTrackingOut
            app.DLCTrackingOut.SelectedBodypartIndex = app.enabledTrackingIndex;
            app.DLCTrackingOut.SelectedBodypart = app.Tracking.BodyParts(app.enabledTrackingIndex);          
            if isfield(app.DLCTrackingOut,'Tracking')
                idx = find(app.DLCTrackingOut.TrackedPressIndex == app.VideoInfo.IndPress);
                if isempty(idx)
                    app.DLCTrackingOut.TrackedNumber = app.DLCTrackingOut.TrackedNumber+1;
                    app.DLCTrackingOut.TrackedPressIndex = [app.DLCTrackingOut.TrackedPressIndex, app.VideoInfo.IndPress];
                    app.DLCTrackingOut.Tracking(end+1) = TrackingOut;
                
                    % sort the TrackedPressIndex
                    [app.DLCTrackingOut.TrackedPressIndex, sort_index] = sort(app.DLCTrackingOut.TrackedPressIndex);
                    app.DLCTrackingOut.Tracking = app.DLCTrackingOut.Tracking(sort_index);
                else
                    app.DLCTrackingOut.Tracking(idx) = TrackingOut;
                end
            else
                app.DLCTrackingOut.TrackedNumber = 1;
                app.DLCTrackingOut.TrackedPressIndex = app.VideoInfo.IndPress; 
                app.DLCTrackingOut.Tracking = TrackingOut;
            end
            
            DLCTrackingOut = app.DLCTrackingOut;
            save(fullfile(app.vpath,"DLCTrackingOut.mat"),"DLCTrackingOut")
        end
        
        function refreshIndframeChanged(app)
            app.FramenumEditField.Value =  num2str(app.ind_frame);
            app.Slider.Value = app.ind_frame;
            
            this_frame = app.vidObj.read(app.ind_frame);
            app.UIAxes_image.UserData{3}.CData = this_frame;
            
            x_pos   =   app.Tracking.Coordinates_x{app.ind_bodypart};
            y_pos   =   app.Tracking.Coordinates_y{app.ind_bodypart};
            lh      =   app.Tracking.Coordinates_p{app.ind_bodypart};
%             t_frames =  posdata(:, 4);


            if lh(app.ind_frame) < 0.8
                set(app.UIAxes_image.UserData{2}, 'XData', x_pos(app.ind_frame), 'YData', y_pos(app.ind_frame), 'color', 'r' )
                set(app.UIAxes_likelihood.UserData{2}, 'XData', app.ind_frame, 'YData', lh(app.ind_frame), 'color', 'r')
            else
                set(app.UIAxes_image.UserData{2}, 'XData', x_pos(app.ind_frame), 'YData', y_pos(app.ind_frame), 'color', 'c' )
                set(app.UIAxes_likelihood.UserData{2}, 'XData', app.ind_frame, 'YData', lh(app.ind_frame), 'color', 'c')
            end

            set(app.UIAxes_points.UserData{1}, 'XData', x_pos(app.ind_frame), 'YData', y_pos(app.ind_frame), 'marker', '+', 'markersize', 15, 'linewidth', 2)
            
            if strcmp(app.UIAxes_x.UserData{1}.Type, 'scatter')
                % plot pose  x vs t
                cla(app.UIAxes_x)
                app.UIAxes_x.UserData = [];
                set(app.UIAxes_x, 'nextplot', 'add')
                app.UIAxes_x.XLim = [1,length(x_pos)];
                ylim(app.UIAxes_x,'auto')
                app.UIAxes_x.UserData{1} = plot(app.UIAxes_x, x_pos, 'ko');
                app.UIAxes_x.YLabel.String = 'X Position';
                app.UIAxes_x.XLabel.String = 'Frames';
                app.UIAxes_x.UserData{2} = plot(app.UIAxes_x, app.ind_frame, x_pos(app.ind_frame), 'm+', 'markersize', 15, 'linewidth', 2);
                
                % plot pose  y vs t
                cla(app.UIAxes_y)
                app.UIAxes_y.UserData = [];
                set(app.UIAxes_y, 'nextplot', 'add')
                app.UIAxes_y.XLim = [1,length(y_pos)];
                ylim(app.UIAxes_y,'auto')
                app.UIAxes_y.UserData{1} = plot(app.UIAxes_y, y_pos, 'ko');
                app.UIAxes_y.YLabel.String = 'Y Position';
                app.UIAxes_y.XLabel.String = 'Frames';
                app.UIAxes_y.UserData{2} = plot(app.UIAxes_y, app.ind_frame, y_pos(app.ind_frame), 'm+', 'markersize', 15, 'linewidth', 2);                
            else
                set(app.UIAxes_x.UserData{2}, 'XData', app.ind_frame, 'YData', x_pos(app.ind_frame))
                set(app.UIAxes_y.UserData{2}, 'XData', app.ind_frame, 'YData', y_pos(app.ind_frame))                
            end
        end
        
        function refreshIndbodypartChanged(app)
            lh = app.Tracking.Coordinates_p{app.ind_bodypart};
            x_pos = app.Tracking.Coordinates_x{app.ind_bodypart};
            y_pos = app.Tracking.Coordinates_y{app.ind_bodypart};

            
%             this_frame = app.vidObj.read(app.ind_frame);

            % Update StartEditField and EndEditField
            if ~isnan(app.FrameStart(app.ind_bodypart))
                app.StartEditField.Value = num2str(app.FrameStart(app.ind_bodypart));
            else
                app.StartEditField.Value = '';
            end
            if ~isnan(app.FrameEnd(app.ind_bodypart))
                app.EndEditField.Value = num2str(app.FrameEnd(app.ind_bodypart));
            else
                app.EndEditField.Value = '';
            end
            
            % Likelihood
            cla(app.UIAxes_likelihood)
            app.UIAxes_likelihood.UserData{1} = plot(app.UIAxes_likelihood, lh, 'ko-');
            set(app.UIAxes_likelihood, 'ylim', [-0.2 1.2])
            set(app.UIAxes_likelihood, 'xlim', [0 length(lh)]);
            yline(app.UIAxes_likelihood, 0.8, 'color', 'g', 'linestyle', ':')
            set(app.UIAxes_likelihood, 'nextplot', 'add')
            
            app.UIAxes_likelihood.XLabel.String = 'Frame #';
            app.UIAxes_likelihood.YLabel.String = 'Likelihood';
            
            if lh(app.ind_frame) < 0.8
                set(app.UIAxes_image.UserData{2}, 'XData', x_pos(app.ind_frame), 'YData', y_pos(app.ind_frame), 'color', 'r' )
                app.UIAxes_likelihood.UserData{2} = plot(app.UIAxes_likelihood, app.ind_frame, lh(app.ind_frame), 'mo', 'markersize', 6, 'markerfacecolor', 'm', 'linewidth', 1);
            else
                set(app.UIAxes_image.UserData{2}, 'XData', x_pos(app.ind_frame), 'YData', y_pos(app.ind_frame), 'color', 'c' )
                app.UIAxes_likelihood.UserData{2} = plot(app.UIAxes_likelihood, app.ind_frame, lh(app.ind_frame), 'mo', 'markersize', 6 , 'markerfacecolor', 'm','linewidth', 1);
            end

            % plot UIAxes_points
            cla(app.UIAxes_points)
            set(app.UIAxes_points, 'nextplot', 'add')
            
            for i =1:length(lh)
                if lh(i)>0.8
                    plot(app.UIAxes_points, x_pos(i), y_pos(i), 'c+', 'linewidth', 1)
                else
                    plot(app.UIAxes_points, x_pos(i), y_pos(i), 'k+', 'linewidth', 1)
                end
            end
            
            app.UIAxes_points.UserData{1} = plot(app.UIAxes_points, x_pos(app.ind_frame), y_pos(app.ind_frame), 'm+', 'markersize', 15,'markerfacecolor', 'm', 'linewidth', 2);
            
%             trajpos = app.UIAxes_points.Position;
%             
%             width = trajpos(3);
%             newheight = width*size(this_frame, 2)/size(this_frame, 1);
%             trajpos(4) = newheight;
%             
%             app.UIAxes_points.Position = trajpos;

            % plot pose  x vs t
            cla(app.UIAxes_x)
            app.UIAxes_x.UserData = [];
            set(app.UIAxes_x, 'nextplot', 'add')
            app.UIAxes_x.XLim = [1,length(x_pos)];
            ylim(app.UIAxes_x,'auto')
            app.UIAxes_x.UserData{1} = plot(app.UIAxes_x, x_pos, 'ko');
            app.UIAxes_x.YLabel.String = 'X Position';
            app.UIAxes_x.XLabel.String = 'Frames';
            app.UIAxes_x.UserData{2} = plot(app.UIAxes_x, app.ind_frame, x_pos(app.ind_frame), 'm+', 'markersize', 15, 'linewidth', 2);
            
            % plot pose  y vs t
            cla(app.UIAxes_y)
            app.UIAxes_y.UserData = [];
            set(app.UIAxes_y, 'nextplot', 'add')
            app.UIAxes_y.XLim = [1,length(y_pos)];
            ylim(app.UIAxes_y,'auto')
            app.UIAxes_y.UserData{1} = plot(app.UIAxes_y, y_pos, 'ko');
            app.UIAxes_y.YLabel.String = 'Y Position';
            app.UIAxes_y.XLabel.String = 'Frames';
            app.UIAxes_y.UserData{2} = plot(app.UIAxes_y, app.ind_frame, y_pos(app.ind_frame),  'm+', 'markersize', 15, 'linewidth', 2);
            
            % clear UIAxes_trajectory
            cla(app.UIAxes_trajectory)
            
            % clear UIAxes_AllTrajectories
            cla(app.UIAxes_AllTrajectories)
            app.DropDown_trajectory.Items = {};
            app.DropDown_trajectory.Value = {};
        end
        
        function initialization(app)  
            app.VidnameEditField.Value = app.vfilename;
            app.MetafileEditField.Value = app.metaname;
            
            lh = app.Tracking.Coordinates_p{app.ind_bodypart};
            x_pos = app.Tracking.Coordinates_x{app.ind_bodypart};
            y_pos = app.Tracking.Coordinates_y{app.ind_bodypart};
            
            n_allframes = length(app.VideoInfo.SideViewFrames);
            app.Slider.UserData{1} = n_allframes;
            app.PerformanceEditField.Value = app.VideoInfo.Outcome;
            
            if ~isnan(app.FrameStart(app.ind_bodypart))
                app.StartEditField.Value = num2str(app.FrameStart(app.ind_bodypart));
            else
                app.StartEditField.Value = '';
            end
            
            if ~isnan(app.FrameEnd(app.ind_bodypart))
                app.EndEditField.Value = num2str(app.FrameEnd(app.ind_bodypart));
            else
                app.EndEditField.Value = '';
            end

            if isfield(app.Tracking,'Hand')
                if strcmp(app.Tracking.Hand,'Left')
                    app.LeftButton.Value = 1;
                elseif strcmp(app.Tracking.Hand,'Right')
                    app.RightButton.Value = 1;
                elseif strcmp(app.Tracking.Hand,'Both')
                    app.BothButton.Value = 1;
                end
            end
            
            % Tracked number
            if isempty(app.DLCTrackingOut)
                app.TrackednumberLabel.Text = 'Tracked Number: 0';
            else
                app.TrackednumberLabel.Text = ['Tracked Number: ', num2str(app.DLCTrackingOut.TrackedNumber)];
            end

            % plot first frame on the video axis
            cla(app.UIAxes_image)      
            app.vidObj = VideoReader(fullfile(app.vpath, app.vfilename));
            this_frame = app.vidObj.read(1);
            
            % get border of two views
            if isfield(app.DLCTrackingOut,'SideviewBorder')
                app.SideviewBorder = app.DLCTrackingOut.SideviewBorder;
                app.TopviewBorder = app.DLCTrackingOut.TopviewBorder;
            else
                if app.NewversionvideoCheckBox.Value
                    [app.SideviewBorder, app.TopviewBorder] = getBorderImgTwoViewsNew(this_frame);
                else
                    [app.SideviewBorder, app.TopviewBorder] = getBorderImgTwoViews(this_frame);
                end
            end
            
            % Likelihood
            cla(app.UIAxes_likelihood)
            app.UIAxes_likelihood.UserData{1} = plot(app.UIAxes_likelihood, lh, 'ko-');
            set(app.UIAxes_likelihood, 'ylim', [-0.2 1.2]);
            set(app.UIAxes_likelihood, 'xlim', [0 length(lh)]);
            yline(app.UIAxes_likelihood, 0.8, 'color', 'g', 'linestyle', ':')
            set(app.UIAxes_likelihood, 'nextplot', 'add')
            
            app.UIAxes_likelihood.XLabel.String = 'Frame #';
            app.UIAxes_likelihood.YLabel.String = 'Likelihood';
            app.UIAxes_likelihood.UserData{2} = plot(app.UIAxes_likelihood, app.ind_frame, lh(app.ind_frame), 'mo', 'markersize', 6 , 'markerfacecolor', 'm','linewidth', 1);

            % update slider data
            app.Slider.Limits = [1 n_allframes];
            app.Slider.Value = app.ind_frame;
            
            % BodyParts checkboxes
            for k = 1:length(app.Tracking.BodyParts)
                app.(['CheckBox_',num2str(k)]).Visible = 'on';
                app.(['CheckBox_',num2str(k)]).Text = app.Tracking.BodyParts{k};
                if any(app.enabledTrackingIndex==k)
                    app.(['CheckBox_',num2str(k)]).Value = 1;
                end
            end
            
            % dropdown
            app.BodypartsDropDown.Items = app.Tracking.BodyParts(app.enabledTrackingIndex);
            app.BodypartsDropDown.Value = app.Tracking.BodyParts{app.ind_bodypart};            
            
            % plot UIAxes_points
            cla(app.UIAxes_points)
            set(app.UIAxes_points, 'nextplot', 'add')
            
            for i =1:length(lh)
                if lh(i)>0.8
                    plot(app.UIAxes_points, x_pos(i), y_pos(i), 'c+', 'linewidth', 1)
                else
                    plot(app.UIAxes_points, x_pos(i), y_pos(i), 'k+', 'linewidth', 1)
                end
            end
            
            app.UIAxes_points.UserData{1} = plot(app.UIAxes_points, x_pos(app.ind_frame), y_pos(app.ind_frame), 'm+', 'markersize', 15,'markerfacecolor', 'm', 'linewidth', 2);
            
%             trajpos = app.UIAxes_points.Position;
%             
%             width = trajpos(3);
%             newheight = width*size(this_frame, 2)/size(this_frame, 1);
%             trajpos(4) = newheight;
%             
%             app.UIAxes_points.Position = trajpos;

            % plot pose  x vs t
            cla(app.UIAxes_x)
            app.UIAxes_x.XDir = 'reverse';
            app.UIAxes_x.YDir = 'reverse';
            app.UIAxes_x.UserData = [];
            set(app.UIAxes_x, 'nextplot', 'add')
            app.UIAxes_x.XLim = [1,length(x_pos)];
            ylim(app.UIAxes_x,'auto')
            app.UIAxes_x.UserData{1} = plot(app.UIAxes_x, x_pos, 'ko');
            app.UIAxes_x.YLabel.String = 'X Position';
            app.UIAxes_x.XLabel.String = 'Frames';
            app.UIAxes_x.UserData{2} = plot(app.UIAxes_x, app.ind_frame, x_pos(app.ind_frame), 'm+', 'markersize', 15, 'linewidth', 2);     
            
            % plot pose  y vs t
            cla(app.UIAxes_y)
            app.UIAxes_y.XDir = 'reverse';
            app.UIAxes_y.YDir = 'reverse';
            app.UIAxes_y.UserData = [];
            set(app.UIAxes_y, 'nextplot', 'add')
            app.UIAxes_y.XLim = [1,length(y_pos)];
            ylim(app.UIAxes_y,'auto')
            app.UIAxes_y.UserData{1} = plot(app.UIAxes_y, y_pos, 'ko');
            app.UIAxes_y.YLabel.String = 'Y Position';
            app.UIAxes_y.XLabel.String = 'Frames';
            app.UIAxes_y.UserData{2} = plot(app.UIAxes_y, app.ind_frame, y_pos(app.ind_frame),  'm+', 'markersize', 15, 'linewidth', 2);
            
            % clear UIAxes_trajectory
            cla(app.UIAxes_trajectory)
        end
        
        function showVideo(app)
            % plot first frame on the video axis
            cla(app.UIAxes_image)     
            
            this_frame = app.vidObj.read(app.ind_frame);
            app.FramenumEditField.Value =  num2str(app.ind_frame);
            
            
            vidpos = app.UIAxes_image.Position;
            height = vidpos(3);
            width = vidpos(4);
            if width > height*size(this_frame, 1)/size(this_frame, 2)
                newwidth = height*size(this_frame, 1)/size(this_frame, 2);
                vidpos(4) = newwidth;
            else
                newheight = width*size(this_frame, 2)/size(this_frame, 1);
                vidpos(3) = newheight;
            end
            
            set(app.UIAxes_image,'Position', vidpos, 'nextplot', 'add',...
                'ylim',[0 size(this_frame, 1)], 'xlim', [0 size(this_frame, 2)], 'ydir','reverse')
            
            app.UIAxes_image.UserData{3} = image(app.UIAxes_image, this_frame);
            
            x_pos   =   app.Tracking.Coordinates_x{app.ind_bodypart};
            y_pos   =   app.Tracking.Coordinates_y{app.ind_bodypart};
            lh      =   app.Tracking.Coordinates_p{app.ind_bodypart};

            if lh(app.ind_frame) < 0.8
                app.UIAxes_image.UserData{2} = plot(app.UIAxes_image, x_pos(app.ind_frame), y_pos(app.ind_frame), 'ro', 'markersize', 6, 'linewidth', 1);
            else
                app.UIAxes_image.UserData{2} = plot(app.UIAxes_image, x_pos(app.ind_frame), y_pos(app.ind_frame), 'co', 'markersize', 6, 'linewidth', 1);
            end
            
            disp('Video successfully loaded!')
        end
        
        function loadVideo(app)           
            load(fullfile(app.vpath, app.metaname));
            if length(VidMeta.IndPress) > 1
                VidMeta.IndPress = find(VidMeta.IndPress==1);
            end
            % Global Data
            app.ind_frame = 1;            
            app.VideoInfo = VidMeta;
            
            % try to load DLCTrackingOut
            if exist(fullfile(app.vpath,"DLCTrackingOut.mat"),"file")
                load(fullfile(app.vpath,"DLCTrackingOut.mat"))
                app.DLCTrackingOut = DLCTrackingOut;
                app.enabledTrackingIndex = app.DLCTrackingOut.SelectedBodypartIndex;
                app.ind_bodypart = app.enabledTrackingIndex(1);
                
                idx = find([app.DLCTrackingOut.TrackedPressIndex] == app.VideoInfo.IndPress);
                if isempty(idx)
                    app.Tracking = loadDLCcsv(fullfile(app.vpath, app.dlcfilename));
                    app.FrameStart = nan(1,length(app.Tracking.BodyParts));
                    app.FrameEnd = nan(1,length(app.Tracking.BodyParts));
                else
                    app.Tracking = app.DLCTrackingOut.Tracking(idx);
                    app.FrameStart = app.Tracking.FrameStart;
                    app.FrameEnd = app.Tracking.FrameEnd;
                end
            else
                app.DLCTrackingOut = [];
                app.Tracking = loadDLCcsv(fullfile(app.vpath, app.dlcfilename));
                app.enabledTrackingIndex = 1; 
                app.ind_bodypart = 1;
                app.FrameStart = nan(1,length(app.Tracking.BodyParts));
                app.FrameEnd = nan(1,length(app.Tracking.BodyParts));
            end
            
            app.initialization();
            app.showVideo();            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadvideoButton
        function LoadvideoButtonPushed(app, event)
            set(app.EphysDLCAppTwoViewsUIFigure,'renderer','opengl')
            % open selection
            [v_filename, app.vpath] = uigetfile('*.avi', 'Select a file') ;
            meta_filename = [v_filename(1:end-4),'.mat'];
            dlc_filename = dir(fullfile(app.vpath, [v_filename(1:end-4) '*.csv']));
            dlc_filename = dlc_filename.name;

            app.vfilename = v_filename;
            app.metaname = meta_filename;
            app.dlcfilename = dlc_filename;
            
            app.loadVideo();
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.ind_frame = round(value);

            if app.count_interpolate == 2
                app.InterpolaterangeEditFieldStart.Value = num2str(app.ind_frame);
                app.count_interpolate = 1;
            elseif app.count_interpolate == 1
                app.InterpolaterangeEditFieldEnd.Value = num2str(app.ind_frame);
                app.count_interpolate = 0;
            end

            app.refreshIndframeChanged();
        end

        % Button pushed function: FillStartButton
        function FillStartButtonPushed(app, event)
            app.StartEditField.Value = num2str(app.ind_frame);
            app.StartEditFieldValueChanged();
        end

        % Button pushed function: SavedatatomatButton
        function SavedatatomatButtonPushed(app, event)
            app.saveData();
        end

        % Button pushed function: NextvideoButton
        function NextvideoButtonPushed(app, event)
            app.saveData();
            AllFiles = dir(fullfile(app.vpath,'*.avi'));
            AllVidFiles=arrayfun(@(x)x.name, AllFiles, 'UniformOutput', false);
            
            % sort by time
            time = zeros(1,length(AllVidFiles));
            for k = 1:length(time)
                time(k) = str2double(extractBetween(AllVidFiles{k}, '_', 'ms.avi'));
            end
            [~, sort_idx] = sort(time);
            AllVidFiles = AllVidFiles(sort_idx);
            
            current_indx = find(strcmp(AllVidFiles,app.vfilename));
            
            if current_indx == length(AllVidFiles)
                disp('Done!')
                return
            end
            
            next_indx = current_indx + 1;
            
            temp_filename = AllVidFiles{next_indx};
            v_filename = temp_filename;
            meta_filename = [temp_filename(1:end-4),'.mat'];

            dlc_filename = dir(fullfile(app.vpath, [v_filename(1:end-4) '*.csv']));
            dlc_filename = dlc_filename.name;

            app.vfilename = v_filename;
            app.metaname = meta_filename;
            app.dlcfilename = dlc_filename;
            
            load(fullfile(app.vpath, app.metaname));
            if length(VidMeta.IndPress) > 1
                VidMeta.IndPress = find(VidMeta.IndPress==1);
            end
            
            % Global Data
            app.ind_frame = 1;            
            app.VideoInfo = VidMeta;
            app.ind_bodypart = app.enabledTrackingIndex(1);
                
            idx = find([app.DLCTrackingOut.TrackedPressIndex] == app.VideoInfo.IndPress);
            if isempty(idx)
                app.Tracking = loadDLCcsv(fullfile(app.vpath, app.dlcfilename));     
                app.FrameStart = nan(1,length(app.Tracking.BodyParts));
                app.FrameEnd = nan(1,length(app.Tracking.BodyParts));
            else
                app.Tracking = app.DLCTrackingOut.Tracking(idx);
                app.FrameStart = app.Tracking.FrameStart;
                app.FrameEnd = app.Tracking.FrameEnd;
            end
            
            app.initialization();
            app.showVideo();
        end

        % Button pushed function: FillEndButton
        function FillEndButtonPushed(app, event)
            app.EndEditField.Value = num2str(app.ind_frame);
            app.EndEditFieldValueChanged();
        end

        % Callback function
        function FillLiftButtonPushed(app, event)
            app.LiftEditField.Value = num2str(app.ind_frame);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            value = app.Slider.Value;
            ind_this = round(value+1);
            if ind_this > app.Slider.UserData{1}
                ind_this = app.Slider.UserData{1};
            end
            app.ind_frame = ind_this;
            app.refreshIndframeChanged();
        end

        % Button pushed function: Next10FramesButton
        function Next10FramesButtonPushed(app, event)
            value = app.Slider.Value;
            ind_this = round(value+10);
            if ind_this > app.Slider.UserData{1}
                ind_this = app.Slider.UserData{1};
            end
            app.ind_frame = ind_this;
            app.refreshIndframeChanged();
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            value = app.Slider.Value;
            ind_this = round(value-1);
            if ind_this <= 0
                ind_this = 1;
            end
            app.ind_frame = ind_this;
            app.refreshIndframeChanged();
        end

        % Button pushed function: Previous10FramesButton
        function Previous10FramesButtonPushed(app, event)
            value = app.Slider.Value;
            ind_this = round(value-10);
            if ind_this <= 0
                ind_this = 1;
            end
            app.ind_frame = ind_this;
            app.refreshIndframeChanged();
        end

        % Button pushed function: ManualtrackButton
        function ManualtrackButtonPushed(app, event)
            % change the location of body part
            
            % draw point on UIAxes
            h = drawpoint(app.UIAxes_image,  'Visible', 'off'); % user-defined body location

            x_pos   =   app.Tracking.Coordinates_x{app.ind_bodypart};
            y_pos   =   app.Tracking.Coordinates_y{app.ind_bodypart};
            lh      =   app.Tracking.Coordinates_p{app.ind_bodypart};

            x_pos(app.ind_frame) = h.Position(1);
            y_pos(app.ind_frame) = h.Position(2);
            lh(app.ind_frame)    = 1;
            
            app.Tracking.Coordinates_x{app.ind_bodypart} = x_pos;
            app.Tracking.Coordinates_y{app.ind_bodypart} = y_pos;
            app.Tracking.Coordinates_p{app.ind_bodypart} = lh;
            
            app.refreshIndbodypartChanged();        
        end

        % Value changed function: BodypartsDropDown
        function BodypartsDropDownValueChanged(app, event)
            value = app.BodypartsDropDown.Value;
            app.ind_bodypart = find(strcmp(app.Tracking.BodyParts,value));
            app.refreshIndbodypartChanged();
        end

        % Button pushed function: SkipButton
        function SkipButtonPushed(app, event)

            AllFiles = dir(fullfile(app.vpath,'*.avi'));
            AllVidFiles=arrayfun(@(x)x.name, AllFiles, 'UniformOutput', false);
            current_indx = find(strcmp(AllVidFiles,app.vfilename));
            
            if current_indx == length(AllVidFiles)
                disp('Done!')
                return
            end
            
            next_indx = current_indx + 1;
            
            temp_filename = AllVidFiles{next_indx};
            v_filename = temp_filename;
            meta_filename = [temp_filename(1:end-4),'.mat'];

            dlc_filename = dir(fullfile(app.vpath, [v_filename(1:end-4) '*.csv']));
            dlc_filename = dlc_filename.name;

            app.vfilename = v_filename;
            app.metaname = meta_filename;
            app.dlcfilename = dlc_filename;
            
            app.loadVideo()
        end

        % Callback function
        function ConfirmthispointButtonPushed(app, event)
            lh      =   app.Tracking.Coordinates_p{app.ind_bodypart};
            lh(app.ind_frame)    = 1;
            app.Tracking.Coordinates_p{app.ind_bodypart} = lh;
            
            app.refreshIndframeChanged();
        end

        % Callback function
        function ShowVideoButtonPushed(app, event)
            app.showVideo();
        end

        % Callback function
        function RemovethispointButtonPushed(app, event)
            lh      =   app.Tracking.Coordinates_p{app.ind_bodypart};
            lh(app.ind_frame)    = 0;
            app.Tracking.Coordinates_p{app.ind_bodypart} = lh;
            
            app.refreshIndframeChanged();            
        end

        % Button pushed function: AutoFillStartButton
        function AutoFillStartButtonPushed(app, event)
            if strcmpi(app.Tracking.BodyParts{app.ind_bodypart}(end-2:end),'top')
                app.StartEditField.Value = num2str(1);
            else
                idx_press = findNearestPoint(app.VideoInfo.SideVideoFrameTimesR,app.VideoInfo.tPress);
                X = app.Tracking.Coordinates_x{app.ind_bodypart}(1:idx_press);
                Y = app.Tracking.Coordinates_y{app.ind_bodypart}(1:idx_press);
                app.StartEditField.Value = num2str(getLiftStartFrameAuto(X,Y));
            end
            app.StartEditFieldValueChanged();
        end

        % Button pushed function: AutoFillEndButton
        function AutoFillEndButtonPushed(app, event)
            if strcmpi(app.Tracking.BodyParts{app.ind_bodypart}(end-2:end),'top')
                app.EndEditField.Value = num2str(length(app.Tracking.Coordinates_x{app.ind_bodypart}));
            else
                idx_press = findNearestPoint(app.VideoInfo.SideVideoFrameTimesR,app.VideoInfo.tPress);
                X = app.Tracking.Coordinates_x{app.ind_bodypart}(1:idx_press);
                Y = app.Tracking.Coordinates_y{app.ind_bodypart}(1:idx_press);
                app.EndEditField.Value = num2str(getLiftEndFrameAuto(X,Y));  
            end
            app.EndEditFieldValueChanged();
        end

        % Value changed function: CheckBox_1, CheckBox_10, CheckBox_11, 
        % ...and 13 other components
        function CheckBoxValueChanged(app, event)
            % if it is the only one being checked, it can't be checked off
            flag = true;
            app.enabledTrackingIndex = [];
            for k = 1:length(app.Tracking.BodyParts)
                if app.(['CheckBox_',num2str(k)]).Value
                    app.enabledTrackingIndex = [app.enabledTrackingIndex, k];
                    flag = false;
                end
            end
            if flag
                event.Source.Value = 1;
                app.enabledTrackingIndex = find(strcmp(app.Tracking.BodyParts,event.Source.Text));
            end
            
            flag_refresh = false;
            if ~any(app.enabledTrackingIndex==app.ind_bodypart)
                app.ind_bodypart = app.enabledTrackingIndex(1);
                flag_refresh = true;
            end            
            app.BodypartsDropDown.Items = app.Tracking.BodyParts(app.enabledTrackingIndex);
            app.BodypartsDropDown.Value = app.Tracking.BodyParts{app.ind_bodypart};            
            if flag_refresh
                app.refreshIndbodypartChanged();
            end        
        end

        % Window scroll wheel function: EphysDLCAppTwoViewsUIFigure
        function EphysDLCAppTwoViewsUIFigureWindowScrollWheel(app, event)
            verticalScrollCount = event.VerticalScrollCount;
            value = app.Slider.Value;
            ind_this = round(value+verticalScrollCount);
            if ind_this > app.Slider.UserData{1}
                ind_this = app.Slider.UserData{1};
            elseif ind_this <= 0
                ind_this = 1;
            end
            app.ind_frame = ind_this;
            app.refreshIndframeChanged();  
        end

        % Button pushed function: PlottrajectoryButton
        function PlottrajectoryButtonPushed(app, event)
            if isempty(app.StartEditField.Value) || isnan(str2double(app.StartEditField.Value))
                FrameBeg = 1;
            else
                FrameBeg = str2double(app.StartEditField.Value);
            end
            
            if isempty(app.EndEditField.Value) || isnan(str2double(app.EndEditField.Value))
                FrameEnd = length(app.Tracking.Coordinates_x{app.ind_bodypart});
            else
                FrameEnd = str2double(app.EndEditField.Value);
            end

            x_pos_full = app.Tracking.Coordinates_x{app.ind_bodypart};
            y_pos_full = app.Tracking.Coordinates_y{app.ind_bodypart};

            x_pos = x_pos_full(FrameBeg:FrameEnd);
            y_pos = y_pos_full(FrameBeg:FrameEnd);
            
            t_frames = app.VideoInfo.SideVideoFrameTimesR - app.VideoInfo.tPress;
            t_frames_selected = t_frames(FrameBeg:FrameEnd);

            this_frame = app.vidObj.read(app.ind_frame);
            % update UIAxes_trajectory
            cla(app.UIAxes_trajectory)
            
            app.UIAxes_trajectory.NextPlot = 'add';            
            % plot the image first            
            app.UIAxes_trajectory.UserData{1} = image(app.UIAxes_trajectory, this_frame);
            if mean(x_pos)>=app.SideviewBorder(1) && mean(x_pos)<=app.SideviewBorder(2)
                app.UIAxes_trajectory.XLim = app.SideviewBorder(1:2);
                app.UIAxes_trajectory.YLim = app.SideviewBorder(3:4);
            else
                app.UIAxes_trajectory.XLim = app.TopviewBorder(1:2);
                app.UIAxes_trajectory.YLim = app.TopviewBorder(3:4);              
            end
            app.UIAxes_trajectory.YDir = 'reverse';

            trajpos = app.UIAxes_trajectory.Position;

            width = trajpos(3);
            newheight = width*diff(app.UIAxes_trajectory.YLim)/diff(app.UIAxes_trajectory.XLim);
            trajpos(4) = newheight;
            app.UIAxes_trajectory.Position = trajpos;
            
            app.UIAxes_trajectory.UserData{2} = scatter(app.UIAxes_trajectory, x_pos, y_pos, 30, t_frames_selected,'filled',...
               'linewidth', 0.25);


            app.UIAxes_x.XLim = [FrameBeg FrameEnd];
            app.UIAxes_y.XLim = [FrameBeg FrameEnd];

            cla(app.UIAxes_x)
            app.UIAxes_x.XDir = 'reverse';
            app.UIAxes_x.YDir = 'reverse';
            app.UIAxes_x.UserData{1} = scatter(app.UIAxes_x, FrameBeg:FrameEnd, x_pos, 30, t_frames_selected,'filled', 'linewidth', 0.25);
            app.UIAxes_x.UserData{2} = plot(app.UIAxes_x, app.ind_frame, x_pos_full(app.ind_frame), 'm+', 'markersize', 15, 'linewidth', 2);

            cla(app.UIAxes_y)
            app.UIAxes_y.XDir = 'reverse';
            app.UIAxes_y.YDir = 'reverse';
            app.UIAxes_y.UserData{1} = scatter(app.UIAxes_y, FrameBeg:FrameEnd, y_pos, 30, t_frames_selected,'filled', 'linewidth', 0.25);
            app.UIAxes_y.UserData{2} =   plot(app.UIAxes_y, app.ind_frame, y_pos_full(app.ind_frame), 'm+', 'markersize', 15, 'linewidth', 2);

            app.UIAxes_x.YLim = app.UIAxes_trajectory.XLim;
            app.UIAxes_y.YLim = app.UIAxes_trajectory.YLim;
            
            % plot all trajectories on UIAxes_AllTrajectories
            cla(app.UIAxes_AllTrajectories)
            app.UIAxes_AllTrajectories.XLim = app.UIAxes_trajectory.XLim;
            app.UIAxes_AllTrajectories.YLim =  app.UIAxes_trajectory.YLim;
            app.UIAxes_AllTrajectories.NextPlot = 'Add';
            app.UIAxes_AllTrajectories.YDir     = 'reverse';
            % plot an image
            image(app.UIAxes_AllTrajectories, this_frame);
            % plot all tracked trials
            if ~isempty(app.DLCTrackingOut)
                for i = 1:length(app.DLCTrackingOut.Tracking)
                    FrameBeg = app.DLCTrackingOut.Tracking(i).FrameStart(app.ind_bodypart);
                    FrameEnd = app.DLCTrackingOut.Tracking(i).FrameEnd(app.ind_bodypart);
                    if isnan(FrameBeg) || isnan(FrameEnd)
                        continue
                    end
                    plot(app.UIAxes_AllTrajectories,...
                        app.DLCTrackingOut.Tracking(i).Coordinates_x{app.ind_bodypart}(FrameBeg:FrameEnd),...
                        app.DLCTrackingOut.Tracking(i).Coordinates_y{app.ind_bodypart}(FrameBeg:FrameEnd),...
                        'color', [0.8 0.8 0.8]);
                end
            end
            plot(app.UIAxes_AllTrajectories, x_pos, y_pos, 'color', [1 0.8 0], 'linewidth', 2);
            
            % update DropDown_trajectory
            if ~isempty(app.DLCTrackingOut)
                app.DropDown_trajectory.Items = ["this", string(app.DLCTrackingOut.TrackedPressIndex)];
                app.DropDown_trajectory.Value = "this";
            end
        end

        % Value changed function: DropDown_trajectory
        function DropDown_trajectoryValueChanged(app, event)
            value = app.DropDown_trajectory.Value;
            
            this_frame = app.vidObj.read(app.ind_frame);
            % plot all trajectories on UIAxes_AllTrajectories
            cla(app.UIAxes_AllTrajectories)
            app.UIAxes_AllTrajectories.XLim = app.UIAxes_trajectory.XLim;
            app.UIAxes_AllTrajectories.YLim =  app.UIAxes_trajectory.YLim;
            app.UIAxes_AllTrajectories.NextPlot = 'Add';
            app.UIAxes_AllTrajectories.YDir     = 'reverse';
            % plot an image
            image(app.UIAxes_AllTrajectories, this_frame);
            % plot all tracked trials
            last_plot_idx = [];
            if ~isempty(app.DLCTrackingOut)
                for i = 1:length(app.DLCTrackingOut.Tracking)
                    FrameBeg = app.DLCTrackingOut.Tracking(i).FrameStart(app.ind_bodypart);
                    FrameEnd = app.DLCTrackingOut.Tracking(i).FrameEnd(app.ind_bodypart);
                    if isnan(FrameBeg) || isnan(FrameEnd)
                        continue
                    end
                    if strcmp(num2str(app.DLCTrackingOut.TrackedPressIndex(i)),value)
                        last_plot_idx = i;
                    else
                        plot(app.UIAxes_AllTrajectories,...
                            app.DLCTrackingOut.Tracking(i).Coordinates_x{app.ind_bodypart}(FrameBeg:FrameEnd),...
                            app.DLCTrackingOut.Tracking(i).Coordinates_y{app.ind_bodypart}(FrameBeg:FrameEnd),...
                            'color', [0.8 0.8 0.8]);
                    end
                end
            end
            
            FrameBeg = str2double(app.StartEditField.Value);
            FrameEnd = str2double(app.EndEditField.Value);

            x_pos = app.Tracking.Coordinates_x{app.ind_bodypart}(FrameBeg:FrameEnd);
            y_pos = app.Tracking.Coordinates_y{app.ind_bodypart}(FrameBeg:FrameEnd);            
            if strcmp(value,'this')
                plot(app.UIAxes_AllTrajectories, x_pos, y_pos, 'color', [1 0.8 0], 'linewidth', 2);
            else
                plot(app.UIAxes_AllTrajectories, x_pos, y_pos, 'color', [0.8 0.8 0.8]);
            end
            
            if ~isempty(last_plot_idx)
                FrameBeg = app.DLCTrackingOut.Tracking(last_plot_idx).FrameStart(app.ind_bodypart);
                FrameEnd = app.DLCTrackingOut.Tracking(last_plot_idx).FrameEnd(app.ind_bodypart);
                plot(app.UIAxes_AllTrajectories,...
                    app.DLCTrackingOut.Tracking(last_plot_idx).Coordinates_x{app.ind_bodypart}(FrameBeg:FrameEnd),...
                    app.DLCTrackingOut.Tracking(last_plot_idx).Coordinates_y{app.ind_bodypart}(FrameBeg:FrameEnd),...
                    'color', [1 0.8 0],...
                    'linewidth', 2);                
            end
        end

        % Key press function: EphysDLCAppTwoViewsUIFigure
        function EphysDLCAppTwoViewsUIFigureKeyPress(app, event)
            c = event.Character;
            disp([c, ' is keypressed'])
            if c == ' '
                app.NextButtonPushed();
            elseif c == 'a'
                try
                    app.UIAxes_image.XLim = app.SideviewBorder(1:2);
                    app.UIAxes_image.YLim = app.SideviewBorder(3:4);
                catch
                    disp('SideviewBorder is not loaded yet!')
                end                
            elseif c == 's'
                try
                    this_frame = app.vidObj.read(1);
                    app.UIAxes_image.XLim = [0 size(this_frame, 2)];
                    app.UIAxes_image.YLim = [0 size(this_frame, 1)];
                catch
                    disp('No video is loaded')
                end
            elseif c == 'd'
                try
                    app.UIAxes_image.XLim = app.TopviewBorder(1:2);
                    app.UIAxes_image.YLim = app.TopviewBorder(3:4);
                catch
                    disp('TopviewBorder is not loaded yet!')
                end
            elseif c == 'w'
                app.ManualtrackButtonPushed();
            elseif c == 'q'
                idx = find(app.enabledTrackingIndex==app.ind_bodypart);
                if idx == length(app.enabledTrackingIndex)
                    app.ind_bodypart = app.enabledTrackingIndex(1);
                else
                    app.ind_bodypart = app.enabledTrackingIndex(idx+1);
                end
                app.BodypartsDropDown.Value = app.Tracking.BodyParts{app.ind_bodypart};
                app.refreshIndbodypartChanged();
            end
        end

        % Button pushed function: SavetoButton
        function SavetoButtonPushed(app, event)
            selpath = uigetdir('Select a folder to save this frame');
            app.SavethisframeButton.UserData = selpath;
            app.FrameDirectoryEditField.Value = selpath;
        end

        % Button pushed function: SavethisframeButton
        function SavethisframeButtonPushed(app, event)
            % save current frame for network refinement
            tosavepath = app.FrameDirectoryEditField.Value;

            if isempty(tosavepath)
                selpath = uigetdir('Select a folder to save this frame');
                app.FrameDirectoryEditField.Value = selpath;
                tosavepath = selpath;
            end

            video_name = extractBefore(app.vfilename, '.avi');
            folder_name = fileparts(app.vpath);
            
            p_end = length(app.vpath);
            if app.vpath(end) == '/' || app.vpath(end) == '\'
                p_end = p_end-1;
            end

            for k = length(app.vpath)-1:-1:1
                if app.vpath(k) == '/' || app.vpath(k) == '\' 
                    p_start = k+1;
                    break
                end
            end
            
            folder_name  = app.vpath(p_start:p_end);

            this_frame_name = [extractBefore(app.vfilename, '.avi'), '_', folder_name, '_Frame', num2str(app.ind_frame)];

            % make and save figure
            this_frame = app.vidObj.read(app.ind_frame);
            imwrite(this_frame, fullfile(tosavepath, [this_frame_name, '.png']))
        end

        % Value changed function: StartEditField
        function StartEditFieldValueChanged(app, event)
            value = app.StartEditField.Value;
            app.FrameStart(app.ind_bodypart) = str2double(value);
        end

        % Value changed function: EndEditField
        function EndEditFieldValueChanged(app, event)
            value = app.EndEditField.Value;
            app.FrameEnd(app.ind_bodypart) = str2double(value);
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            app.count_interpolate = 2;
        end

        % Button pushed function: InterpolateButton
        function InterpolateButtonPushed(app, event)
            frame_start = str2double(app.InterpolaterangeEditFieldStart.Value)+1;
            frame_end = str2double(app.InterpolaterangeEditFieldEnd.Value)-1;
            if frame_start > frame_end
                temp = frame_start;
                frame_start = frame_end;
                frame_end = temp;
            end

            if isempty(frame_start) || isempty(frame_end) || isnan(frame_start) || isnan(frame_end)
                return
            end
            
            x_pos   =   app.Tracking.Coordinates_x{app.ind_bodypart};
            y_pos   =   app.Tracking.Coordinates_y{app.ind_bodypart};
            lh      =   app.Tracking.Coordinates_p{app.ind_bodypart};
            lh_raw  =   lh;


            % keep the frames outside unchanged
            if frame_start > 1
                lh(1:frame_start-1) = 1;
            end
            if frame_end < length(lh)
                lh(frame_end+1:end) = 1;
            end

            t = 1:length(x_pos);
            if size(x_pos,2) == 1
                t = t';
            end
            
            if app.OmitgoodpointsCheckBox.Value
                lh(frame_start:frame_end) = 0;
                if frame_start <= 1 || frame_end >= length(lh)
                    warning(['The start frame is 1 or the end frame is ', num2str(lh)]);
                    return
                end
            end

            idx_good = find(lh>=0.8);
            
            x_pos_good = x_pos(idx_good);
            y_pos_good = y_pos(idx_good);
            t_good = t(idx_good);

            x_pos = interp1(t_good, x_pos_good, t);
            y_pos = interp1(t_good, y_pos_good, t);
            lh(frame_start:frame_end) = 1;

            % keep the frames outside unchanged
            if frame_start > 1
                lh(1:frame_start-1) = lh_raw(1:frame_start-1);
            end
            if frame_end < length(lh)
                lh(frame_end+1:end) = lh_raw(frame_end+1:end);
            end
            
            app.Tracking.Coordinates_x{app.ind_bodypart} = x_pos;
            app.Tracking.Coordinates_y{app.ind_bodypart} = y_pos;
            app.Tracking.Coordinates_p{app.ind_bodypart} = lh;
            
            app.refreshIndbodypartChanged();   
        end

        % Button down function: UIAxes_likelihood
        function UIAxes_likelihoodButtonDown(app, event)
            value = event.IntersectionPoint(1);

            app.ind_frame = round(value);
            if app.count_interpolate == 2
                app.InterpolaterangeEditFieldStart.Value = num2str(app.ind_frame);
                app.count_interpolate = 1;
            elseif app.count_interpolate == 1
                app.InterpolaterangeEditFieldEnd.Value = num2str(app.ind_frame);
                app.count_interpolate = 0;
            end

            app.refreshIndframeChanged();
        end

        % Button pushed function: FillStartButtonInterp
        function FillStartButtonInterpPushed(app, event)
            app.InterpolaterangeEditFieldStart.Value = num2str(app.ind_frame);
        end

        % Button pushed function: FillEndButtonInterp
        function FillEndButtonInterpPushed(app, event)
            app.InterpolaterangeEditFieldEnd.Value = num2str(app.ind_frame);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.EphysDLCAppTwoViewsUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {898, 898, 898};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {898, 898};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {364, '1x', 372};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EphysDLCAppTwoViewsUIFigure = uifigure('Visible', 'off');
            app.EphysDLCAppTwoViewsUIFigure.Name = 'EphysDLCAppTwoViews';
            app.EphysDLCAppTwoViewsUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.EphysDLCAppTwoViewsUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @EphysDLCAppTwoViewsUIFigureWindowScrollWheel, true);
            app.EphysDLCAppTwoViewsUIFigure.KeyPressFcn = createCallbackFcn(app, @EphysDLCAppTwoViewsUIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.EphysDLCAppTwoViewsUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes_AllTrajectories = uiaxes(app.LeftPanel);
            app.UIAxes_AllTrajectories.XTick = [];
            app.UIAxes_AllTrajectories.XTickLabel = '';
            app.UIAxes_AllTrajectories.YTick = [];
            app.UIAxes_AllTrajectories.YTickLabel = '';
            app.UIAxes_AllTrajectories.ZTick = [];
            app.UIAxes_AllTrajectories.ZTickLabel = '';
            app.CheckBox_16 = uicheckbox(app.LeftPanel);
            app.CheckBox_16.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_16.Visible = 'off';
            app.CheckBox_16.Text = '';
            app.CheckBox_15 = uicheckbox(app.LeftPanel);
            app.CheckBox_15.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_15.Visible = 'off';
            app.CheckBox_15.Text = '';
            app.CheckBox_14 = uicheckbox(app.LeftPanel);
            app.CheckBox_14.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_14.Visible = 'off';
            app.CheckBox_14.Text = '';
            app.CheckBox_13 = uicheckbox(app.LeftPanel);
            app.CheckBox_13.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_13.Visible = 'off';
            app.CheckBox_13.Text = '';
            app.CheckBox_12 = uicheckbox(app.LeftPanel);
            app.CheckBox_12.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_12.Visible = 'off';
            app.CheckBox_12.Text = '';
            app.CheckBox_11 = uicheckbox(app.LeftPanel);
            app.CheckBox_11.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_11.Visible = 'off';
            app.CheckBox_11.Text = '';
            app.CheckBox_10 = uicheckbox(app.LeftPanel);
            app.CheckBox_10.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_10.Visible = 'off';
            app.CheckBox_10.Text = '';
            app.CheckBox_9 = uicheckbox(app.LeftPanel);
            app.CheckBox_9.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_9.Visible = 'off';
            app.CheckBox_9.Text = '';
            app.CheckBox_8 = uicheckbox(app.LeftPanel);
            app.CheckBox_8.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_8.Visible = 'off';
            app.CheckBox_8.Text = '';
            app.CheckBox_7 = uicheckbox(app.LeftPanel);
            app.CheckBox_7.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_7.Visible = 'off';
            app.CheckBox_7.Text = '';
            app.CheckBox_6 = uicheckbox(app.LeftPanel);
            app.CheckBox_6.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_6.Visible = 'off';
            app.CheckBox_6.Text = '';
            app.CheckBox_5 = uicheckbox(app.LeftPanel);
            app.CheckBox_5.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_5.Visible = 'off';
            app.CheckBox_5.Text = '';
            app.CheckBox_4 = uicheckbox(app.LeftPanel);
            app.CheckBox_4.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_4.Visible = 'off';
            app.CheckBox_4.Text = '';
            app.CheckBox_3 = uicheckbox(app.LeftPanel);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_3.Visible = 'off';
            app.CheckBox_3.Text = '';
            app.CheckBox_2 = uicheckbox(app.LeftPanel);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_2.Visible = 'off';
            app.CheckBox_2.Text = '';
            app.CheckBox_1 = uicheckbox(app.LeftPanel);
            app.CheckBox_1.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_1.Visible = 'off';
            app.CheckBox_1.Text = '';
            app.TrackednumberLabel = uilabel(app.LeftPanel);
            app.TrackednumberLabel.Text = 'Tracked number:';
            app.DropDown_trajectory = uidropdown(app.LeftPanel);
            app.DropDown_trajectory.Items = {};
            app.DropDown_trajectory.ValueChangedFcn = createCallbackFcn(app, @DropDown_trajectoryValueChanged, true);
            app.DropDown_trajectory.Value = {};
            app.AutoFillEndButton = uibutton(app.LeftPanel, 'push');
            app.AutoFillEndButton.ButtonPushedFcn = createCallbackFcn(app, @AutoFillEndButtonPushed, true);
            app.AutoFillEndButton.Text = 'Auto';
            app.PlottrajectoryButton = uibutton(app.LeftPanel, 'push');
            app.PlottrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @PlottrajectoryButtonPushed, true);
            app.PlottrajectoryButton.Text = 'Plot trajectory';
            app.AutoFillStartButton = uibutton(app.LeftPanel, 'push');
            app.AutoFillStartButton.ButtonPushedFcn = createCallbackFcn(app, @AutoFillStartButtonPushed, true);
            app.AutoFillStartButton.Text = 'Auto';
            app.SkipButton = uibutton(app.LeftPanel, 'push');
            app.SkipButton.ButtonPushedFcn = createCallbackFcn(app, @SkipButtonPushed, true);
            app.SkipButton.Text = 'Skip';
            app.HandButtonGroup = uibuttongroup(app.LeftPanel);
            app.HandButtonGroup.Title = 'Hand';
            app.LeftButton = uiradiobutton(app.HandButtonGroup);
            app.LeftButton.Text = 'Left';
            app.LeftButton.Value = true;
            app.RightButton = uiradiobutton(app.HandButtonGroup);
            app.RightButton.Text = 'Right';
            app.BothButton = uiradiobutton(app.HandButtonGroup);
            app.BothButton.Text = 'Both';
            app.FillEndButton = uibutton(app.LeftPanel, 'push');
            app.FillEndButton.ButtonPushedFcn = createCallbackFcn(app, @FillEndButtonPushed, true);
            app.FillEndButton.Text = 'Fill End';
            app.FillStartButton = uibutton(app.LeftPanel, 'push');
            app.FillStartButton.ButtonPushedFcn = createCallbackFcn(app, @FillStartButtonPushed, true);
            app.FillStartButton.Text = 'Fill Start';
            app.NextvideoButton = uibutton(app.LeftPanel, 'push');
            app.NextvideoButton.ButtonPushedFcn = createCallbackFcn(app, @NextvideoButtonPushed, true);
            app.NextvideoButton.Text = 'Next video';
            app.SavedatatomatButton = uibutton(app.LeftPanel, 'push');
            app.SavedatatomatButton.ButtonPushedFcn = createCallbackFcn(app, @SavedatatomatButtonPushed, true);
            app.SavedatatomatButton.Text = {'Save data to mat'; ''};
            app.LoadvideoButton = uibutton(app.LeftPanel, 'push');
            app.LoadvideoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadvideoButtonPushed, true);
            app.LoadvideoButton.Text = 'Load video';
            app.VidnameEditField = uieditfield(app.LeftPanel, 'text');
            app.VidnameEditField.Tag = 'vidfilename';
            app.VidnameEditFieldLabel = uilabel(app.LeftPanel);
            app.VidnameEditFieldLabel.Tag = 'vidname';
            app.VidnameEditFieldLabel.Text = 'Vid name';
            app.MetafileEditField = uieditfield(app.LeftPanel, 'text');
            app.MetafileEditFieldLabel = uilabel(app.LeftPanel);
            app.MetafileEditFieldLabel.Text = 'Meta file';
            app.FramenumEditField = uieditfield(app.LeftPanel, 'text');
            app.FramenumEditField.Tag = 'framenum';
            app.FramenumEditFieldLabel = uilabel(app.LeftPanel);
            app.FramenumEditFieldLabel.Text = {'Frame num'; ''};
            app.PerformanceEditField = uieditfield(app.LeftPanel, 'text');
            app.PerformanceEditField.Tag = 'DLCfile';
            app.PerformanceEditFieldLabel = uilabel(app.LeftPanel);
            app.PerformanceEditFieldLabel.Text = 'Performance';
            app.StartEditField = uieditfield(app.LeftPanel, 'text');
            app.StartEditField.ValueChangedFcn = createCallbackFcn(app, @StartEditFieldValueChanged, true);
            app.StartEditFieldLabel = uilabel(app.LeftPanel);
            app.StartEditFieldLabel.Text = 'Start';
            app.EndEditField = uieditfield(app.LeftPanel, 'text');
            app.EndEditField.ValueChangedFcn = createCallbackFcn(app, @EndEditFieldValueChanged, true);
            app.EndLabel = uilabel(app.LeftPanel);
            app.EndLabel.Text = 'End';
            app.BodypartsDropDown = uidropdown(app.LeftPanel);
            app.BodypartsDropDown.Items = {};
            app.BodypartsDropDown.ValueChangedFcn = createCallbackFcn(app, @BodypartsDropDownValueChanged, true);
            app.BodypartsDropDown.Tag = 'BodyParts';
            app.BodypartsDropDown.Value = {};
            app.BodypartsDropDown_2Label = uilabel(app.LeftPanel);
            app.BodypartsDropDown_2Label.Text = 'Body parts';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = {'Shortcuts cheat sheet:'; ''; 'a: show sideview'; 's: return to normal'; 'd: show topview'; 'w: manual curation'; 'q: next body part'; 'space: next frame'; ''; 'scroll down: next frame'; 'scroll up: previous frame'; ''};
            app.SavetoButton = uibutton(app.LeftPanel, 'push');
            app.SavetoButton.ButtonPushedFcn = createCallbackFcn(app, @SavetoButtonPushed, true);
            app.SavetoButton.Text = 'Save to:';
            app.SavethisframeButton = uibutton(app.LeftPanel, 'push');
            app.SavethisframeButton.ButtonPushedFcn = createCallbackFcn(app, @SavethisframeButtonPushed, true);
            app.SavethisframeButton.Text = 'Save this frame';
            app.FrameDirectoryEditField = uieditfield(app.LeftPanel, 'text');
            app.NewversionvideoCheckBox = uicheckbox(app.LeftPanel);
            app.NewversionvideoCheckBox.Text = 'New version video';
            app.NewversionvideoCheckBox.Value = true;
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes_image = uiaxes(app.CenterPanel);
            app.UIAxes_image.YDir = 'reverse';
            app.UIAxes_image.XTick = [];
            app.UIAxes_image.YTick = [];
            app.UIAxes_likelihood = uiaxes(app.CenterPanel);
            title(app.UIAxes_likelihood, {'Tracking quality'; ''})
            xlabel(app.UIAxes_likelihood, 'FrameNum')
            ylabel(app.UIAxes_likelihood, 'Likelihood')
            app.UIAxes_likelihood.ButtonDownFcn = createCallbackFcn(app, @UIAxes_likelihoodButtonDown, true);
            app.UIAxes_likelihood.Tag = 'LabelingQuality';
            app.Slider = uislider(app.CenterPanel);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Tag = 'videoslider';
            app.PreviousButton = uibutton(app.CenterPanel, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.CenterPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.Previous10FramesButton = uibutton(app.CenterPanel, 'push');
            app.Previous10FramesButton.ButtonPushedFcn = createCallbackFcn(app, @Previous10FramesButtonPushed, true);
            app.Previous10FramesButton.Text = 'Previous 10 Frames';
            app.Next10FramesButton = uibutton(app.CenterPanel, 'push');
            app.Next10FramesButton.ButtonPushedFcn = createCallbackFcn(app, @Next10FramesButtonPushed, true);
            app.Next10FramesButton.Text = 'Next 10 Frames';
            app.ManualtrackButton = uibutton(app.CenterPanel, 'push');
            app.ManualtrackButton.ButtonPushedFcn = createCallbackFcn(app, @ManualtrackButtonPushed, true);
            app.ManualtrackButton.Text = 'Manual track';
            app.InterpolaterangeEditFieldEnd = uieditfield(app.CenterPanel, 'text');
            app.InterpolaterangeEditFieldEnd.Tag = 'vidfilename';
            app.SetButton = uibutton(app.CenterPanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.InterpolateButton = uibutton(app.CenterPanel, 'push');
            app.InterpolateButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolateButtonPushed, true);
            app.InterpolateButton.Text = 'Interpolate';
            app.InterpolaterangeEditFieldLabel = uilabel(app.CenterPanel);
            app.InterpolaterangeEditFieldLabel.Tag = 'vidname';
            app.InterpolaterangeEditFieldLabel.Text = 'Interpolate range';
            app.InterpolaterangeEditFieldStart = uieditfield(app.CenterPanel, 'text');
            app.InterpolaterangeEditFieldStart.Tag = 'vidfilename';
            app.FillStartButtonInterp = uibutton(app.CenterPanel, 'push');
            app.FillStartButtonInterp.ButtonPushedFcn = createCallbackFcn(app, @FillStartButtonInterpPushed, true);
            app.FillStartButtonInterp.Text = 'Fill Start';
            app.FillEndButtonInterp = uibutton(app.CenterPanel, 'push');
            app.FillEndButtonInterp.ButtonPushedFcn = createCallbackFcn(app, @FillEndButtonInterpPushed, true);
            app.FillEndButtonInterp.Text = 'Fill End';
            app.OmitgoodpointsCheckBox = uicheckbox(app.CenterPanel);
            app.OmitgoodpointsCheckBox.Text = 'Omit good points';
            app.OmitgoodpointsCheckBox.Value = true;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_points = uiaxes(app.RightPanel);
            app.UIAxes_points.YDir = 'reverse';
            app.UIAxes_points.XTick = [];
            app.UIAxes_points.YTick = [];
            app.UIAxes_points.Tag = 'ha_traj';
            app.UIAxes_x = uiaxes(app.RightPanel);
            ylabel(app.UIAxes_x, 'X')
            app.UIAxes_x.XTick = [];
            app.UIAxes_x.YTick = [];
            app.UIAxes_y = uiaxes(app.RightPanel);
            xlabel(app.UIAxes_y, 'FrameNum')
            ylabel(app.UIAxes_y, 'Y')
            app.UIAxes_y.XTick = [];
            app.UIAxes_y.YTick = [];
            app.UIAxes_trajectory = uiaxes(app.RightPanel);
            app.UIAxes_trajectory.XTick = [];
            app.UIAxes_trajectory.YTick = [];
            % Show the figure after all components are created
            app.EphysDLCAppTwoViewsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EphysDLCAppTwoViews

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EphysDLCAppTwoViewsUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EphysDLCAppTwoViewsUIFigure)
        end
    end
end