classdef EphysVideoAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        OnlyforcorrecttrialsCheckBoxTop  matlab.ui.control.CheckBox
        TimeSlider                    matlab.ui.control.Slider
        TimeSliderLabel               matlab.ui.control.Label
        NexttrialButton               matlab.ui.control.Button
        OnlyforcorrecttrialsCheckBox  matlab.ui.control.CheckBox
        FramerateSlider               matlab.ui.control.Slider
        FramerateSliderLabel          matlab.ui.control.Label
        MakevideoclipsCheckBox        matlab.ui.control.CheckBox
        FramerangeEditField2          matlab.ui.control.NumericEditField
        FramerangeEditFieldLabel      matlab.ui.control.Label
        FramerangeEditField1          matlab.ui.control.NumericEditField
        ShowburstframesButton         matlab.ui.control.Button
        FramesnumberEditFieldLabel    matlab.ui.control.Label
        FramesnumberEditField         matlab.ui.control.NumericEditField
        FramespreEditFieldLabel       matlab.ui.control.Label
        FramespreEditField            matlab.ui.control.NumericEditField
        framespostLabel               matlab.ui.control.Label
        FramespostEditField           matlab.ui.control.NumericEditField
        ScaletrackingEditField        matlab.ui.control.NumericEditField
        ScaletrackingEditFieldLabel   matlab.ui.control.Label
        PerformanceEditField          matlab.ui.control.EditField
        PerformanceEditFieldLabel     matlab.ui.control.Label
        ReactionTimeEditField         matlab.ui.control.NumericEditField
        TrialLabel_4                  matlab.ui.control.Label
        ForeperiodEditField           matlab.ui.control.NumericEditField
        TrialLabel_3                  matlab.ui.control.Label
        TrialEditField                matlab.ui.control.NumericEditField
        TrialLabel                    matlab.ui.control.Label
        StopButton                    matlab.ui.control.Button
        PlayButton                    matlab.ui.control.Button
        tpostmsEditFieldLabel         matlab.ui.control.Label
        TrackingDropDown              matlab.ui.control.DropDown
        TrackingDropDownLabel         matlab.ui.control.Label
        UnitDropDown                  matlab.ui.control.DropDown
        UnitDropDownLabel             matlab.ui.control.Label
        tpreEditField                 matlab.ui.control.NumericEditField
        tpremsEditFieldLabel          matlab.ui.control.Label
        tpostEditField                matlab.ui.control.NumericEditField
        EventDropDown                 matlab.ui.control.DropDown
        EventDropDownLabel            matlab.ui.control.Label
        UIAxesTop                     matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        UIAxesSide                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        isVideoPlaying = false; % Description
        r;
        t_pre;
        t_post;
        event;
        unit;

        bodypart;
        bodypart_view;
        bodypart_idx;
        p_threshold = 0.8;

        trial = 1;

        % plot
        scale_tracking = 5;

    end
    
    methods (Access = private)
        
        function vid = getVideo(app, camview)
            if strcmpi(camview, 'side')
                filename = ['./VideoFrames_side/RawVideo/', app.event, num2str(app.trial, '%03d'), '.avi'];
            else
                filename = ['./VideoFrames_top/RawVideo/', app.event, num2str(app.trial, '%03d'), '.avi'];
            end
            if exist(filename, "file")
                vid = VideoReader(filename);
            else
                vid = [];
            end
        end

        function updateVideoInfo(app, trial)
            if nargin<2
                trial = app.trial;
            end
            idx_vid = find([app.r.VideoInfos_side.Index]==trial);
            app.TrialEditField.Value = trial;
            app.PerformanceEditField.Value = app.r.VideoInfos_side(idx_vid).Performance;
            app.ForeperiodEditField.Value = app.r.VideoInfos_side(idx_vid).Foreperiod;

            if isnan(app.r.VideoInfos_side(idx_vid).ReactTime)
                app.ReactionTimeEditField.Value = -Inf;
            else
                app.ReactionTimeEditField.Value = app.r.VideoInfos_side(idx_vid).ReactTime;
            end
        end

        function updateSettings(app)
            app.t_pre = app.tpreEditField.Value;
            app.t_post = app.tpostEditField.Value;
            app.event = app.EventDropDown.Value;
            
            app.unit = find(strcmp(app.UnitDropDown.Items, app.UnitDropDown.Value))-1; % 0 is 'All unit'

            app.bodypart = app.TrackingDropDown.Value;
            idx = find(strcmp(app.TrackingDropDown.Items, app.TrackingDropDown.Value));
            if idx>length(app.r.VideoInfos_side(1).Tracking.BodyParts)
                idx = idx-length(app.r.VideoInfos_side(1).Tracking.BodyParts);
                app.bodypart_view = 'top';
            else
                app.bodypart_view = 'side';
            end
            app.bodypart_idx = idx;

            app.trial = app.TrialEditField.Value;
            app.scale_tracking = app.ScaletrackingEditField.Value;
        end

        function speed = computeSpeed(app, tracking_x, tracking_y)
            speed = zeros(1,length(tracking_x));
            dt = 10;
            for i = 1:length(tracking_x)
                if i==1
                    speed(i) = 1/dt*norm([tracking_x(i+1),tracking_y(i+1)]-[tracking_x(i),tracking_y(i)]);
                elseif i==length(tracking_x)
                    speed(i) = 1/dt*norm([tracking_x(i),tracking_y(i)]-[tracking_x(i-1),tracking_y(i-1)]);
                else
                    speed(i) = 1/dt*0.5*(norm([tracking_x(i+1),tracking_y(i+1)]-[tracking_x(i),tracking_y(i)]) + norm([tracking_x(i),tracking_y(i)]-[tracking_x(i-1),tracking_y(i-1)]));
                end
            end
            speed = smoothdata(speed,'gaussian',25/dt*5);
            speed(isnan(tracking_x)) = NaN;
        end

        function t_line = updateUIAxesBottomTemplate(app, frame_times, spike_times, tracking_x, tracking_y, zone)
                speed = app.computeSpeed(tracking_x, tracking_y);
                speed = (speed-min(speed))./(max(speed)-min(speed))*app.scale_tracking-3*app.scale_tracking+0.5;
                tracking_x = (tracking_x-min(tracking_x))./(max(tracking_x)-min(tracking_x))*app.scale_tracking-2*app.scale_tracking+0.5;
                tracking_y = (tracking_y-min(tracking_y))./(max(tracking_y)-min(tracking_y))*app.scale_tracking-app.scale_tracking+0.5;

                cla(app.UIAxes);
                colors = colororder;
                n_color = size(colors, 1);
                for k = 1:length(spike_times)
                    x_raster = [];
                    y_raster = [];
                    for j = 1:length(spike_times{k})
                        x_raster = [x_raster, spike_times{k}(j), spike_times{k}(j), NaN];
                        y_raster = [y_raster, k-0.5, k+0.5, NaN];
                    end
                    plot(app.UIAxes, x_raster, y_raster, '-', 'LineWidth', 1, 'Color', colors(mod(k-1,n_color)+1,:));
                end

                plot(app.UIAxes, frame_times, tracking_x, 'b-', 'LineWidth', 2);
                plot(app.UIAxes, frame_times, tracking_y, 'm-', 'LineWidth', 2);
                plot(app.UIAxes, frame_times, speed, 'g-', 'LineWidth', 2);
                EasyPlot.plotShaded(app.UIAxes, zone, [-3*app.scale_tracking+0.5, -3*app.scale_tracking+0.5;length(spike_times)+0.5, length(spike_times)+0.5]);
                t_line = xline(app.UIAxes, app.t_pre);

                xlim(app.UIAxes, [frame_times(1), frame_times(end)]);
                ylim(app.UIAxes, [-3*app.scale_tracking+0.5,0.5+length(spike_times)]);
                xlabel(app.UIAxes, ['Time from ', app.event, ' (ms)']);
                
                yticks(app.UIAxes, [0.5-2.5*app.scale_tracking, 0.5-1.5*app.scale_tracking, 0.5-0.5*app.scale_tracking, 1:length(spike_times)]);
                y_labels = cell(1, length(spike_times)+3);
                y_labels{1} = 'Speed';
                y_labels{2} = strrep([app.bodypart, ' X'], '_', ' ');
                y_labels{3} = strrep([app.bodypart, ' Y'], '_', ' ');
                for k = 1:length(spike_times)
                    y_labels{k+3} = num2str(k);
                end
                yticklabels(app.UIAxes, y_labels);
        end   

        function fitImageSize(app, image_side, image_top)
            y = size(image_side, 1);
            x = size(image_side, 2);
            width = app.UIAxesSide.Position(3);
            height = app.UIAxesSide.Position(4);
            if x/y > width/height
                xlim(app.UIAxesSide, [0, x]);
                ylim(app.UIAxesSide, [0, x/width*height]);
            else
                ylim(app.UIAxesSide, [0, y]);
                xlim(app.UIAxesSide, [0, y/height*width]);
            end

            y = size(image_top, 1);
            x = size(image_top, 2);
            width = app.UIAxesTop.Position(3);
            height = app.UIAxesTop.Position(4);
            if x/y > width/height
                xlim(app.UIAxesTop, [0, x]);
                ylim(app.UIAxesTop, [0, x/width*height]);
            else
                ylim(app.UIAxesTop, [0, y]);
                xlim(app.UIAxesTop, [0, y/height*width]);
            end
        end

        function updateSlider(app, t_start, t_end, t_now)
            app.TimeSlider.Limits = [t_start, t_end];
            if t_now < t_start
                t_now = t_start;
            elseif t_now > t_end
                t_now = t_end;
            end
            app.TimeSlider.Value = t_now;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            temp = load('RTarrayAll.mat');
            app.r = temp.r;
            app.t_pre = app.tpreEditField.Value;
            app.t_post = app.tpostEditField.Value;
            app.event = app.EventDropDown.Value;
            
            % update dropdowns
            items_unit = cell(1,length(app.r.Units.SpikeTimes));
            for k = 1:length(items_unit)
                if app.r.Units.SpikeNotes(k,3)==1
                    unit_type = 's';
                else
                    unit_type = 'm';
                end
                items_unit{k} = ['Unit', num2str(k), ' (', unit_type,')'];
            end
            
            items_unit = [{'All'}, items_unit];
            app.UnitDropDown.Items = items_unit;
            app.UnitDropDown.Value = items_unit{1};
            app.unit = 0;

            % fix untracked situation
            if ~isfield(app.r.VideoInfos_side(1), 'Tracking')
                for k = 1:length(app.r.VideoInfos_side)
                    app.r.VideoInfos_side(k).Tracking.BodyParts = {};
                end
            end

            if ~isfield(app.r.VideoInfos_top(1), 'Tracking')
                for k = 1:length(app.r.VideoInfos_top)
                    app.r.VideoInfos_top(k).Tracking.BodyParts = {};
                end
            end

            % update trackings
            items_bodypart = cell(1, length(app.r.VideoInfos_side(1).Tracking.BodyParts)+length(app.r.VideoInfos_top(1).Tracking.BodyParts));
            for k = 1:length(items_bodypart)
                if k<=length(app.r.VideoInfos_side(1).Tracking.BodyParts)
                    items_bodypart{k} = [app.r.VideoInfos_side(1).Tracking.BodyParts{k}, ' (side)'];
                else
                    idx = k-length(app.r.VideoInfos_side(1).Tracking.BodyParts);
                    items_bodypart{k} = [app.r.VideoInfos_top(1).Tracking.BodyParts{idx}, ' (top)'];
                end
            end

            app.TrackingDropDown.Items = items_bodypart;
            app.TrackingDropDown.Value = items_bodypart{1};
            app.bodypart = items_bodypart{1};
            app.bodypart_view = 'side';
            app.bodypart_idx = 1;
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.updateSettings();
            cla(app.UIAxesSide)
            cla(app.UIAxesTop);
            cla(app.UIAxes);

            is_image_created = false;
            
            app.isVideoPlaying = true;
            while app.isVideoPlaying
                app.updateVideoInfo();

                vid_side = app.getVideo('side');
                vid_top = app.getVideo('top');
                if isempty(vid_side) || isempty(vid_top)
                    app.trial = app.trial+1;
                    continue
                end
                
                frame_start = max(1,round((app.t_pre-app.r.VideoInfos_side(1).t_pre)/10));
                frame_end = min(app.r.VideoInfos_side(1).total_frames,round((app.t_post-app.r.VideoInfos_side(1).t_pre)/10));

                idx_vid = find([app.r.VideoInfos_side.Index]==app.trial);
                
                if app.OnlyforcorrecttrialsCheckBoxTop.Value && ~strcmpi(app.r.VideoInfos_side(idx_vid).Performance, 'Correct')
                    app.trial = app.trial+1;
                    continue
                end

                if app.unit == 0
                    spike_times = cell(1,length(app.r.Units.SpikeTimes));
                    for k = 1:length(spike_times)
                        spike_times{k} = app.r.Units.SpikeTimes(k).timings;
                    end
                else
                    spike_times = {app.r.Units.SpikeTimes(app.unit).timings};
                end

                t_start = app.r.VideoInfos_side(idx_vid).Time + app.t_pre;
                t_end = app.r.VideoInfos_side(idx_vid).Time + app.t_post;
                frame_times = app.r.VideoInfos_side(idx_vid).VideoFrameTime - app.r.VideoInfos_side(idx_vid).Time;
                frame_times_top = app.r.VideoInfos_top(idx_vid).VideoFrameTime - app.r.VideoInfos_top(idx_vid).Time;
                
                for k = 1:length(spike_times)
                    spike_times{k} = spike_times{k}(spike_times{k}>=t_start & spike_times{k}<=t_end)-app.r.VideoInfos_side(idx_vid).Time;
                end
                
                if strcmpi(app.bodypart_view, 'side')
                    tracking_x = app.r.VideoInfos_side(idx_vid).Tracking.Coordinates_x{app.bodypart_idx}(frame_start:frame_end);
                    tracking_y = app.r.VideoInfos_side(idx_vid).Tracking.Coordinates_y{app.bodypart_idx}(frame_start:frame_end);
                    tracking_p = app.r.VideoInfos_side(idx_vid).Tracking.Coordinates_p{app.bodypart_idx}(frame_start:frame_end);
                else
                    tracking_x = app.r.VideoInfos_top(idx_vid).Tracking.Coordinates_x{app.bodypart_idx}(frame_start:frame_end);
                    tracking_y = app.r.VideoInfos_top(idx_vid).Tracking.Coordinates_y{app.bodypart_idx}(frame_start:frame_end);
                    tracking_p = app.r.VideoInfos_top(idx_vid).Tracking.Coordinates_p{app.bodypart_idx}(frame_start:frame_end);                   
                end
                tracking_x(tracking_p<app.p_threshold) = NaN;
                tracking_y(tracking_p<app.p_threshold) = NaN;
                

                FP_zone = [0, app.r.VideoInfos_side(idx_vid).Foreperiod];
                t_line = app.updateUIAxesBottomTemplate(frame_times(frame_start:frame_end), spike_times, tracking_x, tracking_y, FP_zone);
                
                if ~is_image_created
                    image_side = image(app.UIAxesSide, vid_side.read(1));
                    image_top = image(app.UIAxesTop, vid_top.read(1));
                    app.fitImageSize(vid_side.read(1), vid_top.read(1));

                    if strcmpi(app.bodypart_view, 'side')
                        point_tracking = plot(app.UIAxesSide, tracking_x(1), tracking_y(1), 'bo', 'MarkerSize', 5, 'MarkerFaceColor', 'blue');
                    else
                        point_tracking = plot(app.UIAxesTop, tracking_x(1), tracking_y(1), 'bo', 'MarkerSize', 5, 'MarkerFaceColor', 'blue');
                    end
                    is_image_created = true;
                end

                app.updateSlider(frame_times(frame_start), frame_times(frame_end), frame_times(frame_start));
                frame = frame_start;
                while frame<frame_end
                    if ~app.isVideoPlaying
                        return
                    end

                    image_side.CData = vid_side.read(frame);

                    frame_top = findNearestPoint(frame_times_top, frame_times(frame));
                    image_top.CData = vid_top.read(frame_top);
                    
                    if strcmpi(app.bodypart_view, 'side')
                        point_tracking.XData = tracking_x(frame-frame_start+1);
                        point_tracking.YData = tracking_y(frame-frame_start+1);
                    else
                        point_tracking.XData = tracking_x(frame_top-frame_start+1);
                        point_tracking.YData = tracking_y(frame_top-frame_start+1);
                    end

                    % update bottom
                    t_line.Value = frame_times(frame);

                    pause((100-app.FramerateSlider.Value)/1000);
                    drawnow
                    
                    frame_now = findNearestPoint(frame_times, app.TimeSlider.Value);
                    frame = frame_now+1;
                    app.updateSlider(frame_times(frame_start), frame_times(frame_end), frame_times(frame));
                end

                app.trial = app.trial+1;
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.isVideoPlaying = false;
        end

        % Callback function
        function UpdatesettingsButtonPushed(app, event)
            temp = load('RTarrayAll.mat');
            app.r = temp.r;
            app.t_pre = app.tpreEditField.Value;
            app.t_post = app.tpostEditField.Value;
            app.event = app.EventDropDown.Value;
            
            app.unit = find(strcmp(app.UnitDropDown.Items, app.UnitDropDown.Value))-1; % 0 is 'All unit'

            app.bodypart = app.TrackingDropDown.Value;
            idx = find(strcmp(app.TrackingDropDown.Items, app.TrackingDropDown.Value));
            if idx>length(app.r.Units.SpikeTimes)
                idx = idx-length(app.r.Units.SpikeTimes);
                app.bodypart_view = 'top';
            else
                app.bodypart_view = 'side';
            end
            app.bodypart_idx = idx;

            app.trial = app.TrialEditField.Value;
            app.scale_tracking = app.ScaletrackingEditField.Value;
            
        end

        % Button pushed function: ShowburstframesButton
        function ShowburstframesButtonPushed(app, event)
                app.updateSettings();
                cla(app.UIAxesSide)
                cla(app.UIAxesTop);
                cla(app.UIAxes);

                FrameNum = app.FramesnumberEditField.Value;
                correct_only = app.OnlyforcorrecttrialsCheckBox.Value;

                n_frame_pre = app.FramespreEditField.Value;
                n_frame_post = app.FramespostEditField.Value;
                frame_range = [app.FramerangeEditField1.Value, app.FramerangeEditField2.Value];
                if isinf(frame_range(1)) || isinf(frame_range(2))
                    frame_range = [];
                end

                plot_longer_clip = app.MakevideoclipsCheckBox.Value;
            
                if correct_only
                    correct_index = find(strcmp({app.r.VideoInfos_side.Performance},'Correct'));
                    VideoInfos_correct_side = app.r.VideoInfos_side(correct_index);
                    VideoInfos_correct_top = app.r.VideoInfos_top(correct_index);
                else
                    VideoInfos_correct_side = app.r.VideoInfos_side;
                    VideoInfos_correct_top = app.r.VideoInfos_top;
                end
                
                app.unit = find(strcmp(app.UnitDropDown.Items, app.UnitDropDown.Value))-1;
                if app.unit<=0
                    disp('Please choose a unit');
                    return
                end

                spk_time = app.r.Units.SpikeTimes(app.unit).timings;
                firing_rate_t_post = 50;
                firing_rate_t_pre = -50;
            
                firing_rate = zeros(length(VideoInfos_correct_side),VideoInfos_correct_side(1).total_frames);
                for k = 1:length(VideoInfos_correct_side)
                    for j = 1:VideoInfos_correct_side(1).total_frames
                        firing_rate(k,j) = sum(spk_time+firing_rate_t_post>VideoInfos_correct_side(k).VideoFrameTime(j)...
                            & spk_time+firing_rate_t_pre<VideoInfos_correct_side(k).VideoFrameTime(j));
                    end
                end
                if ~isempty(frame_range)
                    firing_rate(:,(1:VideoInfos_correct_side(1).total_frames)<frame_range(1) | (1:VideoInfos_correct_side(1).total_frames)>frame_range(2))=0;
                end
            
                spike_time = sort(firing_rate(:),'descend');
                threshold = spike_time(FrameNum);
%                 figure;
%                 histogram(spike_time(spike_time>2));
%                 xline(threshold);
%                 xlabel('Spike Count')
%                 title('Spike Count Histogram')
                %%
                app.isVideoPlaying = true;

                is_image_created = false;

                for k = 1:length(VideoInfos_correct_side)
                    if ~app.isVideoPlaying
                        return
                    end

                    j = 1;
                    
                    if ~plot_longer_clip
                        disp(['Extracting from Trial #', num2str(k), '...']);
                    end

                    while app.isVideoPlaying && j <= VideoInfos_correct_side(1).total_frames
                        if firing_rate(k,j) > threshold
                            vid_this_top = VideoReader(['VideoFrames_top\RawVideo\Press',num2str(VideoInfos_correct_side(k).Index,'%03d'),'.avi']);
                            vid_this_side = VideoReader(['VideoFrames_side\RawVideo\Press',num2str(VideoInfos_correct_side(k).Index,'%03d'),'.avi']);
                            
                            app.updateVideoInfo(VideoInfos_correct_side(k).Index);

                            if ~plot_longer_clip
                                if ~app.isVideoPlaying
                                    return
                                end

                                if ~is_image_created
                                    image_side = image(app.UIAxesSide, vid_this_side.read(j));
                                    image_top = image(app.UIAxesTop, vid_this_top.read(j));
                                    is_image_created = true;
                                end
                                
                                image_top.CData = vid_this_top.read(j);
                                image_side.CData = vid_this_side.read(j);
        
                                pause((100-app.FramerateSlider.Value)/1000);
                                drawnow;
                            else
                                j_start = max(j+n_frame_pre,1);
                                
                                i = min(VideoInfos_correct_side(1).total_frames,round(j+firing_rate_t_post/10));
                                while i<=VideoInfos_correct_side(1).total_frames
                                    if firing_rate(k,i) <= threshold
                                        break
                                    end
                                    i = i+1;
                                end
                                j_next = i;
                                j_end = min(round(i-firing_rate_t_post/10+n_frame_post),VideoInfos_correct_side(1).total_frames);
                                
                                
                                burst_frames = [max(round(j+firing_rate_t_pre/10),1),...
                                    min(j_next,VideoInfos_correct_side(1).total_frames)];
                                burst_zone = VideoInfos_correct_side(k).VideoFrameTime(burst_frames) - VideoInfos_correct_side(k).Time;

                                j = j_next;
            
                                t_start = VideoInfos_correct_side(k).VideoFrameTime(j_start);
                                t_end = VideoInfos_correct_side(k).VideoFrameTime(j_end);
                                spike_time = app.r.Units.SpikeTimes(app.unit).timings(app.r.Units.SpikeTimes(app.unit).timings<=t_end & app.r.Units.SpikeTimes(app.unit).timings>t_start);
                                
                                frame_times_raw = VideoInfos_correct_side(k).VideoFrameTime - VideoInfos_correct_side(k).Time;
                                frame_times_top_raw = VideoInfos_correct_top(k).VideoFrameTime - VideoInfos_correct_top(k).Time;

                                frame_times = VideoInfos_correct_side(k).VideoFrameTime(j_start:j_end) - VideoInfos_correct_side(k).Time;
                                spike_time = {spike_time - VideoInfos_correct_side(k).Time};

                                if strcmpi(app.bodypart_view, 'side')
                                    tracking_x = VideoInfos_correct_side(k).Tracking.Coordinates_x{app.bodypart_idx}(j_start:j_end);
                                    tracking_y = VideoInfos_correct_side(k).Tracking.Coordinates_y{app.bodypart_idx}(j_start:j_end);
                                    tracking_p = VideoInfos_correct_side(k).Tracking.Coordinates_p{app.bodypart_idx}(j_start:j_end);
                                else
                                    tracking_x = VideoInfos_correct_top(k).Tracking.Coordinates_x{app.bodypart_idx}(j_start:j_end);
                                    tracking_y = VideoInfos_correct_top(k).Tracking.Coordinates_y{app.bodypart_idx}(j_start:j_end);
                                    tracking_p = VideoInfos_correct_top(k).Tracking.Coordinates_p{app.bodypart_idx}(j_start:j_end);                   
                                end
                                tracking_x(tracking_p<app.p_threshold) = NaN;
                                tracking_y(tracking_p<app.p_threshold) = NaN;

                
                                t_line = app.updateUIAxesBottomTemplate(frame_times, spike_time, tracking_x, tracking_y, burst_zone);
                                
                                if ~is_image_created
                                    image_side = image(app.UIAxesSide, vid_this_side.read(1));
                                    image_top = image(app.UIAxesTop, vid_this_top.read(1));
                                    app.fitImageSize(vid_this_side.read(1), vid_this_top.read(1));

                                    if strcmpi(app.bodypart_view, 'side')
                                        point_tracking = plot(app.UIAxesSide, tracking_x(1), tracking_y(1), 'bo', 'MarkerSize', 5, 'MarkerFaceColor', 'blue');
                                    else
                                        point_tracking = plot(app.UIAxesTop, tracking_x(1), tracking_y(1), 'bo', 'MarkerSize', 5, 'MarkerFaceColor', 'blue');
                                    end
                                    is_image_created = true;
                                end                    
                                
                                app.updateSlider(frame_times(1), frame_times(end), frame_times(1));

                                i = j_start;
                                while i<=j_end
                                    if ~app.isVideoPlaying
                                        return
                                    end

                                    i_top = findNearestPoint(frame_times_top_raw, frame_times_raw(i));
                                    image_top.CData = vid_this_top.read(i_top);
                                    
                                    image_side.CData = vid_this_side.read(i);
                                    
                                    if strcmpi(app.bodypart_view, 'side')
                                        point_tracking.XData = tracking_x(i-j_start+1);
                                        point_tracking.YData = tracking_y(i-j_start+1);
                                    else
                                        idx = i_top-j_start+1;
                                        idx = max(idx, 1);
                                        idx = min(idx, j_end-j_start+1);
                                        point_tracking.XData = tracking_x(idx);
                                        point_tracking.YData = tracking_y(idx);
                                    end

                                    % update bottom
                                    t_line.Value = frame_times(i-j_start+1);

                                    pause((100-app.FramerateSlider.Value)/1000);
                                    drawnow;
                                    
                                    frame_now = findNearestPoint(frame_times, app.TimeSlider.Value);
                                    i = frame_now+j_start;
                                    if i>j_end
                                        break
                                    end
                                    app.updateSlider(frame_times(1), frame_times(end), frame_times(i-j_start+1));
                                end
                            end
                        end
                    j = j+1;
                    end
                end
                
        end

        % Button pushed function: NexttrialButton
        function NexttrialButtonPushed(app, event)
            app.StopButtonPushed();
            app.TrialEditField.Value = app.TrialEditField.Value+1;
            app.PlayButtonPushed();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesSide = uiaxes(app.UIFigure);
            app.UIAxesSide.YDir = 'reverse';
            app.UIAxesSide.XTick = [];
            app.UIAxesSide.YTick = [];
            app.UIAxesSide.ZTick = [];
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxesTop = uiaxes(app.UIFigure);
            app.UIAxesTop.YDir = 'reverse';
            app.UIAxesTop.XTick = [];
            app.UIAxesTop.YTick = [];
            app.UIAxesTop.ZTick = [];
            app.EventDropDownLabel = uilabel(app.UIFigure);
            app.EventDropDownLabel.Text = 'Event';
            app.EventDropDown = uidropdown(app.UIFigure);
            app.EventDropDown.Items = {'Press', 'Release', 'Trigger', 'Release', 'Lift'};
            app.EventDropDown.Value = 'Press';
            app.tpostEditField = uieditfield(app.UIFigure, 'numeric');
            app.tpostEditField.Value = 2000;
            app.tpremsEditFieldLabel = uilabel(app.UIFigure);
            app.tpremsEditFieldLabel.Text = 't pre (ms)';
            app.tpreEditField = uieditfield(app.UIFigure, 'numeric');
            app.tpreEditField.Value = -2000;
            app.UnitDropDownLabel = uilabel(app.UIFigure);
            app.UnitDropDownLabel.Text = 'Unit';
            app.UnitDropDown = uidropdown(app.UIFigure);
            app.UnitDropDown.Items = {};
            app.UnitDropDown.Value = {};
            app.TrackingDropDownLabel = uilabel(app.UIFigure);
            app.TrackingDropDownLabel.Text = 'Tracking';
            app.TrackingDropDown = uidropdown(app.UIFigure);
            app.TrackingDropDown.Items = {};
            app.TrackingDropDown.Value = {};
            app.tpostmsEditFieldLabel = uilabel(app.UIFigure);
            app.tpostmsEditFieldLabel.Text = 't post (ms)';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.TrialLabel = uilabel(app.UIFigure);
            app.TrialLabel.Text = 'Trial';
            app.TrialEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrialEditField.Value = 1;
            app.TrialLabel_3 = uilabel(app.UIFigure);
            app.TrialLabel_3.Text = 'Foreperiod';
            app.ForeperiodEditField = uieditfield(app.UIFigure, 'numeric');
            app.ForeperiodEditField.Value = 1500;
            app.TrialLabel_4 = uilabel(app.UIFigure);
            app.TrialLabel_4.Text = 'Reaction time';
            app.ReactionTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.PerformanceEditFieldLabel = uilabel(app.UIFigure);
            app.PerformanceEditFieldLabel.Text = 'Performance';
            app.PerformanceEditField = uieditfield(app.UIFigure, 'text');
            app.ScaletrackingEditFieldLabel = uilabel(app.UIFigure);
            app.ScaletrackingEditFieldLabel.Text = 'Scale (tracking)';
            app.ScaletrackingEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScaletrackingEditField.Value = 5;
            app.FramespostEditField = uieditfield(app.UIFigure, 'numeric');
            app.FramespostEditField.Limits = [1 Inf];
            app.FramespostEditField.Value = 50;
            app.framespostLabel = uilabel(app.UIFigure);
            app.framespostLabel.Text = 'Frames post ';
            app.FramespreEditField = uieditfield(app.UIFigure, 'numeric');
            app.FramespreEditField.Limits = [-Inf -1];
            app.FramespreEditField.Value = -50;
            app.FramespreEditFieldLabel = uilabel(app.UIFigure);
            app.FramespreEditFieldLabel.Text = 'Frames pre ';
            app.FramesnumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.FramesnumberEditField.Value = 50;
            app.FramesnumberEditFieldLabel = uilabel(app.UIFigure);
            app.FramesnumberEditFieldLabel.Text = 'Frames number ';
            app.ShowburstframesButton = uibutton(app.UIFigure, 'push');
            app.ShowburstframesButton.ButtonPushedFcn = createCallbackFcn(app, @ShowburstframesButtonPushed, true);
            app.ShowburstframesButton.Text = 'Show burst frames';
            app.FramerangeEditField1 = uieditfield(app.UIFigure, 'numeric');
            app.FramerangeEditField1.Value = -Inf;
            app.FramerangeEditFieldLabel = uilabel(app.UIFigure);
            app.FramerangeEditFieldLabel.Text = 'Frame range ';
            app.FramerangeEditField2 = uieditfield(app.UIFigure, 'numeric');
            app.FramerangeEditField2.Value = Inf;
            app.MakevideoclipsCheckBox = uicheckbox(app.UIFigure);
            app.MakevideoclipsCheckBox.Text = 'Make video clips';
            app.MakevideoclipsCheckBox.Value = true;
            app.FramerateSliderLabel = uilabel(app.UIFigure);
            app.FramerateSliderLabel.Text = 'Frame rate';
            app.FramerateSlider = uislider(app.UIFigure);
            app.FramerateSlider.MajorTicks = [0 100];
            app.FramerateSlider.MajorTickLabels = {'Slow', 'Fast'};
            app.FramerateSlider.Value = 100;
            app.OnlyforcorrecttrialsCheckBox = uicheckbox(app.UIFigure);
            app.OnlyforcorrecttrialsCheckBox.Text = 'Only for correct trials';
            app.OnlyforcorrecttrialsCheckBox.Value = true;
            app.NexttrialButton = uibutton(app.UIFigure, 'push');
            app.NexttrialButton.ButtonPushedFcn = createCallbackFcn(app, @NexttrialButtonPushed, true);
            app.NexttrialButton.Text = 'Next trial';
            app.TimeSliderLabel = uilabel(app.UIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.UIFigure);
            app.OnlyforcorrecttrialsCheckBoxTop = uicheckbox(app.UIFigure);
            app.OnlyforcorrecttrialsCheckBoxTop.Text = 'Only for correct trials';
            app.OnlyforcorrecttrialsCheckBoxTop.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EphysVideoAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end