classdef GetVideoDetail < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        PerformanceEditField       matlab.ui.control.EditField
        PerformanceEditFieldLabel  matlab.ui.control.Label
        NextvideoButton            matlab.ui.control.Button
        SavedatatomatButton        matlab.ui.control.Button
        FramenumEditField          matlab.ui.control.EditField
        FramenumEditFieldLabel     matlab.ui.control.Label
        MetafileEditField          matlab.ui.control.EditField
        MetafileEditFieldLabel     matlab.ui.control.Label
        DLCfileEditField           matlab.ui.control.EditField
        DLCfileEditFieldLabel      matlab.ui.control.Label
        VidnameEditField           matlab.ui.control.EditField
        VidnameEditFieldLabel      matlab.ui.control.Label
        LoadvideoButton            matlab.ui.control.Button
        CenterPanel                matlab.ui.container.Panel
        Next10FramesButton         matlab.ui.control.Button
        Previous10FramesButton     matlab.ui.control.Button
        NextButton                 matlab.ui.control.Button
        PreviousButton             matlab.ui.control.Button
        Slider                     matlab.ui.control.Slider
        UIAxes                     matlab.ui.control.UIAxes
        RightPanel                 matlab.ui.container.Panel
        HandButtonGroup            matlab.ui.container.ButtonGroup
        BothButton                 matlab.ui.control.RadioButton
        RightButton                matlab.ui.control.RadioButton
        LeftButton                 matlab.ui.control.RadioButton
        HighestEditField           matlab.ui.control.EditField
        HighestEditFieldLabel      matlab.ui.control.Label
        StartEditField             matlab.ui.control.EditField
        StartEditFieldLabel        matlab.ui.control.Label
        FillHighestButton          matlab.ui.control.Button
        FillStartButton            matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadvideoButton
        function LoadvideoButtonPushed(app, event)
            set(app.UIFigure,'renderer','opengl')
            % open selection
            [vfilename, ~] = uigetfile('RawVideo/*.avi', 'Select a file') ;
            meta_filename = ['MatFile/',vfilename(1:end-4),'.mat'];
            dlcTable_name = dir(['RawVideo/*',vfilename(1:end-4),'*.csv']);
            dlcTable_name = fullfile('RawVideo/',dlcTable_name.name);
            vfilename = fullfile('RawVideo/',vfilename);
            

            app.LoadvideoButton.UserData.vfilename = vfilename;
            app.LoadvideoButton.UserData.metaname = meta_filename;
            app.LoadvideoButton.UserData.dlcTable = dlcTable_name;
            
            app.VidnameEditField.Value = vfilename;
            app.DLCfileEditField.Value = dlcTable_name;
            app.MetafileEditField.Value = meta_filename;
            
%             % start to update video file
%             ind_body = find(strcmp(S(2, :), app.BodypartsDropDown.Value));
%             nframes   =       D(:, 1);
%             x_pos       =       D(:, ind_body(1));
%             y_pos       =       D(:, ind_body(2));
%             lh              =       D(:, ind_body(3));
            
            load(app.LoadvideoButton.UserData.metaname);
            n_allframes = VideoInfo.total_frames;
            app.Slider.UserData{1} = n_allframes;
            app.PerformanceEditField.Value = VideoInfo.Performance;
            
            if isfield(VideoInfo,'LiftStartFrameNum')
                app.StartEditField.Value = num2str(VideoInfo.LiftStartFrameNum);
            else
                app.StartEditField.Value = '';
            end
            
            if isfield(VideoInfo,'LiftHighestFrameNum')
                app.HighestEditField.Value = num2str(VideoInfo.LiftHighestFrameNum);
            else
                app.HighestEditField.Value = '';
            end

            if isfield(VideoInfo,'Hand')
                if strcmp(VideoInfo.Hand,'Left')
                    app.LeftButton.Value = 1;
                elseif strcmp(VideoInfo.Hand,'Right')
                    app.RightButton.Value = 1;
                else
                    app.BothButton.Value = 1;
                end
            else
                app.LeftButton.Value = 1;
            end

            % plot first frame on the video axis
            cla(app.UIAxes)
            ind_this = 1;
            
            this_video = vfilename;
            vidObj = VideoReader(this_video);
            tic
            AllFrames = read(vidObj, [1, n_allframes]);
            app.UIAxes.UserData{1} = AllFrames;
            toc
            
            this_frame = AllFrames(:, :, :, ind_this);
            app.FramenumEditField.Value =  num2str(ind_this);
            
            
            vidpos = app.UIAxes.Position;
            height = vidpos(3);
            newwidth = height*size(this_frame, 1)/size(this_frame, 2);
            vidpos(4) = newwidth;
            disp(size(this_frame))
            set(app.UIAxes,'position', vidpos,  'nextplot', 'add',...
                'ylim',[0 size(this_frame, 1)], 'xlim', [0 size(this_frame, 2)], 'ydir','reverse')
            
            app.UIAxes.UserData{3} = image(app.UIAxes, this_frame);
            
            
            % update slider data
            app.Slider.Limits = [1 n_allframes];
            app.Slider.Value = ind_this;
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            clc
            ind_this = round(value);
            tic
            sprintf('Plot new frame')

            app.FramenumEditField.Value =  num2str(ind_this);
            
            this_frame = app.UIAxes.UserData{1}(:, :, :, ind_this);
            app.UIAxes.UserData{3}.CData = this_frame;
            
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            
            verticalScrollCount = event.VerticalScrollCount;
            
            value = app.Slider.Value;
            newvalue = value + verticalScrollCount;
            newvalue = max([1 newvalue]);
            app.Slider.Value = newvalue;
            value = newvalue;
            
            clc
            ind_this = round(value);
            tic
            sprintf('Plot new frame')
            
            app.FramenumEditField.Value =  num2str(ind_this);
            
            this_frame = app.UIAxes.UserData{1}(:, :, :, ind_this);
            app.UIAxes.UserData{3}.CData = this_frame;


        end

        % Callback function
        function SelectrangeButtonPushed(app, event)
            % select the rannge
            rect = getrect(app.UIAxes2);
            
            posdata = app.BodypartsDropDown.UserData;
            
            x_pos   =   posdata(:, 1);
            y_pos   =   posdata(:, 2);
            lh      =   posdata(:, 3);
            frs     =   (1:length(lh))';
            ind_chosen = find(frs>=rect(1) & frs<=sum(rect([1 3])) & lh>=rect(2) & lh <= sum(rect([2 4])));
            
            if isempty(app.SelectrangeButton.UserData)
                app.SelectrangeButton.UserData{1} = ind_chosen;
                app.SelectrangeButton.UserData{2} = plot(app.UIAxes2, frs(ind_chosen), lh(ind_chosen), 'g.', 'markersize', 5);
                app.UIAxes3.UserData{2} = plot(app.UIAxes3, x_pos(ind_chosen), y_pos(ind_chosen), 'go', 'markersize', 4);
            else
                app.SelectrangeButton.UserData{1} = ind_chosen;
                set(app.SelectrangeButton.UserData{2}, 'XData', frs(ind_chosen), 'YData', lh(ind_chosen));
                set(app.UIAxes3.UserData{2}, 'XData',  x_pos(ind_chosen), 'YData', y_pos(ind_chosen))
            end
            
            %             %% plot trajectory
            %
            %             t_chosen = 1:length(ind_chosen);
            %             x_chosen = x_pos(ind_chosen);
            %             y_chosen = y_pos(ind_chosen);
            %
            %             tnew = resample(t_chosen, 10, 1);
            %             x_chosennew = interp1(t_chosen, x_chosen, tnew);
            %             y_chosennew = interp1(t_chosen, y_chosen, tnew);
            %             app.UIAxes3.UserData{3} = plot(app.UIAxes3, x_chosennew, y_chosennew, 'color', [1 0.8 0.2]);
            
        end

        % Callback function
        function UndoButtonPushed(app, event)

            posdata = app.BodypartsDropDown.UserData;

            x_pos   =   posdata(:, 1);
            y_pos   =   posdata(:, 2);
            lh      =   posdata(:, 3);
            frs     =   [1:length(lh)]';

            ind_chosen = [];

            app.SelectrangeButton.UserData{1} = ind_chosen;
            set(app.SelectrangeButton.UserData{2}, 'XData', [], 'YData', []);
            set(app.UIAxes3.UserData{2}, 'XData',  [], 'YData', [])


        end

        % Callback function
        function ManualtrackButtonPushed(app, event)

        end

        % Button pushed function: FillStartButton
        function FillStartButtonPushed(app, event)
            value = app.Slider.Value;
            ind_this = round(value);
            app.StartEditField.Value = num2str(ind_this);
        end

        % Callback function
        function FillEndButtonPushed(app, event)

        end

        % Callback function
        function PlottrajectoryButtonPushed(app, event)

        end

        % Button pushed function: SavedatatomatButton
        function SavedatatomatButtonPushed(app, event)
           load(app.MetafileEditField.Value)
           if ~isempty(app.StartEditField.Value) && ~strcmp(app.StartEditField.Value,'NaN')
                VideoInfo.LiftStartFrameNum = str2double(app.StartEditField.Value);
                VideoInfo.LiftStartTime = VideoInfo.VideoFrameTime(VideoInfo.LiftStartFrameNum);
           else
               VideoInfo.LiftStartFrameNum = NaN;
               VideoInfo.LiftStartTime = NaN;
           end
           if ~isempty(app.HighestEditField.Value) && ~strcmp(app.HighestEditField.Value,'NaN')
                VideoInfo.LiftHighestFrameNum = str2double(app.HighestEditField.Value);
                VideoInfo.LiftHighestTime = VideoInfo.VideoFrameTime(VideoInfo.LiftHighestFrameNum);
           else
                VideoInfo.LiftHighestFrameNum = NaN;
                VideoInfo.LiftHighestTime = NaN;
           end
           VideoInfo.Hand = app.HandButtonGroup.SelectedObject.Text;
           save(app.MetafileEditField.Value,"VideoInfo")
        end

        % Button pushed function: NextvideoButton
        function NextvideoButtonPushed(app, event)
           load(app.MetafileEditField.Value)
           if ~isempty(app.StartEditField.Value) && ~strcmp(app.StartEditField.Value,'NaN')
                VideoInfo.LiftStartFrameNum = str2double(app.StartEditField.Value);
                VideoInfo.LiftStartTime = VideoInfo.VideoFrameTime(VideoInfo.LiftStartFrameNum);
           else
               VideoInfo.LiftStartFrameNum = NaN;
               VideoInfo.LiftStartTime = NaN;
           end
           if ~isempty(app.HighestEditField.Value) && ~strcmp(app.HighestEditField.Value,'NaN')
                VideoInfo.LiftHighestFrameNum = str2double(app.HighestEditField.Value);
                VideoInfo.LiftHighestTime = VideoInfo.VideoFrameTime(VideoInfo.LiftHighestFrameNum);
           else
                VideoInfo.LiftHighestFrameNum = NaN;
                VideoInfo.LiftHighestTime = NaN;
           end
           VideoInfo.Hand = app.HandButtonGroup.SelectedObject.Text;
           save(app.MetafileEditField.Value,"VideoInfo")
           
            [~,name,ext] = fileparts(app.LoadvideoButton.UserData.vfilename);
            temp_vfilename = [name,ext];
            AllFiles = dir('RawVideo/*Press*.avi');
            AllVidFiles=arrayfun(@(x)x.name, AllFiles, 'UniformOutput', false);
            current_indx = find(strcmp(AllVidFiles,temp_vfilename));
            
            if current_indx == length(AllVidFiles)
                disp('Done!')
                return
            end
            
            next_indx = current_indx + 1;
            
            temp_filename = AllVidFiles{next_indx};
            vfilename = fullfile('RawVideo/',temp_filename);
            meta_filename = fullfile('MatFile/',[temp_filename(1:end-4),'.mat']);
            
            load(meta_filename)
            while ~strcmp(VideoInfo.Performance,'Correct')
                next_indx = next_indx + 1;
                if next_indx > length(AllVidFiles)
                    disp('Done!')
                    return
                end
                temp_filename = AllVidFiles{next_indx};
                vfilename = fullfile('RawVideo/',temp_filename);
                meta_filename = fullfile('MatFile/',[temp_filename(1:end-4),'.mat']);
                load(meta_filename)
            end
            
            dlcTable_name = dir(['RawVideo/*',temp_filename(1:end-4),'*.csv']);
            dlcTable_name = fullfile('RawVideo/',dlcTable_name.name);
            
            app.LoadvideoButton.UserData.vfilename = vfilename;
            app.LoadvideoButton.UserData.metaname = meta_filename;
            app.LoadvideoButton.UserData.dlcTable = dlcTable_name;
            
            app.VidnameEditField.Value = vfilename;
            app.DLCfileEditField.Value = dlcTable_name;
            app.MetafileEditField.Value = meta_filename;
            
%             % start to update video file
%             ind_body = find(strcmp(S(2, :), app.BodypartsDropDown.Value));
%             nframes   =       D(:, 1);
%             x_pos       =       D(:, ind_body(1));
%             y_pos       =       D(:, ind_body(2));
%             lh              =       D(:, ind_body(3));
            
            load(app.LoadvideoButton.UserData.metaname);
            n_allframes = VideoInfo.total_frames;
            app.Slider.UserData{1} = n_allframes;
            app.PerformanceEditField.Value = VideoInfo.Performance;
            
            if isfield(VideoInfo,'LiftStartFrameNum')
                app.StartEditField.Value = num2str(VideoInfo.LiftStartFrameNum);
            else
                app.StartEditField.Value = '';
            end
            
            if isfield(VideoInfo,'LiftHighestFrameNum')
                app.HighestEditField.Value = num2str(VideoInfo.LiftHighestFrameNum);
            else
                app.HighestEditField.Value = '';
            end

            if isfield(VideoInfo,'Hand')
                if strcmp(VideoInfo.Hand,'Left')
                    app.LeftButton.Value = 1;
                elseif strcmp(VideoInfo.Hand,'Right')
                    app.RightButton.Value = 1;
                else
                    app.BothButton.Value = 1;
                end
            else
                app.LeftButton.Value = 1;
            end
            
            % plot first frame on the video axis
            cla(app.UIAxes)
            ind_this = 1;


            this_video = vfilename;
            vidObj = VideoReader(this_video);
            tic
            AllFrames = read(vidObj, [1, n_allframes]);
            app.UIAxes.UserData{1} = AllFrames;
            toc
            
            this_frame = AllFrames(:, :, :, ind_this);
            app.FramenumEditField.Value =  num2str(ind_this);


            vidpos = app.UIAxes.Position;
            height = vidpos(3);
            newwidth = height*size(this_frame, 1)/size(this_frame, 2);
            vidpos(4) = newwidth;
                    
            set(app.UIAxes,'position', vidpos,  'nextplot', 'add',...
                'xlim',[0 size(this_frame, 2)], 'ylim', [0 size(this_frame, 1)], 'ydir','reverse')
            
            app.UIAxes.UserData{3} = image(app.UIAxes, this_frame);
            
            % update slider data
            app.Slider.Limits = [1 n_allframes];
            app.Slider.Value = ind_this;
           
            
        end

        % Button pushed function: FillHighestButton
        function FillHighestButtonPushed(app, event)
            value = app.Slider.Value;
            ind_this = round(value);
            app.HighestEditField.Value = num2str(ind_this);
        end

        % Callback function
        function FillLiftButtonPushed(app, event)
            value = app.Slider.Value;
            ind_this = round(value);
            app.LiftEditField.Value = num2str(ind_this);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            value = app.Slider.Value;
            ind_this = round(value+1);
            if ind_this > app.Slider.UserData{1}
                ind_this = app.Slider.UserData{1};
            end
            app.Slider.Value = ind_this;
            app.FramenumEditField.Value =  num2str(ind_this);
            
            this_frame = app.UIAxes.UserData{1}(:, :, :, ind_this);
            app.UIAxes.UserData{3}.CData = this_frame;
        end

        % Button pushed function: Next10FramesButton
        function Next10FramesButtonPushed(app, event)
            value = app.Slider.Value;
            ind_this = round(value+10);
            if ind_this > app.Slider.UserData{1}
                ind_this = app.Slider.UserData{1};
            end
            app.Slider.Value = ind_this;
            app.FramenumEditField.Value =  num2str(ind_this);
            
            this_frame = app.UIAxes.UserData{1}(:, :, :, ind_this);
            app.UIAxes.UserData{3}.CData = this_frame;
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            value = app.Slider.Value;
            ind_this = round(value-1);
            if ind_this <= 0
                ind_this = 1;
            end
            app.Slider.Value = ind_this;
            app.FramenumEditField.Value =  num2str(ind_this);
            
            this_frame = app.UIAxes.UserData{1}(:, :, :, ind_this);
            app.UIAxes.UserData{3}.CData = this_frame;
        end

        % Button pushed function: Previous10FramesButton
        function Previous10FramesButtonPushed(app, event)
            value = app.Slider.Value;
            ind_this = round(value-10);
            if ind_this <= 0
                ind_this = 1;
            end
            app.Slider.Value = ind_this;
            app.FramenumEditField.Value =  num2str(ind_this);
            
            this_frame = app.UIAxes.UserData{1}(:, :, :, ind_this);
            app.UIAxes.UserData{3}.CData = this_frame;
        end

        % Selection changed function: HandButtonGroup
        function HandButtonGroupSelectionChanged(app, event)
            selectedButton = app.HandButtonGroup.SelectedObject;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {750, 750, 750};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {750, 750};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {247, '1x', 332};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadvideoButton = uibutton(app.LeftPanel, 'push');
            app.LoadvideoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadvideoButtonPushed, true);
            app.LoadvideoButton.Text = 'Load video';
            app.VidnameEditFieldLabel = uilabel(app.LeftPanel);
            app.VidnameEditFieldLabel.Tag = 'vidname';
            app.VidnameEditFieldLabel.Text = 'Vid name';
            app.VidnameEditField = uieditfield(app.LeftPanel, 'text');
            app.VidnameEditField.Tag = 'vidfilename';
            app.DLCfileEditFieldLabel = uilabel(app.LeftPanel);
            app.DLCfileEditFieldLabel.Text = {'DLC file'; ''};
            app.DLCfileEditField = uieditfield(app.LeftPanel, 'text');
            app.DLCfileEditField.Tag = 'DLCfile';
            app.MetafileEditFieldLabel = uilabel(app.LeftPanel);
            app.MetafileEditFieldLabel.Text = 'Meta file';
            app.MetafileEditField = uieditfield(app.LeftPanel, 'text');
            app.FramenumEditFieldLabel = uilabel(app.LeftPanel);
            app.FramenumEditFieldLabel.Text = {'Frame num'; ''};
            app.FramenumEditField = uieditfield(app.LeftPanel, 'text');
            app.FramenumEditField.Tag = 'framenum';
            app.SavedatatomatButton = uibutton(app.LeftPanel, 'push');
            app.SavedatatomatButton.ButtonPushedFcn = createCallbackFcn(app, @SavedatatomatButtonPushed, true);
            app.SavedatatomatButton.Text = {'Save data to mat'; ''};
            app.NextvideoButton = uibutton(app.LeftPanel, 'push');
            app.NextvideoButton.ButtonPushedFcn = createCallbackFcn(app, @NextvideoButtonPushed, true);
            app.NextvideoButton.Text = 'Next video';
            app.PerformanceEditFieldLabel = uilabel(app.LeftPanel);
            app.PerformanceEditFieldLabel.Text = 'Performance';
            app.PerformanceEditField = uieditfield(app.LeftPanel, 'text');
            app.PerformanceEditField.Tag = 'DLCfile';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YDir = 'reverse';
            app.Slider = uislider(app.CenterPanel);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Tag = 'videoslider';
            app.PreviousButton = uibutton(app.CenterPanel, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.CenterPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.Previous10FramesButton = uibutton(app.CenterPanel, 'push');
            app.Previous10FramesButton.ButtonPushedFcn = createCallbackFcn(app, @Previous10FramesButtonPushed, true);
            app.Previous10FramesButton.Text = 'Previous 10 Frames';
            app.Next10FramesButton = uibutton(app.CenterPanel, 'push');
            app.Next10FramesButton.ButtonPushedFcn = createCallbackFcn(app, @Next10FramesButtonPushed, true);
            app.Next10FramesButton.Text = 'Next 10 Frames';
            app.RightPanel = uipanel(app.GridLayout);
            app.FillStartButton = uibutton(app.RightPanel, 'push');
            app.FillStartButton.ButtonPushedFcn = createCallbackFcn(app, @FillStartButtonPushed, true);
            app.FillStartButton.Text = 'Fill Start';
            app.FillHighestButton = uibutton(app.RightPanel, 'push');
            app.FillHighestButton.ButtonPushedFcn = createCallbackFcn(app, @FillHighestButtonPushed, true);
            app.FillHighestButton.Text = 'Fill Highest';
            app.StartEditFieldLabel = uilabel(app.RightPanel);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.RightPanel, 'text');
            app.HighestEditFieldLabel = uilabel(app.RightPanel);
            app.HighestEditFieldLabel.Text = 'Highest';
            app.HighestEditField = uieditfield(app.RightPanel, 'text');
            app.HandButtonGroup = uibuttongroup(app.RightPanel);
            app.HandButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @HandButtonGroupSelectionChanged, true);
            app.HandButtonGroup.Title = 'Hand';
            app.LeftButton = uiradiobutton(app.HandButtonGroup);
            app.LeftButton.Text = 'Left';
            app.LeftButton.Value = true;
            app.RightButton = uiradiobutton(app.HandButtonGroup);
            app.RightButton.Text = 'Right';
            app.BothButton = uiradiobutton(app.HandButtonGroup);
            app.BothButton.Text = 'Both';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GetVideoDetail

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end