classdef merge_r < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Label                     matlab.ui.control.Label
        Lamp                      matlab.ui.control.Lamp
        Saver_allButton           matlab.ui.control.Button
        UndoButton                matlab.ui.control.Button
        NewOneButton              matlab.ui.control.Button
        SameButton                matlab.ui.control.Button
        SkipButton                matlab.ui.control.Button
        CompareUnitsButton        matlab.ui.control.Button
        PickThisrButton           matlab.ui.control.Button
        NextButton                matlab.ui.control.Button
        PreviousButton            matlab.ui.control.Button
        ImageNew                  matlab.ui.control.Image
        Image                     matlab.ui.control.Image
        ShowExampleUnitButton     matlab.ui.control.Button
        LoadNewrButton            matlab.ui.control.Button
        AnimalNameEditField       matlab.ui.control.EditField
        AnimalNameEditFieldLabel  matlab.ui.control.Label
        LoadExistingr_allButton   matlab.ui.control.Button
    end

    
    properties (Access = public)
        r
        r_path
        r_all
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadNewrButton
        function LoadNewrButtonPushed(app, event)
            [r_filename, r_pathname] = uigetfile('*.mat');
            temp = load(fullfile(r_pathname,r_filename));
            r_this = temp.r;
            app.r_path = r_pathname;
            r_this.path = r_pathname;
            r_this.filename = r_filename;
            if isfield(r_this,'Analog')
                r_this = rmfield(r_this,'Analog');
            end
            if isfield(r_this,'Video')
                r_this = rmfield(r_this,'Video');
            end
            for k = 1:length(r_this.Units.SpikeTimes)
                r_this.Units.SpikeTimes(k).wave = mean(r_this.Units.SpikeTimes(k).wave);
            end   
            app.AnimalNameEditField.Value = r_this.Meta(1).Subject;
            app.r = r_this;
            app.Lamp.Color = [1,0,0];
        end

        % Button pushed function: ShowExampleUnitButton
        function ShowExampleUnitButtonPushed(app, event)
            su = app.r.Units.SpikeNotes(app.r.Units.SpikeNotes(:,3)==1,:);
            img_path = fullfile(app.r_path,['Fig/Ch',num2str(su(1,1)),'_Unit',num2str(su(1,2)),'.png']);
            app.Image.ImageSource = img_path;
            app.Image.UserData = {1,su};
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            num = app.Image.UserData{1};
            su = app.Image.UserData{2};
            if num ~= 1
                num = num-1;
            end
            if isa(su,'table')
                temp = su(num,:).rIndex_RawChannel_Number{1};
                r_index = temp(end,1);
                channel = temp(end,2);
                number = temp(end,3);
                img_path = fullfile(app.r_all.r{r_index}.path,['Fig/Ch',num2str(channel),'_Unit',num2str(number),'.png']);
                app.Image.ImageSource = img_path;
                app.Image.UserData = {num,su}; 
                app.Label.Text = [num2str(num),'/',num2str(size(su,1))];
            else
                img_path = fullfile(app.r_path,['Fig/Ch',num2str(su(num,1)),'_Unit',num2str(su(num,2)),'.png']);
                app.Image.ImageSource = img_path;
                app.Image.UserData = {num,su};
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            num = app.Image.UserData{1};
            su = app.Image.UserData{2};
            if num ~= size(su,1)
                num = num+1;
            end
            if isa(su,'table')
                temp = su(num,:).rIndex_RawChannel_Number{1};
                r_index = temp(end,1);
                channel = temp(end,2);
                number = temp(end,3);
                img_path = fullfile(app.r_all.r{r_index}.path,['Fig/Ch',num2str(channel),'_Unit',num2str(number),'.png']);
                app.Image.ImageSource = img_path;
                app.Image.UserData = {num,su};
                app.Label.Text = [num2str(num),'/',num2str(size(su,1))];
            else
                img_path = fullfile(app.r_path,['Fig/Ch',num2str(su(num,1)),'_Unit',num2str(su(num,2)),'.png']);
                app.Image.ImageSource = img_path;
                app.Image.UserData = {num,su}; 
            end
        end

        % Button pushed function: PickThisrButton
        function PickThisrButtonPushed(app, event)
            if isempty(app.r_all) && (~isempty(app.r))
                app.r_all.AnimalName = app.AnimalNameEditField.Value;
                app.r_all.r = {app.r};
                app.Lamp.Color = [0,1,0];
            elseif ~isempty(app.r_all)
                flag = true;
                for k = 1:length(app.r_all.r)
                    if strcmp(app.r_all.r{k}.path,app.r.path)
                        flag = false;
                    end
                end
                if flag
                    app.r_all.r{end+1} = app.r;
                    app.Lamp.Color = [0,1,0];
                end
            end
        end

        % Button pushed function: CompareUnitsButton
        function CompareUnitsButtonPushed(app, event)
            su = app.r.Units.SpikeNotes(app.r.Units.SpikeNotes(:,3)==1,:);
            img_path = fullfile(app.r_path,['Fig/Ch',num2str(su(1,1)),'_Unit',num2str(su(1,2)),'.png']);
            app.ImageNew.ImageSource = img_path;
            app.ImageNew.UserData = {1,su};
            
            if isfield(app.r_all,'UnitsCombined')
                channels = table2array(app.r_all.UnitsCombined(:,'Channel'));
                su_existing = app.r_all.UnitsCombined(channels==su(1,1),:);
                app.Image.UserData = {1,su_existing};
                if size(su_existing,1)>0
                    unit_info = table2array(su_existing(1,'rIndex_RawChannel_Number'));
                    unit_info = unit_info{1};
                    r_index = unit_info(end,1);
                    raw_channel = unit_info(end,2);
                    raw_number = unit_info(end,3);
                    r_path_this = app.r_all.r{r_index}.path;
                    img_path = fullfile(r_path_this,['Fig/Ch',num2str(raw_channel),'_Unit',num2str(raw_number),'.png']);
                    app.Image.ImageSource = img_path;
                    app.Label.Text = ['1/',num2str(size(su_existing,1))];
                else
                    app.Image.ImageSource = '';
                    app.Label.Text = '0/0';
                end
            else
                app.Image.ImageSource = '';   
                app.Label.Text = '0/0';
            end
        end

        % Button pushed function: SkipButton
        function SkipButtonPushed(app, event)
            num = app.ImageNew.UserData{1};
            su = app.ImageNew.UserData{2};
            if num ~= size(su,1)
                num = num+1;
            end
            img_path = fullfile(app.r_path,['Fig/Ch',num2str(su(num,1)),'_Unit',num2str(su(num,2)),'.png']);
            app.ImageNew.ImageSource = img_path;
            app.ImageNew.UserData = {num,su}; 
            
            if isfield(app.r_all,'UnitsCombined')
                channels = table2array(app.r_all.UnitsCombined(:,'Channel'));
                su_existing = app.r_all.UnitsCombined(channels==su(num,1),:);
                app.Image.UserData = {1,su_existing};
                if size(su_existing,1)>0
                    unit_info = table2array(su_existing(1,'rIndex_RawChannel_Number'));
                    unit_info = unit_info{1};
                    r_index = unit_info(end,1);
                    raw_channel = unit_info(end,2);
                    raw_number = unit_info(end,3);
                    r_path_this = app.r_all.r{r_index}.path;
                    img_path = fullfile(r_path_this,['Fig/Ch',num2str(raw_channel),'_Unit',num2str(raw_number),'.png']);
                    app.Image.ImageSource = img_path;
                    app.Label.Text = [num2str(num),'/',num2str(size(su_existing,1))];
                else
                    app.Image.ImageSource = '';
                    app.Label.Text = '0/0';
                end
            end
            
        end

        % Button pushed function: NewOneButton
        function NewOneButtonPushed(app, event)
            num = app.ImageNew.UserData{1};
            su = app.ImageNew.UserData{2};
            Channel = su(num,1);
            rIndex_RawChannel_Number = {[length(app.r_all.r),su(num,1:2)]};
            if ~isfield(app.r_all,'UnitsCombined')
                Number = 1;
                tbl = table(Channel,Number,rIndex_RawChannel_Number);
                app.r_all.UnitsCombined = tbl;
            else
                Number = sum(table2array(app.r_all.UnitsCombined(:,'Channel'))==Channel)+1;
                temp_tbl = app.r_all.UnitsCombined(app.r_all.UnitsCombined.Channel==Channel,:);
                flag = true;
                for k = 1:size(temp_tbl,1)
                    if any(all(temp_tbl(k,:).rIndex_RawChannel_Number{1}==rIndex_RawChannel_Number{1},2))
                        flag = false;
                    end
                end
                if flag
                    app.r_all.UnitsCombined = [app.r_all.UnitsCombined;{Channel,Number,rIndex_RawChannel_Number}];
                end
            end
            
            app.r_all.UnitsCombined = sortrows(app.r_all.UnitsCombined,{'Channel','Number'});
            
            if num ~= size(su,1)
                num = num+1;
            end
            img_path = fullfile(app.r_path,['Fig/Ch',num2str(su(num,1)),'_Unit',num2str(su(num,2)),'.png']);
            app.ImageNew.ImageSource = img_path;
            app.ImageNew.UserData = {num,su}; 
            
            if isfield(app.r_all,'UnitsCombined')
                channels = table2array(app.r_all.UnitsCombined(:,'Channel'));
                su_existing = app.r_all.UnitsCombined(channels==su(num,1),:);
                app.Image.UserData = {1,su_existing};
                if size(su_existing,1)>0
                    unit_info = table2array(su_existing(1,'rIndex_RawChannel_Number'));
                    unit_info = unit_info{1};
                    r_index = unit_info(end,1);
                    raw_channel = unit_info(end,2);
                    raw_number = unit_info(end,3);
                    r_path_this = app.r_all.r{r_index}.path;
                    img_path = fullfile(r_path_this,['Fig/Ch',num2str(raw_channel),'_Unit',num2str(raw_number),'.png']);
                    app.Image.ImageSource = img_path;
                    app.Label.Text = ['1/',num2str(size(su_existing,1))];
                else
                    app.Image.ImageSource = ''; 
                    app.Label.Text = '0/0';
                end
            end
        end

        % Button pushed function: SameButton
        function SameButtonPushed(app, event)
            num_new = app.ImageNew.UserData{1};
            su_new = app.ImageNew.UserData{2};
            
            num = app.Image.UserData{1};
            su_existing = app.Image.UserData{2};
            if size(su_existing,1)==0
                return
            end
            Channel = table2array(su_existing(num,'Channel'));
            Number = table2array(su_existing(num,'Number'));     
            
            tbl = app.r_all.UnitsCombined;
            rIndex_RawChannel_Number = app.r_all.UnitsCombined(tbl.Channel == Channel & tbl.Number == Number,:).rIndex_RawChannel_Number;
            rIndex_RawChannel_Number{1} = unique([rIndex_RawChannel_Number{1};[length(app.r_all.r),su_new(num_new,1:2)]],'rows',"stable");
            app.r_all.UnitsCombined(tbl.Channel == Channel & tbl.Number == Number,:).rIndex_RawChannel_Number=rIndex_RawChannel_Number;
            
            if num_new ~= size(su_new,1)
                num_new = num_new+1;
            end
            img_path = fullfile(app.r_path,['Fig/Ch',num2str(su_new(num_new,1)),'_Unit',num2str(su_new(num_new,2)),'.png']);
            app.ImageNew.ImageSource = img_path;
            app.ImageNew.UserData = {num_new,su_new}; 
            
            if isfield(app.r_all,'UnitsCombined')
                channels = table2array(app.r_all.UnitsCombined(:,'Channel'));
                su_existing = app.r_all.UnitsCombined(channels==su_new(num_new,1),:);
                app.Image.UserData = {1,su_existing};
                if size(su_existing,1)>0
                    unit_info = table2array(su_existing(1,'rIndex_RawChannel_Number'));
                    unit_info = unit_info{1};
                    r_index = unit_info(end,1);
                    raw_channel = unit_info(end,2);
                    raw_number = unit_info(end,3);
                    r_path_this = app.r_all.r{r_index}.path;
                    img_path = fullfile(r_path_this,['Fig/Ch',num2str(raw_channel),'_Unit',num2str(raw_number),'.png']);
                    app.Image.ImageSource = img_path;
                    app.Label.Text = [num2str(num),'/',num2str(size(su_existing,1))];
                else
                    app.Image.ImageSource = '';  
                    app.Label.Text = '0/0';
                end
            end

        end

        % Button pushed function: Saver_allButton
        function Saver_allButtonPushed(app, event)
            r_all = app.r_all;
            save('r_all','r_all');
        end

        % Button pushed function: LoadExistingr_allButton
        function LoadExistingr_allButtonPushed(app, event)
            temp = uiopen("load");
            app.r_all = temp.r_all;
            app.AnimalNameEditField.Value = app.r_all.AnimalName;
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            num = app.ImageNew.UserData{1};
            su = app.ImageNew.UserData{2};
            
            if num == size(su,1)
                Channel = su(num,1);
                Number = su(num,2);
                r_index = length(app.r_all.r);
        
                k = 1;
                while k <= size(app.r_all.UnitsCombined,1)
                    temp = app.r_all.UnitsCombined(k,:).rIndex_RawChannel_Number{1};
                    all(temp==[r_index,Channel,Number])
                    temp(all(temp==[r_index,Channel,Number],2),:)=[];
                    if isempty(temp)
                        app.r_all.UnitsCombined(k,:) = [];
                    else
                        app.r_all.UnitsCombined(k,:).rIndex_RawChannel_Number = {temp};
                    end
                    k = k+1;
                end 
            end
            
            if num ~= 1
                num = num-1;               
            end
            img_path = fullfile(app.r_path,['Fig/Ch',num2str(su(num,1)),'_Unit',num2str(su(num,2)),'.png']);
            app.ImageNew.ImageSource = img_path;
            app.ImageNew.UserData = {num,su}; 
            
            Channel = su(num,1);
            Number = su(num,2);
            r_index = length(app.r_all.r);
            
            k = 1;
            while k <= size(app.r_all.UnitsCombined,1)
                temp = app.r_all.UnitsCombined(k,:).rIndex_RawChannel_Number{1};
                temp(all(temp==[r_index,Channel,Number],2),:)=[];
                if isempty(temp)
                    app.r_all.UnitsCombined(k,:) = [];
                else
                    app.r_all.UnitsCombined(k,:).rIndex_RawChannel_Number = {temp};
                end
                k = k+1;
            end
            
            if isfield(app.r_all,'UnitsCombined')
                channels = table2array(app.r_all.UnitsCombined(:,'Channel'));
                su_existing = app.r_all.UnitsCombined(channels==su(num,1),:);
                app.Image.UserData = {1,su_existing};
                if size(su_existing,1)>0
                    unit_info = table2array(su_existing(1,'rIndex_RawChannel_Number'));
                    unit_info = unit_info{1};
                    r_index = unit_info(end,1);
                    raw_channel = unit_info(end,2);
                    raw_number = unit_info(end,3);
                    r_path_this = app.r_all.r{r_index}.path;
                    img_path = fullfile(r_path_this,['Fig/Ch',num2str(raw_channel),'_Unit',num2str(raw_number),'.png']);
                    app.Image.ImageSource = img_path;
                    app.Label.Text = ['1/',num2str(size(su_existing,1))];
                else
                    app.Image.ImageSource = '';   
                    app.Label.Text = '0/0';
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadExistingr_allButton = uibutton(app.UIFigure, 'push');
            app.LoadExistingr_allButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExistingr_allButtonPushed, true);
            app.LoadExistingr_allButton.Text = 'Load Existing r_all';
            app.AnimalNameEditFieldLabel = uilabel(app.UIFigure);
            app.AnimalNameEditFieldLabel.Text = 'Animal Name';
            app.AnimalNameEditField = uieditfield(app.UIFigure, 'text');
            app.LoadNewrButton = uibutton(app.UIFigure, 'push');
            app.LoadNewrButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNewrButtonPushed, true);
            app.LoadNewrButton.Text = 'Load New r';
            app.ShowExampleUnitButton = uibutton(app.UIFigure, 'push');
            app.ShowExampleUnitButton.ButtonPushedFcn = createCallbackFcn(app, @ShowExampleUnitButtonPushed, true);
            app.ShowExampleUnitButton.Text = 'Show Example Unit';
            app.Image = uiimage(app.UIFigure);
            app.ImageNew = uiimage(app.UIFigure);
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PickThisrButton = uibutton(app.UIFigure, 'push');
            app.PickThisrButton.ButtonPushedFcn = createCallbackFcn(app, @PickThisrButtonPushed, true);
            app.PickThisrButton.Text = 'Pick This r';
            app.CompareUnitsButton = uibutton(app.UIFigure, 'push');
            app.CompareUnitsButton.ButtonPushedFcn = createCallbackFcn(app, @CompareUnitsButtonPushed, true);
            app.CompareUnitsButton.Text = 'Compare Units';
            app.SkipButton = uibutton(app.UIFigure, 'push');
            app.SkipButton.ButtonPushedFcn = createCallbackFcn(app, @SkipButtonPushed, true);
            app.SkipButton.Text = 'Skip';
            app.SameButton = uibutton(app.UIFigure, 'push');
            app.SameButton.ButtonPushedFcn = createCallbackFcn(app, @SameButtonPushed, true);
            app.SameButton.Text = 'Same';
            app.NewOneButton = uibutton(app.UIFigure, 'push');
            app.NewOneButton.ButtonPushedFcn = createCallbackFcn(app, @NewOneButtonPushed, true);
            app.NewOneButton.Text = 'New One!';
            app.UndoButton = uibutton(app.UIFigure, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = 'Undo';
            app.Saver_allButton = uibutton(app.UIFigure, 'push');
            app.Saver_allButton.ButtonPushedFcn = createCallbackFcn(app, @Saver_allButtonPushed, true);
            app.Saver_allButton.Text = 'Save r_all';
            app.Lamp = uilamp(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0/0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = merge_r

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end