classdef findSameNeurons < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        DebugTextArea            matlab.ui.control.TextArea
        DebugButton              matlab.ui.control.Button
        RemoveButton             matlab.ui.control.Button
        MergeButton              matlab.ui.control.Button
        SplitButton              matlab.ui.control.Button
        CheckfigBButton          matlab.ui.control.Button
        CheckfigAButton          matlab.ui.control.Button
        ExtractneuroninfoButton  matlab.ui.control.Button
        PreviousButton           matlab.ui.control.Button
        ItisthelastunitLabel     matlab.ui.control.Label
        Lamp                     matlab.ui.control.Lamp
        OnlyunclassifiedButton   matlab.ui.control.StateButton
        OnlysingleunitsButton    matlab.ui.control.StateButton
        Checkr_allButton         matlab.ui.control.StateButton
        loadr_allButton          matlab.ui.control.Button
        UndoButton               matlab.ui.control.Button
        SaveButton               matlab.ui.control.Button
        SampleDropDownB          matlab.ui.control.DropDown
        SampleDropDownBLabel     matlab.ui.control.Label
        SampleDropDownA          matlab.ui.control.DropDown
        SampleDropDownALabel     matlab.ui.control.Label
        NextrButton              matlab.ui.control.Button
        NeuronRightLabel         matlab.ui.control.Label
        NeuronLeftLabel          matlab.ui.control.Label
        NeuronListBoxB           matlab.ui.control.ListBox
        NeuronListBoxBLabel      matlab.ui.control.Label
        SkipButton               matlab.ui.control.Button
        TetrodeDropDown          matlab.ui.control.DropDown
        TetrodeDropDownLabel     matlab.ui.control.Label
        NeuronListBoxA           matlab.ui.control.ListBox
        NeuronListBoxLabel       matlab.ui.control.Label
        SameButton               matlab.ui.control.Button
        NewButton                matlab.ui.control.Button
        NeuronBLabel             matlab.ui.control.Label
        NeuronALabel             matlab.ui.control.Label
        loadrButton              matlab.ui.control.Button
        UIAxesWaveformB          matlab.ui.control.UIAxes
        UIAxesWaveformA          matlab.ui.control.UIAxes
        UIAxesRewardPETH_A       matlab.ui.control.UIAxes
        UIAxesRewardPETH_B       matlab.ui.control.UIAxes
        UIAxesReleasePETH_B      matlab.ui.control.UIAxes
        UIAxesReleasePETH_A      matlab.ui.control.UIAxes
        UIAxesPressPETH_B        matlab.ui.control.UIAxes
        UIAxesPressPETH_A        matlab.ui.control.UIAxes
        UIAxesISI_B              matlab.ui.control.UIAxes
        UIAxesAutoCorrelogramB   matlab.ui.control.UIAxes
        UIAxesISI_A              matlab.ui.control.UIAxes
        UIAxesAutoCorrelogramA   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        r_filenames
        r_idx
        r
        r_all

        % Tetrodes
        tetrodes = [];

        is_only_single_units = true;
        is_only_unclassified = true;

        infoA = [];
        infoB = []; 
    end
    
    methods (Access = private)
        
        function initialize(app)
            set(app.UIFigure, 'Renderer', 'opengl');

            % load r
            app.r_idx = 1;
            input = load(app.r_filenames{app.r_idx});
            app.r = input.r;

            % initialize r_all
            app.r_all.AnimalName = app.r.Meta(1).Subject;
            app.r_all.r = cell(length(app.r_filenames), 1);
            app.r_all.r_filenames = app.r_filenames;
            app.update_r_all();

            % Neuron B
            app.update_infoB(1);
            app.update_axes('B');
        end

        function update_r_all(app)
            r_this.Meta = app.r.Meta;
            r_this.Behavior = app.r.Behavior;
            r_this.BehaviorClass = app.r.BehaviorClass;
            units = app.r.Units;
            for k = 1:length(app.r.Units.SpikeTimes)
                units.SpikeTimes(k).wave = mean(units.SpikeTimes(k).wave);
            end
            r_this.Units = units;
            r_this.path = app.r_filenames{app.r_idx};
            app.r_all.r{app.r_idx} = r_this;
        end

        function update_infoA(app, channel, numInChannel_r_all, idx_sample)
            % clear info
            app.infoA = [];
            
            if ~app.Checkr_allButton.Value && isempty(app.infoB)
                return
            end
            if ~isfield(app.r_all, 'UnitsCombined')
                return
            end

            if nargin<2
                channel = app.infoB.channel;
            end

            Channel = table2array(app.r_all.UnitsCombined(:,'Channel'));
            idx_channel = find(Channel==channel);
            if isempty(idx_channel)
                return
            end


            if nargin<3
                numInChannel_r_all = length(idx_channel);
            end
            
            idx_unit = idx_channel(numInChannel_r_all);

            rIndex_RawChannel_Number = app.r_all.UnitsCombined(idx_unit,:).rIndex_RawChannel_Number{1};

            if nargin<4
                idx_sample = size(rIndex_RawChannel_Number,1);
            end

            rIndex = rIndex_RawChannel_Number(idx_sample,1);
            numInChannel = rIndex_RawChannel_Number(idx_sample,3);

            r_this = app.r_all.r{rIndex};
            unit = find(r_this.Units.SpikeNotes(:,1)==channel & r_this.Units.SpikeNotes(:,2)==rIndex_RawChannel_Number(idx_sample,3));
            if r_this.Units.SpikeNotes(unit,3)==1
                type = 's';
            else
                type = 'm';
            end

            app.infoA.unit = unit;
            app.infoA.channel = channel;
            app.infoA.numInChannel = numInChannel;
            app.infoA.r = r_this;
            app.infoA.r_idx = rIndex;
            app.infoA.type = type;
            app.infoA.sample = idx_sample;
            app.infoA.numInChannel_r_all = numInChannel_r_all;
        end

        function update_infoB(app, unit)
            if nargin<2
                if isnumeric(app.infoB.unit)
                    unit = app.infoB.unit+1;
                else
                    unit = 1;
                end
            end

            if unit>length(app.r.Units.SpikeTimes)
                app.ItisthelastunitLabel.Visible = 'on';
                app.Lamp.Color = 'green';
                app.infoB = [];
                return
            end
            if (app.is_only_single_units && app.r.Units.SpikeNotes(unit,3)==2) ...
                    || (app.is_only_unclassified && app.is_classified(unit))
                unit = unit+1;
                update_infoB(app, unit);
                return
            end

            % clear info
            app.infoB = [];

            app.infoB.r = app.r;
            app.infoB.unit = unit;
            app.infoB.channel = app.r.Units.SpikeNotes(app.infoB.unit,1);
            app.infoB.numInChannel = app.r.Units.SpikeNotes(app.infoB.unit,2);
            if app.r.Units.SpikeNotes(app.infoB.unit,3)==1
                app.infoB.type = 's';
            else
                app.infoB.type = 'm';
            end
            app.infoB.sample = 1;
        end

        function update_infoB_r_all(app, numInChannel_r_all, idx_sample)
            % clear info
            app.infoB = [];
            
            if isempty(app.infoA)
                return
            end
            if ~isfield(app.r_all, 'UnitsCombined')
                return
            end

            channel = app.infoA.channel;

            Channel = table2array(app.r_all.UnitsCombined(:,'Channel'));
            idx_channel = find(Channel==channel);
            if isempty(idx_channel)
                return
            end


            if nargin<2
                numInChannel_r_all = length(idx_channel);
            end
            
            idx_unit = idx_channel(numInChannel_r_all);

            rIndex_RawChannel_Number = app.r_all.UnitsCombined(idx_unit,:).rIndex_RawChannel_Number{1};

            if nargin<3
                idx_sample = size(rIndex_RawChannel_Number,1);
            end

            rIndex = rIndex_RawChannel_Number(idx_sample,1);
            numInChannel = rIndex_RawChannel_Number(idx_sample,3);

            r_this = app.r_all.r{rIndex};
            unit = find(r_this.Units.SpikeNotes(:,1)==channel & r_this.Units.SpikeNotes(:,2)==rIndex_RawChannel_Number(idx_sample,3));
            if r_this.Units.SpikeNotes(unit,3)==1
                type = 's';
            else
                type = 'm';
            end

            app.infoB.unit = unit;
            app.infoB.channel = channel;
            app.infoB.numInChannel = numInChannel;
            app.infoB.r_idx = rIndex;
            app.infoB.r = r_this;
            app.infoB.type = type;
            app.infoB.sample = idx_sample;
            app.infoB.numInChannel_r_all = numInChannel_r_all;
        end

        function update_neuron_list(app, channel, pos)
            % clear
            if nargin<3
                pos = 'A';
            end

            app.(['NeuronListBox',pos]).Items = {};
            app.(['SampleDropDown',pos]).Items = {};

            if isempty(app.(['info',pos]))
                return
            end
            if nargin<2
                channel = app.(['info',pos]).channel;
            end
            Channel = table2array(app.r_all.UnitsCombined(:,'Channel'));
            idx_unit = find(Channel==channel);
            idx_number = app.(['info',pos]).numInChannel_r_all;
            idx_sample = app.(['info',pos]).sample;

            % update TetrodeDropDown
            if ~any(strcmp(app.TetrodeDropDown.Items,num2str(channel)))
                app.TetrodeDropDown.Items = {num2str(channel)};
            end
            app.TetrodeDropDown.Value = num2str(channel);
            
            % update listbox
            values_listbox = cell(length(idx_unit),1);
            for k = 1:length(values_listbox)
                rIndex_RawChannel_Number = app.r_all.UnitsCombined(idx_unit(k),:).rIndex_RawChannel_Number{1};
                n_sample = size(rIndex_RawChannel_Number,1);
                r_this = app.r_all.r{rIndex_RawChannel_Number(end,1)};
                values_listbox{k} = ['Unit#', num2str(k), ' ', datestr(r_this.Meta(1).DateTime,'yyyymmdd'), ' (', num2str(n_sample), ')'];
            end
            app.(['NeuronListBox',pos]).Items = values_listbox;
            app.(['NeuronListBox',pos]).Value = values_listbox{idx_number};

            % update SampleDropDown
            unit = idx_unit(idx_number);
            rIndex_RawChannel_Number = app.r_all.UnitsCombined(unit,:).rIndex_RawChannel_Number{1};
            values_dropdown = cell(size(rIndex_RawChannel_Number,1),1);
            for k = 1:length(values_dropdown)
                r_this = app.r_all.r{rIndex_RawChannel_Number(k,1)};
                idx_unit_r = find(r_this.Units.SpikeNotes(:,1)==channel & r_this.Units.SpikeNotes(:,2)==rIndex_RawChannel_Number(k,3));
                if r_this.Units.SpikeNotes(idx_unit_r,3)==1
                    type = 's';
                else
                    type = 'm';
                end
                values_dropdown{k} = [datestr(r_this.Meta(1).DateTime,'yyyymmdd'),...
                    ' unit#',num2str(rIndex_RawChannel_Number(k,3)),...
                    ' (', type, ')'];                
            end
            app.(['SampleDropDown',pos]).Items = values_dropdown;
            app.(['SampleDropDown',pos]).Value = values_dropdown{idx_sample};          
        end           
        
        function update_axes_all(app)
            app.update_axes('A');
            app.update_axes('B');
            app.set_equal_ylim();
        end
        
        function update_axes(app, pos)
            % clear axes
            app.(['Neuron',pos,'Label']).Text = ['Neuron ',pos];
            cla(app.(['UIAxesAutoCorrelogram',pos]));
            cla(app.(['UIAxesISI_',pos]));
            cla(app.(['UIAxesWaveform',pos]));
            cla(app.(['UIAxesPressPETH_',pos]));
            cla(app.(['UIAxesReleasePETH_',pos]));
            cla(app.(['UIAxesRewardPETH_',pos]));

            if isempty(app.(['info',pos]))
                return
            end

            % Label
            app.(['Neuron',pos,'Label']).Text = [app.(['info',pos]).r.Meta(1).Subject,...
                ' ',datestr(app.(['info',pos]).r.Meta(1).DateTime,'yyyymmdd'),...
                ' tetrode#',num2str(app.(['info',pos]).channel),...
                ' unit#',num2str(app.(['info',pos]).numInChannel),...
                ' (', app.(['info',pos]).type, ')'];
            
            % Auto Correlogram
            if isfield(app.(['info',pos]).r.Units.SpikeTimes, 'autoCorrelogram')
                lag = app.(['info',pos]).r.Units.SpikeTimes(app.(['info',pos]).unit).autoCorrelogram.lag;
                auto_cor = app.(['info',pos]).r.Units.SpikeTimes(app.(['info',pos]).unit).autoCorrelogram.corr;
            else
                binwidth = 1; % ms
                window = 50;
    
                spike_times = app.(['info',pos]).r.Units.SpikeTimes(app.(['info',pos]).unit).timings; % ms
    
                spike_times = round(spike_times-spike_times(1))+1;
                s = zeros(max(spike_times),1);
                s(spike_times) = 1;
                
                [auto_cor, lag] = xcorr(s,s,round(window/binwidth));
                auto_cor(lag==0)=0;
            end

            bar(app.(['UIAxesAutoCorrelogram',pos]), lag, auto_cor);

            % ISI
            spike_times = app.(['info',pos]).r.Units.SpikeTimes(app.(['info',pos]).unit).timings; % ms
            isi = diff(spike_times);
            histogram(app.(['UIAxesISI_',pos]),isi,'BinLimits',[0,100],'BinWidth',1);
            xlim(app.(['UIAxesISI_',pos]),[0,100]);

            % waveform
            waveforms = app.(['info',pos]).r.Units.SpikeTimes(app.(['info',pos]).unit).wave;
            n_waveform = size(waveforms,1);
            if n_waveform>200
                idx_waveform = randperm(n_waveform,200);
                waveforms_mean = mean(waveforms);
            elseif n_waveform == 1
                idx_waveform = 1;
                waveforms_mean = waveforms;
            else
                idx_waveform = 1:n_waveform;
                waveforms_mean = mean(waveforms);
            end

            plot(app.(['UIAxesWaveform',pos]),waveforms_mean','LineWidth',3,'Color','k');
            ylim(app.(['UIAxesWaveform',pos]),'auto');
            ylim_this = app.(['UIAxesWaveform',pos]).YLim;
            plot(app.(['UIAxesWaveform',pos]),waveforms(idx_waveform,:)','Color',[0.7,0.7,0.7]);
            plot(app.(['UIAxesWaveform',pos]),waveforms_mean','LineWidth',3,'Color','k');
            ylim(app.(['UIAxesWaveform',pos]), ylim_this);

            % PETH press
            if isfield(app.(['info',pos]).r.Units.SpikeTimes, 'PETH')
                t_PETH_press = app.(['info',pos]).r.Units.SpikeTimes(app.(['info',pos]).unit).PETH.t_PETH_press;
                PETH_press = app.(['info',pos]).r.Units.SpikeTimes(app.(['info',pos]).unit).PETH.PETH_press;
                t_PETH_release = app.(['info',pos]).r.Units.SpikeTimes(app.(['info',pos]).unit).PETH.t_PETH_release;
                PETH_release = app.(['info',pos]).r.Units.SpikeTimes(app.(['info',pos]).unit).PETH.PETH_release;
                t_PETH_reward = app.(['info',pos]).r.Units.SpikeTimes(app.(['info',pos]).unit).PETH.t_PETH_reward;
                PETH_reward = app.(['info',pos]).r.Units.SpikeTimes(app.(['info',pos]).unit).PETH.PETH_reward;
            else
                idx_press = find(strcmp(app.(['info',pos]).r.Behavior.Labels,'LeverPress'));
                idx_release = find(strcmp(app.(['info',pos]).r.Behavior.Labels,'LeverRelease'));
                idx_reward = find(strcmp(app.(['info',pos]).r.Behavior.Labels,'ValveOnset'));
                idx_correct = app.(['info',pos]).r.Behavior.CorrectIndex;
    
                press_times = app.(['info',pos]).r.Behavior.EventTimings(app.(['info',pos]).r.Behavior.EventMarkers==idx_press);
                release_times = app.(['info',pos]).r.Behavior.EventTimings(app.(['info',pos]).r.Behavior.EventMarkers==idx_release);
                reward_times = app.(['info',pos]).r.Behavior.EventTimings(app.(['info',pos]).r.Behavior.EventMarkers==idx_reward);
    
                press_times = press_times(idx_correct);
                release_times = release_times(idx_correct);
    
                params.pre = 2000;
                params.post = 2000;
                params.binwidth = 10;
                gaussian_kernel = 5; % ms
    
                [PETH_press, t_PETH_press] = jpsth(spike_times, press_times, params);
                [PETH_release, t_PETH_release] = jpsth(spike_times, release_times, params);
                [PETH_reward, t_PETH_reward] = jpsth(spike_times, reward_times, params);
                PETH_press = smoothdata(PETH_press,'gaussian',gaussian_kernel*5);
                PETH_release = smoothdata(PETH_release,'gaussian',gaussian_kernel*5);
                PETH_reward = smoothdata(PETH_reward,'gaussian',gaussian_kernel*5);
            end

            plot(app.(['UIAxesPressPETH_',pos]), t_PETH_press, PETH_press);
            plot(app.(['UIAxesReleasePETH_',pos]), t_PETH_release, PETH_release);
            plot(app.(['UIAxesRewardPETH_',pos]), t_PETH_reward, PETH_reward);

            ylim(app.(['UIAxesPressPETH_',pos]), 'auto');
            ylim(app.(['UIAxesReleasePETH_',pos]), 'auto');
            ylim(app.(['UIAxesRewardPETH_',pos]), 'auto');
        end

        function set_equal_ylim(app)
            if isempty(app.infoA) || isempty(app.infoB)
                return
            end

            equal_ylim(app.UIAxesWaveformA, app.UIAxesWaveformB);
            equal_ylim(app.UIAxesPressPETH_A, app.UIAxesPressPETH_B);
            equal_ylim(app.UIAxesReleasePETH_A, app.UIAxesReleasePETH_B);
            equal_ylim(app.UIAxesRewardPETH_A, app.UIAxesRewardPETH_B);

            function equal_ylim(ax1, ax2)
                ylim1 = ax1.YLim;
                ylim2 = ax2.YLim;
                ylim_new = zeros(2,1);
                ylim_new(1) = min(ylim1(1), ylim2(1));
                ylim_new(2) = max(ylim1(2), ylim2(2));

                ylim(ax1, ylim_new);
                ylim(ax2, ylim_new);
            end
        end

        function out = is_classified(app, unit)
            r_index = app.r_idx;
            channel = app.r.Units.SpikeNotes(unit,1);
            numInChannel = app.r.Units.SpikeNotes(unit,2);

            if ~isfield(app.r_all, 'UnitsCombined')
                out = false;
                return
            end
            for k = 1:height(app.r_all.UnitsCombined)
                if app.r_all.UnitsCombined(k,:).Channel ~= channel
                    continue
                end
                
                rIndex_RawChannel_Number = app.r_all.UnitsCombined(k,:).rIndex_RawChannel_Number{1};
                for j = 1:size(rIndex_RawChannel_Number,1)
                    if all(rIndex_RawChannel_Number(j,:) == [r_index,channel,numInChannel])
                        out = true;
                        return
                    end
                end
            end

            out = false;
        end

        function remove_unit_r_all(app, channel, numInChannel_r_all, r_index, numInChannel)
            % remove this unit in r_all
            tbl = app.r_all.UnitsCombined;
            rIndex_RawChannel_Number = app.r_all.UnitsCombined(tbl.Channel == channel & tbl.Number == numInChannel_r_all,:).rIndex_RawChannel_Number{1};

            for k = 1:size(rIndex_RawChannel_Number,1)
                if all(rIndex_RawChannel_Number(k,:) == [r_index,channel,numInChannel])
                    rIndex_RawChannel_Number(k,:) = [];
                    break
                end
            end

            idx_unit = find(tbl.Channel == channel & tbl.Number == numInChannel_r_all);
            app.r_all.UnitsCombined(idx_unit,:).rIndex_RawChannel_Number{1} = rIndex_RawChannel_Number;

            if isempty(app.r_all.UnitsCombined(idx_unit,:).rIndex_RawChannel_Number{1})
                app.r_all.UnitsCombined(idx_unit,:) = [];
            end
        end

        function insert_unit_r_all(app, r_index, numInChannel)
            channel = app.infoA.channel;
            Number = app.infoA.numInChannel_r_all;
            
            tbl = app.r_all.UnitsCombined;
            rIndex_RawChannel_Number = app.r_all.UnitsCombined(tbl.Channel == channel & tbl.Number == Number,:).rIndex_RawChannel_Number;
            
            rIndex_RawChannel_Number{1} = unique([rIndex_RawChannel_Number{1};[r_index, channel, numInChannel]],'rows',"stable");
            rIndex_RawChannel_Number{1} = sortrows(rIndex_RawChannel_Number{1},[1,3]);
            app.r_all.UnitsCombined(tbl.Channel == channel & tbl.Number == Number,:).rIndex_RawChannel_Number=rIndex_RawChannel_Number;
        end

        function new_unit_r_all(app)
            channel = app.infoB.channel;
            rIndex_RawChannel_Number = {[app.infoB.r_idx, channel, app.infoB.numInChannel]};
            if ~isfield(app.r_all,'UnitsCombined')
                Number = 1;
                tbl = table(channel,Number,rIndex_RawChannel_Number);
                app.r_all.UnitsCombined = tbl;
            else
                Number = sum(table2array(app.r_all.UnitsCombined(:,'Channel'))==channel)+1;
                % check whether this unit is already in the table
                temp_tbl = app.r_all.UnitsCombined(app.r_all.UnitsCombined.Channel==channel,:);
                flag = true;
                for k = 1:size(temp_tbl,1)
                    if any(all(temp_tbl(k,:).rIndex_RawChannel_Number{1}==rIndex_RawChannel_Number{1},2))
                        flag = false;
                    end
                end
                
                if flag
                    % if this unit is not in this table
                    app.r_all.UnitsCombined = [app.r_all.UnitsCombined;{channel,Number,rIndex_RawChannel_Number}];
                end
            end
            app.r_all.UnitsCombined = sortrows(app.r_all.UnitsCombined, [1,2]);
        end

        function update_r_all_number(app)
            % update r_all Number
            app.r_all.UnitsCombined(1,:).Number = 1;
            for k = 2:height(app.r_all.UnitsCombined)
                if app.r_all.UnitsCombined(k,:).Channel == app.r_all.UnitsCombined(k-1,:).Channel
                    app.r_all.UnitsCombined(k,:).Number = app.r_all.UnitsCombined(k-1,:).Number+1;
                else
                    app.r_all.UnitsCombined(k,:).Number = 1;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            modifier = event.Modifier;
            if ismember(modifier,'Control') && key=='s'
                app.SaveButtonPushed();
            end
            if ismember(modifier,'Control') && key=='z'
                app.UndoButtonPushed();
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            r_all = app.r_all;
            save('r_all.mat', 'r_all');
            disp('r_all successfully saved!')
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            if isempty(app.infoB)
                if strcmp(app.ItisthelastunitLabel.Visible, 'on')
                    unit_last = length(app.r.Units.SpikeTimes);
                    while app.is_only_single_units && app.r.Units.SpikeNotes(unit_last,3)==2
                        unit_last = unit_last-1;
                        if unit_last<=0
                            return
                        end
                    end
                    app.Lamp.Color = 'red';
                    app.ItisthelastunitLabel.Visible = "off";
                else
                    return
                end
            else
                unit_last = app.infoB.unit;

                while true
                    unit_last = unit_last-1;
                    if unit_last<=0
                        disp('It is already the first unit!');
                        return
                    end
    
                    if app.is_only_single_units && app.r.Units.SpikeNotes(unit_last,3)==2
                        continue
                    end
    
                    break
                end
            end

            % remove this unit in r_all
            r_index = app.r_idx;
            channel = app.r.Units.SpikeNotes(unit_last,1);
            numInChannel = app.r.Units.SpikeNotes(unit_last,2);
            
            k = 0;
            while k < height(app.r_all.UnitsCombined)
                k = k+1;
                if app.r_all.UnitsCombined(k,:).Channel ~= channel
                    continue
                end
                
                rIndex_RawChannel_Number = app.r_all.UnitsCombined(k,:).rIndex_RawChannel_Number{1};
                for j = 1:size(rIndex_RawChannel_Number,1)
                    if all(rIndex_RawChannel_Number(j,:) == [r_index,channel,numInChannel])
                        rIndex_RawChannel_Number(j,:) = [];
                        app.r_all.UnitsCombined(k,:).rIndex_RawChannel_Number{1} = rIndex_RawChannel_Number;

                        if isempty(rIndex_RawChannel_Number)
                            app.r_all.UnitsCombined(k,:) = [];
                            k = k-1;
                        end
                        break
                    end
                end
            end

            app.update_infoB(unit_last);
            app.update_infoA();
            app.update_neuron_list();
            app.update_axes_all();
        end

        % Button pushed function: loadrButton
        function loadrButtonPushed(app, event)
            [filenames, dir_name] = uigetfile('./R/','MultiSelect','on');
            app.r_filenames = fullfile(dir_name, filenames);
            app.loadrButton.Enable = "off";
            app.loadr_allButton.Enable = "off";

            app.initialize();
        end

        % Button pushed function: NextrButton
        function NextrButtonPushed(app, event)
            % check if it is completed
            if app.r_idx >= length(app.r_all.r)
                disp('Done!');
                return
            end

            % Lamp
            app.Lamp.Color = 'red';
            app.ItisthelastunitLabel.Visible = "off";

            % r
            app.r_idx = app.r_idx+1;
            input = load(app.r_filenames{app.r_idx});
            app.r = input.r;

            % r_all
            app.update_r_all();

            % update fig
            app.update_infoB(1);
            app.update_infoA();
            app.update_neuron_list();
            app.update_axes_all();
        end

        % Value changed function: TetrodeDropDown
        function TetrodeDropDownValueChanged(app, event)
            channel = str2double(app.TetrodeDropDown.Value);
            app.update_infoA(channel);
            app.update_neuron_list(channel, 'A');
            app.update_infoB_r_all();
            app.update_neuron_list(channel, 'B');
            app.update_axes_all();
        end

        % Value changed function: NeuronListBoxA
        function NeuronListBoxAValueChanged(app, event)
            value = app.NeuronListBoxA.Value;
            numInChannel_r_all = find(strcmp(app.NeuronListBoxA.Items, value));

            app.SampleDropDownA.Items = {};
            if isempty(app.infoB)
                return
            end
            
            channel = app.infoB.channel;
            Channel = table2array(app.r_all.UnitsCombined(:,'Channel'));
            idx_unit = find(Channel==channel);
            
            % update SampleDropDown
            unit = idx_unit(numInChannel_r_all);
            rIndex_RawChannel_Number = app.r_all.UnitsCombined(unit,:).rIndex_RawChannel_Number{1};
            idx_sample = size(rIndex_RawChannel_Number,1);
            values_dropdown = cell(size(rIndex_RawChannel_Number,1),1);
            for k = 1:length(values_dropdown)
                r_this = app.r_all.r{rIndex_RawChannel_Number(k,1)};
                idx_unit_r = find(r_this.Units.SpikeNotes(:,1)==channel & r_this.Units.SpikeNotes(:,2)==rIndex_RawChannel_Number(k,3));
                if r_this.Units.SpikeNotes(idx_unit_r,3)==1
                    type = 's';
                else
                    type = 'm';
                end
                values_dropdown{k} = [datestr(r_this.Meta(1).DateTime,'yyyymmdd'),...
                    ' unit#',num2str(rIndex_RawChannel_Number(k,3)),...
                    ' (', type, ')'];                
            end
            app.SampleDropDownA.Items = values_dropdown;
            app.SampleDropDownA.Value = values_dropdown{idx_sample}; 

            app.update_infoA(channel, numInChannel_r_all, idx_sample);
            app.update_axes_all();
        end

        % Value changed function: SampleDropDownA
        function SampleDropDownAValueChanged(app, event)
            idx_sample = find(strcmp(app.SampleDropDownA.Items, app.SampleDropDownA.Value));
            numInChannel_r_all = find(strcmp(app.NeuronListBoxA.Items, app.NeuronListBoxA.Value));
            channel = app.infoA.channel;

            app.update_infoA(channel, numInChannel_r_all, idx_sample);
            app.update_axes_all();           
        end

        % Value changed function: NeuronListBoxB
        function NeuronListBoxBValueChanged(app, event)
            value = app.NeuronListBoxB.Value;
            numInChannel_r_all = find(strcmp(app.NeuronListBoxB.Items, value));
            
            channel = app.infoA.channel;
            Channel = table2array(app.r_all.UnitsCombined(:,'Channel'));
            idx_unit = find(Channel==channel);
            
            app.SampleDropDownB.Items = {};
            % update SampleDropDown
            unit = idx_unit(numInChannel_r_all);
            rIndex_RawChannel_Number = app.r_all.UnitsCombined(unit,:).rIndex_RawChannel_Number{1};
            idx_sample = size(rIndex_RawChannel_Number,1);
            values_dropdown = cell(size(rIndex_RawChannel_Number,1),1);
            for k = 1:length(values_dropdown)
                r_this = app.r_all.r{rIndex_RawChannel_Number(k,1)};
                idx_unit_r = find(r_this.Units.SpikeNotes(:,1)==channel & r_this.Units.SpikeNotes(:,2)==rIndex_RawChannel_Number(k,3));
                if r_this.Units.SpikeNotes(idx_unit_r,3)==1
                    type = 's';
                else
                    type = 'm';
                end
                values_dropdown{k} = [datestr(r_this.Meta(1).DateTime,'yyyymmdd'),...
                    ' unit#',num2str(rIndex_RawChannel_Number(k,3)),...
                    ' (', type, ')'];                
            end
            app.SampleDropDownB.Items = values_dropdown;
            app.SampleDropDownB.Value = values_dropdown{idx_sample}; 

            app.update_infoB_r_all(numInChannel_r_all, idx_sample);
            app.update_axes_all();
        end

        % Value changed function: SampleDropDownB
        function SampleDropDownBValueChanged(app, event)
            idx_sample = find(strcmp(app.SampleDropDownB.Items, app.SampleDropDownB.Value));
            numInChannel_r_all = find(strcmp(app.NeuronListBoxB.Items, app.NeuronListBoxB.Value));

            app.update_infoB_r_all(numInChannel_r_all, idx_sample);
            app.update_axes_all(); 
        end

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)
            if isempty(app.infoB)
                return
            end
            
            Channel = app.infoB.channel;
            rIndex_RawChannel_Number = {[app.r_idx, app.infoB.channel, app.infoB.numInChannel]};
            if ~isfield(app.r_all,'UnitsCombined')
                Number = 1;
                tbl = table(Channel,Number,rIndex_RawChannel_Number);
                app.r_all.UnitsCombined = tbl;
            else
                Number = sum(table2array(app.r_all.UnitsCombined(:,'Channel'))==Channel)+1;
                % check whether this unit is already in the table
                temp_tbl = app.r_all.UnitsCombined(app.r_all.UnitsCombined.Channel==Channel,:);
                flag = true;
                for k = 1:size(temp_tbl,1)
                    if any(all(temp_tbl(k,:).rIndex_RawChannel_Number{1}==rIndex_RawChannel_Number{1},2))
                        flag = false;
                    end
                end
                
                if flag
                    % if this unit is not in this table
                    app.r_all.UnitsCombined = [app.r_all.UnitsCombined;{Channel,Number,rIndex_RawChannel_Number}];
                end
            end
            
            app.r_all.UnitsCombined = sortrows(app.r_all.UnitsCombined,{'Channel','Number'});
            app.update_infoB();
            app.update_infoA();
            app.update_neuron_list();
            app.update_axes_all();
        end

        % Button pushed function: SameButton
        function SameButtonPushed(app, event)
            if isempty(app.infoA)
                return
            end

            Channel = app.infoA.channel;
            Number = app.infoA.numInChannel_r_all;
            
            tbl = app.r_all.UnitsCombined;
            rIndex_RawChannel_Number = app.r_all.UnitsCombined(tbl.Channel == Channel & tbl.Number == Number,:).rIndex_RawChannel_Number{1};

            % check it does not contains neuron same day
            r_idx_end = rIndex_RawChannel_Number(end,1);
            if app.r_all.r{r_idx_end}.Meta(1).DateTime == app.infoB.r.Meta(1).DateTime
                disp('These 2 units are from the same day!');
                return
            end

            rIndex_RawChannel_Number = unique([rIndex_RawChannel_Number;[app.r_idx, app.infoB.channel, app.infoB.numInChannel]],'rows',"stable");
            app.r_all.UnitsCombined(tbl.Channel == Channel & tbl.Number == Number,:).rIndex_RawChannel_Number{1} = rIndex_RawChannel_Number;

            app.update_infoB();
            app.update_infoA();
            app.update_neuron_list();
            app.update_axes_all();
        end

        % Button pushed function: SkipButton
        function SkipButtonPushed(app, event)
            if isempty(app.infoB)
                return
            end
            app.update_infoB();
            app.update_infoA();
            app.update_neuron_list();
            app.update_axes_all();           
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            verticalScrollCount = event.VerticalScrollCount;
            if ~app.Checkr_allButton.Value
                if isempty(app.NeuronListBoxA.Items)
                    return
                end
                idx_unit = find(strcmp(app.NeuronListBoxA.Items,app.NeuronListBoxA.Value));
                total_unit = length(app.NeuronListBoxA.Items);
                if idx_unit + verticalScrollCount>0 && idx_unit + verticalScrollCount<=total_unit
                    app.NeuronListBoxA.Value = app.NeuronListBoxA.Items{idx_unit+verticalScrollCount};
                end
                app.NeuronListBoxAValueChanged();
            else
                if isempty(app.SampleDropDownA.Items)
                    return
                end
                idx_sample = find(strcmp(app.SampleDropDownA.Items,app.SampleDropDownA.Value));
                total_sample = length(app.SampleDropDownA.Items);
                if idx_sample + verticalScrollCount>0 && idx_sample + verticalScrollCount<=total_sample
                    app.SampleDropDownA.Value = app.SampleDropDownA.Items(idx_sample+verticalScrollCount);
                end
                app.SampleDropDownAValueChanged();
            end
        end

        % Value changed function: Checkr_allButton
        function Checkr_allButtonValueChanged(app, event)
            if ~app.Checkr_allButton.Value
                app.NeuronListBoxB.Enable = "off";
                app.NeuronListBoxBLabel.Enable = "off";
                app.SampleDropDownB.Enable = "off";
                app.SampleDropDownBLabel.Enable = "off";
                app.TetrodeDropDown.Enable = "off";
                app.TetrodeDropDownLabel.Enable = "off";
                app.NewButton.Visible = "on";
                app.SameButton.Visible = "on";
                app.PreviousButton.Visible = "on";
                app.SkipButton.Visible = "on";
                app.SplitButton.Visible = "off";
                app.MergeButton.Visible = "off";
                app.RemoveButton.Visible = "off";
                app.UndoButton.Enable = "on";

                app.update_infoA();
                app.update_neuron_list();
                app.update_infoB();
                app.update_axes_all();
            else
                app.NeuronListBoxB.Enable = "on";
                app.NeuronListBoxBLabel.Enable = "on";
                app.SampleDropDownB.Enable = "on";
                app.SampleDropDownBLabel.Enable = "on";
                app.TetrodeDropDown.Enable = "on";
                app.TetrodeDropDownLabel.Enable = "on";
                app.NewButton.Visible = "off";
                app.SameButton.Visible = "off";
                app.PreviousButton.Visible = "off";
                app.SkipButton.Visible = "off";
                app.SplitButton.Visible = "on";
                app.MergeButton.Visible = "on";
                app.RemoveButton.Visible = "on";
                app.UndoButton.Enable = "off";

                channels = table2array(app.r_all.UnitsCombined(:,"Channel"));
                channels = sort(unique(channels));

                items = cell(1,length(channels));
                for k = 1:length(items)
                    items{k} = num2str(channels(k));
                end
                app.TetrodeDropDown.Items = items;
                app.TetrodeDropDown.Value = items{1};
                
                app.TetrodeDropDownValueChanged();
            end
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            if isempty(app.infoB)
                if strcmp(app.ItisthelastunitLabel.Visible, 'on')
                    unit_this = length(app.r.Units.SpikeTimes);
                    while (app.is_only_single_units && app.r.Units.SpikeNotes(unit_this,3)==2) ...
                        || (app.is_only_unclassified && app.is_classified(unit_this))
                        unit_this = unit_this-1;
                        if unit_this<=0
                            return
                        end
                    end
                    app.Lamp.Color = 'red';
                    app.ItisthelastunitLabel.Visible = "off";                
                else
                    return
                end
            else
                unit_this = app.infoB.unit;
                while true
                    unit_this = unit_this-1;
                    if unit_this<=0
                        disp('It is already the first unclassified unit!');
                        return
                    end
    
                    if app.is_only_single_units && app.r.Units.SpikeNotes(unit_this,3)==2
                        continue
                    end
                    if app.is_only_unclassified && app.is_classified(unit_this)
                        continue
                    end
    
                    break
                end
            end

            app.update_infoB(unit_this);
            app.update_infoA();
            app.update_neuron_list();
            app.update_axes_all();
        end

        % Button pushed function: ExtractneuroninfoButton
        function ExtractneuroninfoButtonPushed(app, event)
            for k = 1:length(app.r_filenames)
                filename = app.r_filenames{k};
                input = load(filename);
                r = input.r;

                if isfield(r.Units.SpikeTimes, 'autoCorrelogram') && isfield(r.Units.SpikeTimes, 'PETH')
                    disp([filename, ' done!'])
                    continue
                end

                if ~isfield(r.Units.SpikeTimes, 'autoCorrelogram')
                    for j = 1:length(r.Units.SpikeTimes)
                        binwidth = 1; % ms
                        window = 50;
            
                        spike_times = r.Units.SpikeTimes(j).timings; % ms
                        spike_times = round(spike_times-spike_times(1))+1;
                        s = zeros(max(spike_times),1);
                        s(spike_times) = 1;
                        
                        [auto_cor, lag] = xcorr(s,s,round(window/binwidth));
                        auto_cor(lag==0)=0; 
                        r.Units.SpikeTimes(j).autoCorrelogram.corr = auto_cor;
                        r.Units.SpikeTimes(j).autoCorrelogram.lag = lag;
                    end
                end

                if ~isfield(r.Units.SpikeTimes, 'PETH')
                    for j = 1:length(r.Units.SpikeTimes)
                        spike_times = r.Units.SpikeTimes(j).timings; % ms

                        idx_press = find(strcmp(r.Behavior.Labels,'LeverPress'));
                        idx_release = find(strcmp(r.Behavior.Labels,'LeverRelease'));
                        idx_reward = find(strcmp(r.Behavior.Labels,'ValveOnset'));
                        idx_correct = r.Behavior.CorrectIndex;
            
                        press_times = r.Behavior.EventTimings(r.Behavior.EventMarkers==idx_press);
                        release_times = r.Behavior.EventTimings(r.Behavior.EventMarkers==idx_release);
                        reward_times = r.Behavior.EventTimings(r.Behavior.EventMarkers==idx_reward);
            
                        press_times = press_times(idx_correct);
                        release_times = release_times(idx_correct);
            
                        params.pre = 2000;
                        params.post = 2000;
                        params.binwidth = 10;
                        gaussian_kernel = 5; % ms
            
                        [PETH_press, r.Units.SpikeTimes(j).PETH.t_PETH_press] = jpsth(spike_times, press_times, params);
                        [PETH_release, r.Units.SpikeTimes(j).PETH.t_PETH_release] = jpsth(spike_times, release_times, params);
                        [PETH_reward, r.Units.SpikeTimes(j).PETH.t_PETH_reward] = jpsth(spike_times, reward_times, params);
                        r.Units.SpikeTimes(j).PETH.PETH_press = smoothdata(PETH_press,'gaussian',gaussian_kernel*5);
                        r.Units.SpikeTimes(j).PETH.PETH_release = smoothdata(PETH_release,'gaussian',gaussian_kernel*5);
                        r.Units.SpikeTimes(j).PETH.PETH_reward = smoothdata(PETH_reward,'gaussian',gaussian_kernel*5);
                    end
                end                
                save(filename, 'r');
                disp([filename, ' done!'])
            end
            disp('Successfully extracted!')
        end

        % Button pushed function: CheckfigAButton
        function CheckfigAButtonPushed(app, event)
            dir_Max = 'D:\Ephys\ANMs\Max';
            date = datestr(app.infoA.r.Meta(1).DateTime, 'yyyymmdd');
            channel = app.infoA.channel;
            numInChannel = app.infoA.numInChannel;
            img_path = fullfile(dir_Max, date, 'Fig/', ['Ch', num2str(channel), '_Unit', num2str(numInChannel), '_unsorted.png']);

            if exist(img_path, 'file')
                figure;
                img = imread(img_path);
                imshow(img);
                return
            end

            
            if ~isempty(app.infoA)
                SRTSpikesV5_unsorted(app.infoA.r,app.infoA.unit,'ToSave',false);
            end
        end

        % Button pushed function: CheckfigBButton
        function CheckfigBButtonPushed(app, event)
            dir_Max = 'D:\Ephys\ANMs\Max';
            date = datestr(app.infoB.r.Meta(1).DateTime, 'yyyymmdd');
            channel = app.infoB.channel;
            numInChannel = app.infoB.numInChannel;
            img_path = fullfile(dir_Max, date, 'Fig/', ['Ch', num2str(channel), '_Unit', num2str(numInChannel), '_unsorted.png']);

            if exist(img_path, 'file')
                figure;
                img = imread(img_path);
                imshow(img);
                return
            end

            if ~isempty(app.infoB)
                SRTSpikesV5_unsorted(app.infoB.r,app.infoB.unit,'ToSave',false);
            end
        end

        % Button pushed function: loadr_allButton
        function loadr_allButtonPushed(app, event)
            [filename, dir_name] = uigetfile('./*.mat');
            input = load(fullfile(dir_name, filename));
            app.r_all = input.r_all;
            app.r_filenames = app.r_all.r_filenames;
            app.loadrButton.Enable = "off";
            app.loadr_allButton.Enable = "off";

            set(app.UIFigure, 'Renderer', 'opengl');

            % load r
            for k = 1:length(app.r_all.r)
                if isempty(app.r_all.r{k})
                    app.r_idx = max(1,k-1);
                    break
                end
                app.r_idx = k;
            end
            input = load(app.r_filenames{app.r_idx});
            app.r = input.r;

            % initialize r_all
            app.update_r_all();

            % Neuron B
            app.update_infoB(1);
            app.update_infoA();
            app.update_neuron_list();
            app.update_axes_all();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            uiconfirm(app.UIFigure,'Save your data before close?','Confirm',...
                'Options',{'Yes','No','Cancel'},'DefaultOption',1,'CancelOption',3,...
                'CloseFcn',@CloseFcnForFigure);

            function CloseFcnForFigure(source, event)
                switch event.SelectedOption
                    case 'No'
                        delete(source);
                        delete(app);
                    case 'Yes'
                        app.SaveButtonPushed();
                        delete(source);
                        delete(app);
                end
            end
        end

        % Button pushed function: SplitButton
        function SplitButtonPushed(app, event)
            channel = app.infoB.channel;
            numInChannel_r_all = app.infoB.numInChannel_r_all;
            r_index = app.infoB.r_idx;
            numInChannel = app.infoB.numInChannel;
            app.remove_unit_r_all(channel, numInChannel_r_all, r_index, numInChannel);
            app.new_unit_r_all();       

            app.update_r_all_number();

            app.TetrodeDropDownValueChanged();
        end

        % Button pushed function: MergeButton
        function MergeButtonPushed(app, event)
            channel = app.infoB.channel;
            numInChannel_r_all = app.infoB.numInChannel_r_all;
            r_index = app.infoB.r_idx;
            numInChannel = app.infoB.numInChannel;
            app.remove_unit_r_all(channel, numInChannel_r_all, r_index, numInChannel);
            app.insert_unit_r_all(r_index, numInChannel);

            app.update_r_all_number();

            app.TetrodeDropDownValueChanged();
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            uiconfirm(app.UIFigure, 'Ready to remove this unit permenantly?', 'Confirm', ...
                'Options', {'Yes', 'No'},...
                'DefaultOption', 1,...
                'CancelOption', 2,...
                'CloseFcn', @CloseFcnForFigure);

            function CloseFcnForFigure(source, event)
                switch event.SelectedOption
                    case 'Yes'
                        channel = app.infoB.channel;
                        numInChannel_r_all = app.infoB.numInChannel_r_all;
                        r_index = app.infoB.r_idx;
                        numInChannel = app.infoB.numInChannel;
                        app.remove_unit_r_all(channel, numInChannel_r_all, r_index, numInChannel);

                        app.update_r_all_number();

                        app.TetrodeDropDownValueChanged();
                end
            end
        end

        % Button pushed function: DebugButton
        function DebugButtonPushed(app, event)
            text = app.DebugTextArea.Value;
            for k = 1:length(text)
                try
                    eval(text{k})
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxesAutoCorrelogramA = uiaxes(app.UIFigure);
            title(app.UIAxesAutoCorrelogramA, 'Autocorrelogram')
            app.UIAxesAutoCorrelogramA.XTick = [];
            app.UIAxesAutoCorrelogramA.XTickLabel = '';
            app.UIAxesAutoCorrelogramA.YTick = [];
            app.UIAxesAutoCorrelogramA.YTickLabel = '';
            app.UIAxesISI_A = uiaxes(app.UIFigure);
            title(app.UIAxesISI_A, 'ISI')
            app.UIAxesISI_A.XTick = [];
            app.UIAxesISI_A.XTickLabel = '';
            app.UIAxesISI_A.YTick = [];
            app.UIAxesISI_A.YTickLabel = '';
            app.UIAxesAutoCorrelogramB = uiaxes(app.UIFigure);
            title(app.UIAxesAutoCorrelogramB, 'Autocorrelogram')
            app.UIAxesAutoCorrelogramB.XTick = [];
            app.UIAxesAutoCorrelogramB.XTickLabel = '';
            app.UIAxesAutoCorrelogramB.YTick = [];
            app.UIAxesAutoCorrelogramB.YTickLabel = '';
            app.UIAxesISI_B = uiaxes(app.UIFigure);
            title(app.UIAxesISI_B, 'ISI')
            app.UIAxesISI_B.XTick = [];
            app.UIAxesISI_B.XTickLabel = '';
            app.UIAxesISI_B.YTick = [];
            app.UIAxesISI_B.YTickLabel = '';
            app.UIAxesPressPETH_A = uiaxes(app.UIFigure);
            title(app.UIAxesPressPETH_A, 'Press PETH')
            ylabel(app.UIAxesPressPETH_A, 'Firing rate (Hz)')
            app.UIAxesPressPETH_B = uiaxes(app.UIFigure);
            title(app.UIAxesPressPETH_B, 'Press PETH')
            ylabel(app.UIAxesPressPETH_B, 'Firing rate (Hz)')
            app.UIAxesReleasePETH_A = uiaxes(app.UIFigure);
            title(app.UIAxesReleasePETH_A, 'Release PETH')
            ylabel(app.UIAxesReleasePETH_A, 'Firing rate (Hz)')
            app.UIAxesReleasePETH_B = uiaxes(app.UIFigure);
            title(app.UIAxesReleasePETH_B, 'Release PETH')
            ylabel(app.UIAxesReleasePETH_B, 'Firing rate (Hz)')
            app.UIAxesRewardPETH_B = uiaxes(app.UIFigure);
            title(app.UIAxesRewardPETH_B, 'Reward PETH')
            xlabel(app.UIAxesRewardPETH_B, 'Time (ms)')
            ylabel(app.UIAxesRewardPETH_B, 'Firing rate (Hz)')
            app.UIAxesRewardPETH_A = uiaxes(app.UIFigure);
            title(app.UIAxesRewardPETH_A, 'Reward PETH')
            xlabel(app.UIAxesRewardPETH_A, 'Time (ms)')
            ylabel(app.UIAxesRewardPETH_A, 'Firing rate (Hz)')
            app.UIAxesWaveformA = uiaxes(app.UIFigure);
            title(app.UIAxesWaveformA, 'Waveform')
            app.UIAxesWaveformA.XTick = [];
            app.UIAxesWaveformA.XTickLabel = '';
            app.UIAxesWaveformB = uiaxes(app.UIFigure);
            title(app.UIAxesWaveformB, 'Waveform')
            app.UIAxesWaveformB.XTick = [];
            app.loadrButton = uibutton(app.UIFigure, 'push');
            app.loadrButton.ButtonPushedFcn = createCallbackFcn(app, @loadrButtonPushed, true);
            app.loadrButton.Text = 'load r';
            app.NeuronALabel = uilabel(app.UIFigure);
            app.NeuronALabel.Text = 'Neuron A';
            app.NeuronBLabel = uilabel(app.UIFigure);
            app.NeuronBLabel.Text = 'Neuron B';
            app.NewButton = uibutton(app.UIFigure, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Text = 'New';
            app.SameButton = uibutton(app.UIFigure, 'push');
            app.SameButton.ButtonPushedFcn = createCallbackFcn(app, @SameButtonPushed, true);
            app.SameButton.Text = 'Same';
            app.NeuronListBoxLabel = uilabel(app.UIFigure);
            app.NeuronListBoxLabel.Text = 'Neuron#';
            app.NeuronListBoxA = uilistbox(app.UIFigure);
            app.NeuronListBoxA.Items = {};
            app.NeuronListBoxA.ValueChangedFcn = createCallbackFcn(app, @NeuronListBoxAValueChanged, true);
            app.NeuronListBoxA.Value = {};
            app.TetrodeDropDownLabel = uilabel(app.UIFigure);
            app.TetrodeDropDownLabel.Enable = 'off';
            app.TetrodeDropDownLabel.Text = 'Tetrode#';
            app.TetrodeDropDown = uidropdown(app.UIFigure);
            app.TetrodeDropDown.Items = {};
            app.TetrodeDropDown.ValueChangedFcn = createCallbackFcn(app, @TetrodeDropDownValueChanged, true);
            app.TetrodeDropDown.Enable = 'off';
            app.TetrodeDropDown.Value = {};
            app.SkipButton = uibutton(app.UIFigure, 'push');
            app.SkipButton.ButtonPushedFcn = createCallbackFcn(app, @SkipButtonPushed, true);
            app.SkipButton.Text = 'Skip';
            app.NeuronListBoxBLabel = uilabel(app.UIFigure);
            app.NeuronListBoxBLabel.Enable = 'off';
            app.NeuronListBoxBLabel.Text = 'Neuron#';
            app.NeuronListBoxB = uilistbox(app.UIFigure);
            app.NeuronListBoxB.Items = {};
            app.NeuronListBoxB.ValueChangedFcn = createCallbackFcn(app, @NeuronListBoxBValueChanged, true);
            app.NeuronListBoxB.Enable = 'off';
            app.NeuronListBoxB.Value = {};
            app.NeuronLeftLabel = uilabel(app.UIFigure);
            app.NeuronLeftLabel.Text = 'Neuron Left';
            app.NeuronRightLabel = uilabel(app.UIFigure);
            app.NeuronRightLabel.Text = 'Neuron Right';
            app.NextrButton = uibutton(app.UIFigure, 'push');
            app.NextrButton.ButtonPushedFcn = createCallbackFcn(app, @NextrButtonPushed, true);
            app.NextrButton.Text = 'Next r';
            app.SampleDropDownALabel = uilabel(app.UIFigure);
            app.SampleDropDownALabel.Text = 'Sample#';
            app.SampleDropDownA = uidropdown(app.UIFigure);
            app.SampleDropDownA.Items = {};
            app.SampleDropDownA.ValueChangedFcn = createCallbackFcn(app, @SampleDropDownAValueChanged, true);
            app.SampleDropDownA.Value = {};
            app.SampleDropDownBLabel = uilabel(app.UIFigure);
            app.SampleDropDownBLabel.Enable = 'off';
            app.SampleDropDownBLabel.Text = 'Sample#';
            app.SampleDropDownB = uidropdown(app.UIFigure);
            app.SampleDropDownB.Items = {};
            app.SampleDropDownB.ValueChangedFcn = createCallbackFcn(app, @SampleDropDownBValueChanged, true);
            app.SampleDropDownB.Enable = 'off';
            app.SampleDropDownB.Value = {};
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.UndoButton = uibutton(app.UIFigure, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = 'Undo';
            app.loadr_allButton = uibutton(app.UIFigure, 'push');
            app.loadr_allButton.ButtonPushedFcn = createCallbackFcn(app, @loadr_allButtonPushed, true);
            app.loadr_allButton.Text = 'load r_all';
            app.Checkr_allButton = uibutton(app.UIFigure, 'state');
            app.Checkr_allButton.ValueChangedFcn = createCallbackFcn(app, @Checkr_allButtonValueChanged, true);
            app.Checkr_allButton.Text = 'Check r_all';
            app.OnlysingleunitsButton = uibutton(app.UIFigure, 'state');
            app.OnlysingleunitsButton.Visible = 'off';
            app.OnlysingleunitsButton.Text = 'Only single units';
            app.OnlysingleunitsButton.Value = true;
            app.OnlyunclassifiedButton = uibutton(app.UIFigure, 'state');
            app.OnlyunclassifiedButton.Visible = 'off';
            app.OnlyunclassifiedButton.Text = 'Only unclassified';
            app.OnlyunclassifiedButton.Value = true;
            app.Lamp = uilamp(app.UIFigure);
            app.ItisthelastunitLabel = uilabel(app.UIFigure);
            app.ItisthelastunitLabel.Visible = 'off';
            app.ItisthelastunitLabel.Text = 'It is the last unit!';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.ExtractneuroninfoButton = uibutton(app.UIFigure, 'push');
            app.ExtractneuroninfoButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractneuroninfoButtonPushed, true);
            app.ExtractneuroninfoButton.Text = 'Extract neuron info';
            app.CheckfigAButton = uibutton(app.UIFigure, 'push');
            app.CheckfigAButton.ButtonPushedFcn = createCallbackFcn(app, @CheckfigAButtonPushed, true);
            app.CheckfigAButton.Text = 'Check fig';
            app.CheckfigBButton = uibutton(app.UIFigure, 'push');
            app.CheckfigBButton.ButtonPushedFcn = createCallbackFcn(app, @CheckfigBButtonPushed, true);
            app.CheckfigBButton.Text = 'Check fig';
            app.SplitButton = uibutton(app.UIFigure, 'push');
            app.SplitButton.ButtonPushedFcn = createCallbackFcn(app, @SplitButtonPushed, true);
            app.SplitButton.Visible = 'off';
            app.SplitButton.Text = 'Split';
            app.MergeButton = uibutton(app.UIFigure, 'push');
            app.MergeButton.ButtonPushedFcn = createCallbackFcn(app, @MergeButtonPushed, true);
            app.MergeButton.Visible = 'off';
            app.MergeButton.Text = 'Merge';
            app.RemoveButton = uibutton(app.UIFigure, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Visible = 'off';
            app.RemoveButton.Text = 'Remove';
            app.DebugButton = uibutton(app.UIFigure, 'push');
            app.DebugButton.ButtonPushedFcn = createCallbackFcn(app, @DebugButtonPushed, true);
            app.DebugButton.Text = 'Debug';
            app.DebugTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = findSameNeurons

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end