classdef CheckSingleSpikes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        meansubCheckBox             matlab.ui.control.CheckBox
        Panel                       matlab.ui.container.Panel
        SelectgoodspikesButton      matlab.ui.control.Button
        CheckautocorrelogramButton  matlab.ui.control.Button
        RemovebadspikesButton       matlab.ui.control.Button
        numberLabel                 matlab.ui.control.Label
        AddtobadButton              matlab.ui.control.Button
        SelectbadspikesButton       matlab.ui.control.Button
        SelectoutliersButton        matlab.ui.control.Button
        LowerlimitEditField         matlab.ui.control.NumericEditField
        LowerlimitEditFieldLabel    matlab.ui.control.Label
        UpperlimitEditField         matlab.ui.control.NumericEditField
        UpperlimitEditFieldLabel    matlab.ui.control.Label
        SaveButton                  matlab.ui.control.Button
        TotalspikesLabel            matlab.ui.control.Label
        NumofspikesEditField        matlab.ui.control.NumericEditField
        NumofspikesEditFieldLabel   matlab.ui.control.Label
        PlotspikesButton            matlab.ui.control.Button
        SpikeclusterDropDown        matlab.ui.control.DropDown
        SpikeclusterDropDownLabel   matlab.ui.control.Label
        LoadspikesButton            matlab.ui.control.Button
        MultichannelsDropDown       matlab.ui.control.DropDown
        MultichannelsDropDownLabel  matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        UIAxesSpikes                matlab.ui.control.UIAxes
        UIAxesColormap              matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadspikesButton
        function LoadspikesButtonPushed(app, event)
            
            % Load this spike cluster
           indcluster = app.MultichannelsDropDown.Value;
%             cluster_name = ['polytrode' num2str(indcluster) '_spikes.mat'];
%             polyspikes = load(cluster_name); 
            %             No spike sorting information
            %             par: [1×1 struct]
            %             index: [5.3000 35.8333 57.5333 72.4667 92.1333 119.1667 … ]
            %             thr: [13×4 double]
            %             spikes: [320929×256 double]

            if app.meansubCheckBox.Value == 1
                cluster_time =  ['times_chdat_meansub' num2str(indcluster) '.mat'];
            else
                cluster_time =  ['times_chdat' num2str(indcluster) '.mat'];
            end;

            polyspiketimes = load(cluster_time);
            %               par: [1×1 struct]
            %            spikes: [320929×256 double]
            %             inspk: [320929×35 double]
            %              Temp: 1
            %            forced: [1 1 1 1 1 1 1 0 1 1 1 1 1 1 1 1 1 1 1 1 1 1 … ]
            %        gui_status: [1×1 struct]
            %     cluster_class: [320929×2 double]
            %           ipermut: [104739 142493 273052 87127 77425 300537 … ]

            % plot sorted spikes
            clusters = unique(polyspiketimes.cluster_class(:, 1));
            nclusters = length(clusters);

            allcolors = varycolor(nclusters*2);
            allcolors = allcolors(1:2:2*nclusters-1, :);

            app.SpikeclusterDropDown.Items = string(clusters);
            app.LoadspikesButton.UserData = polyspiketimes;

            app.AddtobadButton.UserData = [];


        end

        % Button pushed function: PlotspikesButton
        function PlotspikesButtonPushed(app, event)
            % plot spikes
            polyspiketimes = app.LoadspikesButton.UserData;
            clusters = unique(polyspiketimes.cluster_class(:, 1));
            nclusters = length(clusters);

            allcolors = varycolor(nclusters*2);
            allcolors = allcolors(1:2:2*nclusters-1, :);
            indcluster = str2num(app.SpikeclusterDropDown.Value);
            thiscolor = allcolors(clusters==indcluster, :);
            spikeswave_thiscluster = polyspiketimes.spikes(find(polyspiketimes.cluster_class(:, 1) == indcluster), :);

            plotnum = min([size(spikeswave_thiscluster, 1) app.NumofspikesEditField.Value]);

            spikewave_toplot = spikeswave_thiscluster(randperm(size(spikeswave_thiscluster, 1), plotnum), :);

            tspike = [1:size(spikewave_toplot, 2)];

            plot(app.UIAxesSpikes, tspike, spikewave_toplot, 'color', thiscolor)
            app.UIAxesSpikes.XLabel.String            = 'Time (ms)';
            app.UIAxesSpikes.XLim = [tspike(1) tspike(end)];
            app.UIAxesSpikes.YLim = [min(spikewave_toplot(:))*1.2 max(spikewave_toplot(:))*1.2];
            axes(app.UIAxesSpikes) 

            % Plot spike in color maps
            % histogram2(X,Y,Xedges,Yedges)
                
            yedges = [min(spikeswave_thiscluster(:)):10:max(spikeswave_thiscluster(:))]; % 2.5 uV per bin
            ncountsall = zeros(length(tspike), length(yedges)-1);

            for i =1:length(tspike)                
                ncountsall(i, :) = histcounts(spikeswave_thiscluster(:, i), yedges);
            end;

             imagesc(app.UIAxesColormap, tspike, yedges(1:end)+5, ncountsall');

             app.UIAxesColormap.XLim =[tspike(1) tspike(end)];
             app.UIAxesColormap.YLim = [yedges(1) yedges(end)];
             app.UIAxesColormap.YDir = 'normal';

             app.TotalspikesLabel.Text = num2str(size(spikeswave_thiscluster, 1));

             display('Done')
        end

        % Value changed function: SpikeclusterDropDown
        function SpikeclusterDropDownValueChanged(app, event)
            value = app.SpikeclusterDropDown.Value;
            % plot spikes
            polyspiketimes = app.LoadspikesButton.UserData;
            clusters = unique(polyspiketimes.cluster_class(:, 1));
            nclusters = length(clusters);

            allcolors = varycolor(nclusters*2);
            allcolors = allcolors(1:2:2*nclusters-1, :);
            indcluster = str2num(app.SpikeclusterDropDown.Value);

           numofspikes = length(find(polyspiketimes.cluster_class(:, 1) == indcluster));
            
           app.TotalspikesLabel.Text = num2str(numofspikes);


        end

        % Button pushed function: SelectbadspikesButton
        function SelectbadspikesButtonPushed(app, event)

            rect = getrect(app.UIAxesColormap);
            xrange = [rect(1) rect(1)+rect(3)];
            yrange = [rect(2) rect(2)+rect(4)];

            polyspiketimes = app.LoadspikesButton.UserData;
            clusters = unique(polyspiketimes.cluster_class(:, 1));
            nclusters = length(clusters);
            indcluster = str2num(app.SpikeclusterDropDown.Value);

            spikeswave_thiscluster = polyspiketimes.spikes(find(polyspiketimes.cluster_class(:, 1) == indcluster), :);
            tspike = [1:size(spikeswave_thiscluster, 2)];

            spk_tocheck = spikeswave_thiscluster(:, tspike>=xrange(1) & tspike<=xrange(2));
            [ia, ~] = find(spk_tocheck>=yrange(1) & spk_tocheck<=yrange(2));

            if ~isempty(ia)
                indbad = unique(ia);
                indbadplot = indbad(randperm(length(indbad), min(100, length(indbad))));

                figure(34); clf(34)

                plot(tspike, spikeswave_thiscluster(indbadplot, :), 'color', [0.5 0.5 0.5]);
                app.SelectbadspikesButton.UserData = indbad;
                text(1, 0, num2str(length(indbad)))

            end;


        end

        % Button pushed function: AddtobadButton
        function AddtobadButtonPushed(app, event)

            newbadindex = app.SelectbadspikesButton.UserData;

            if size(newbadindex, 1)>size(newbadindex, 2)
                newbadindex = newbadindex';
                app.SelectbadspikesButton.UserData = [];
            end;

            newbadindex2 = app.SelectoutliersButton.UserData;

            if size(newbadindex2, 1)>size(newbadindex2, 2)
                newbadindex2 = newbadindex2';
                app.SelectoutliersButton.UserData = [];
            end;


            if isempty(app.AddtobadButton.UserData)
                app.AddtobadButton.UserData = unique([newbadindex newbadindex2]);
            else
                app.AddtobadButton.UserData = unique([app.AddtobadButton.UserData newbadindex newbadindex2]);
            end;
                
            app.numberLabel.Text = num2str(length(app.AddtobadButton.UserData));


        end

        % Button pushed function: RemovebadspikesButton
        function RemovebadspikesButtonPushed(app, event)
            % remove bad spikes from the current cluster

            indbad =  app.AddtobadButton.UserData;

            polyspiketimes = app.LoadspikesButton.UserData;

            clusters = unique(polyspiketimes.cluster_class(:, 1));
            nclusters = length(clusters);
            indcluster = str2num(app.SpikeclusterDropDown.Value);

            spkind = find(polyspiketimes.cluster_class(:, 1) == indcluster);  % all index

            ind2delete = spkind(indbad);
            polyspiketimes.cluster_class(ind2delete, 1) = 0; % turn them into 0
            app.AddtobadButton.UserData = [];
            app.LoadspikesButton.UserData = polyspiketimes;  % save the data
            app.numberLabel.Text = '';

            %               par: [1×1 struct]
            %            spikes: [320929×256 double]
            %             inspk: [320929×35 double]
            %              Temp: 1
            %            forced: [1 1 1 1 1 1 1 0 1 1 1 1 1 1 1 1 1 1 1 1 1 1 … ]
            %        gui_status: [1×1 struct]
            %     cluster_class: [320929×2 double]
            %           ipermut: [104739 142493 273052 87127 77425 300537 … ]

            clusters = unique(polyspiketimes.cluster_class(:, 1));
            nclusters = length(clusters);

            allcolors = varycolor(nclusters*2);
            allcolors = allcolors(1:2:2*nclusters-1, :);
            indcluster = str2num(app.SpikeclusterDropDown.Value);
            thiscolor = allcolors(clusters==indcluster, :);
            spikeswave_thiscluster = polyspiketimes.spikes(find(polyspiketimes.cluster_class(:, 1) == indcluster), :);
            app.TotalspikesLabel.Text = num2str(size(spikeswave_thiscluster, 1));

            plotnum = min([size(spikeswave_thiscluster, 1) app.NumofspikesEditField.Value]);

            spikewave_toplot = spikeswave_thiscluster(randperm(size(spikeswave_thiscluster, 1), plotnum), :);

            tspike = [1:size(spikewave_toplot, 2)];

            plot(app.UIAxesSpikes, tspike, spikewave_toplot, 'color', thiscolor)
            app.UIAxesSpikes.XLabel.String            = 'Time (ms)';
            app.UIAxesSpikes.XLim = [tspike(1) tspike(end)];
            axes(app.UIAxesSpikes)
            axis tight

            % Plot spike in color maps
            % histogram2(X,Y,Xedges,Yedges)
                
            yedges = [min(spikeswave_thiscluster(:)):10:max(spikeswave_thiscluster(:))]; % 10 uV per bin
            ncountsall = zeros(length(tspike), length(yedges)-1);

            for i =1:length(tspike)                
                ncountsall(i, :) = histcounts(spikeswave_thiscluster(:, i), yedges);
            end;

             imagesc(app.UIAxesColormap, tspike, yedges(1:end)+5, ncountsall');

             app.UIAxesColormap.XLim =[tspike(1) tspike(end)];
             app.UIAxesColormap.YLim = [yedges(1) yedges(end)];
             app.UIAxesColormap.YDir = 'normal'; 

        end

        % Callback function
        function ClearbadButtonPushed(app, event)
            app.AddtobadButton.UserData = [];
             app.numberLabel.Text = '';
        end

        % Button pushed function: SelectoutliersButton
        function SelectoutliersButtonPushed(app, event)
            
            upper_limit = app.UpperlimitEditField.Value;
            lower_limit = app.LowerlimitEditField.Value;


            polyspiketimes = app.LoadspikesButton.UserData; 

            clusters = unique(polyspiketimes.cluster_class(:, 1));
            nclusters = length(clusters);

            allcolors = varycolor(nclusters*2);
            allcolors = allcolors(1:2:2*nclusters-1, :);
            indcluster = str2num(app.SpikeclusterDropDown.Value);
            thiscolor = allcolors(clusters==indcluster, :);
            spikeswave_thiscluster = polyspiketimes.spikes(find(polyspiketimes.cluster_class(:, 1) == indcluster), :);
            tspike = [1:size(spikeswave_thiscluster, 2)];

            [ia, ~] = find(spikeswave_thiscluster> upper_limit | spikeswave_thiscluster<lower_limit);
            if ~isempty(ia)

                indoutofbound = unique(ia);
                indbadplot = indoutofbound(randperm(length(indoutofbound), min(100, length(indoutofbound))));
                figure(34); clf(34)

                plot(tspike, spikeswave_thiscluster(indbadplot, :), 'color', [0.5 0.5 0.5]);
                app.SelectoutliersButton.UserData = indoutofbound; 
                text(1, 0, num2str(length(indoutofbound)), 'color', 'r')
            end;


        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            % this will overwrite the old data use with caution
            indcluster = app.MultichannelsDropDown.Value;
            if app.meansubCheckBox.Value == 1
                cluster_time =  ['times_chdat_meansub' num2str(indcluster) '.mat'];
            else
                cluster_time =  ['times_chdat' num2str(indcluster) '.mat'];
            end;

            polyspiketimes = app.LoadspikesButton.UserData; 

            par = polyspiketimes.par;
            spikes = polyspiketimes.spikes;
            inspk = polyspiketimes.inspk;
            Temp = polyspiketimes.Temp;
            forced = polyspiketimes.forced;
            gui_status = polyspiketimes.gui_status;
            cluster_class = polyspiketimes.cluster_class;
            ipermut = polyspiketimes.ipermut;
            save(cluster_time, 'spikes', 'cluster_class', 'par', 'gui_status', 'Temp', 'forced', 'inspk', 'ipermut');

            display('Done saving')
        end

        % Button pushed function: CheckautocorrelogramButton
        function CheckautocorrelogramButtonPushed(app, event)
            % plot auto-correlogram 

            polyspiketimes = app.LoadspikesButton.UserData; 

            clusters = unique(polyspiketimes.cluster_class(:, 1));
            nclusters = length(clusters);

            allcolors = varycolor(nclusters*2);
            allcolors = allcolors(1:2:2*nclusters-1, :);
            indcluster = str2num(app.SpikeclusterDropDown.Value);
            thiscolor = allcolors(clusters==indcluster, :);

            spikestime_thiscluster = polyspiketimes.cluster_class(find(polyspiketimes.cluster_class(:, 1) == indcluster), 2);


            % plot autocorrelation
            kutime = round(spikestime_thiscluster);
            kutime2 = zeros(1, max(kutime));
            kutime2(kutime)=1;

            [c, lags] = xcorr(kutime2, 25); % max lag 25 ms
            c(lags==0)=0;

            figure(35); clf(35)

            ha00= axes('nextplot', 'add', 'xlim', [-25 25])
            if median(c)>1
                set(ha00, 'nextplot', 'add', 'xtick', [-50:10:50], 'ytick', [0 median(c)])
            else
                set(ha00, 'nextplot', 'add', 'xtick', [-50:10:50], 'ytick', [0 1], 'ylim', [0 1])
            end;

            hbar = bar(lags, c);
            set(hbar, 'facecolor', thiscolor, 'edgecolor', 'k', 'linewidth', 1)

            xlabel('Lag(ms)')

            title(['Cluster#' num2str(indcluster)])
            legend(num2str(length(kutime)))


        end

        % Button pushed function: SelectgoodspikesButton
        function SelectgoodspikesButtonPushed(app, event)
            rect = getrect(app.UIAxesColormap);

            xrange = [rect(1) rect(1)+rect(3)];
            yrange = [rect(2) rect(2)+rect(4)];

            polyspiketimes = app.LoadspikesButton.UserData;
            clusters = unique(polyspiketimes.cluster_class(:, 1));
            nclusters = length(clusters);
            indcluster = str2num(app.SpikeclusterDropDown.Value);

            spikeswave_thiscluster = polyspiketimes.spikes(find(polyspiketimes.cluster_class(:, 1) == indcluster), :);
            tspike = [1:size(spikeswave_thiscluster, 2)];

            spk_tocheck = spikeswave_thiscluster(:, tspike>=xrange(1) & tspike<=xrange(2));

            [ia, ~] = find(spk_tocheck>=yrange(1) & spk_tocheck<=yrange(2)); % this actually selects good spikes

            ia = setdiff(1:size(spk_tocheck, 1), unique(ia));

            if ~isempty(ia)
                indbad = unique(ia);
                indbadplot = indbad(randperm(length(indbad), min(100, length(indbad))));

                figure(34); clf(34)

                plot(tspike, spikeswave_thiscluster(indbadplot, :), 'color', [0.5 0.5 0.5]);
                app.SelectbadspikesButton.UserData = indbad;
                text(1, 0, num2str(length(indbad)))

            end;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {742, 742};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {216, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.MultichannelsDropDownLabel = uilabel(app.LeftPanel);
            app.MultichannelsDropDownLabel.Text = 'Multi-channels';
            app.MultichannelsDropDown = uidropdown(app.LeftPanel);
            app.MultichannelsDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32'};
            app.MultichannelsDropDown.Value = '1';
            app.LoadspikesButton = uibutton(app.LeftPanel, 'push');
            app.LoadspikesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadspikesButtonPushed, true);
            app.LoadspikesButton.Text = 'Load spikes';
            app.SpikeclusterDropDownLabel = uilabel(app.LeftPanel);
            app.SpikeclusterDropDownLabel.Text = 'Spike cluster';
            app.SpikeclusterDropDown = uidropdown(app.LeftPanel);
            app.SpikeclusterDropDown.ValueChangedFcn = createCallbackFcn(app, @SpikeclusterDropDownValueChanged, true);
            app.PlotspikesButton = uibutton(app.LeftPanel, 'push');
            app.PlotspikesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotspikesButtonPushed, true);
            app.PlotspikesButton.Text = 'Plot spikes';
            app.NumofspikesEditFieldLabel = uilabel(app.LeftPanel);
            app.NumofspikesEditFieldLabel.Text = 'Num of spikes';
            app.NumofspikesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumofspikesEditField.Value = 250;
            app.TotalspikesLabel = uilabel(app.LeftPanel);
            app.TotalspikesLabel.Text = 'Total spikes';
            app.SaveButton = uibutton(app.LeftPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Panel = uipanel(app.LeftPanel);
            app.Panel.Title = 'Panel';
            app.UpperlimitEditFieldLabel = uilabel(app.Panel);
            app.UpperlimitEditFieldLabel.Text = 'Upper limit';
            app.UpperlimitEditField = uieditfield(app.Panel, 'numeric');
            app.UpperlimitEditField.Value = 1000;
            app.LowerlimitEditFieldLabel = uilabel(app.Panel);
            app.LowerlimitEditFieldLabel.Text = 'Lower limit';
            app.LowerlimitEditField = uieditfield(app.Panel, 'numeric');
            app.LowerlimitEditField.Value = -1000;
            app.SelectoutliersButton = uibutton(app.Panel, 'push');
            app.SelectoutliersButton.ButtonPushedFcn = createCallbackFcn(app, @SelectoutliersButtonPushed, true);
            app.SelectoutliersButton.Text = 'Select outliers';
            app.SelectbadspikesButton = uibutton(app.Panel, 'push');
            app.SelectbadspikesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectbadspikesButtonPushed, true);
            app.SelectbadspikesButton.Text = 'Select bad spikes';
            app.AddtobadButton = uibutton(app.Panel, 'push');
            app.AddtobadButton.ButtonPushedFcn = createCallbackFcn(app, @AddtobadButtonPushed, true);
            app.AddtobadButton.Text = 'Add to bad';
            app.numberLabel = uilabel(app.Panel);
            app.numberLabel.Text = 'number';
            app.RemovebadspikesButton = uibutton(app.Panel, 'push');
            app.RemovebadspikesButton.ButtonPushedFcn = createCallbackFcn(app, @RemovebadspikesButtonPushed, true);
            app.RemovebadspikesButton.Text = 'Remove bad spikes';
            app.CheckautocorrelogramButton = uibutton(app.Panel, 'push');
            app.CheckautocorrelogramButton.ButtonPushedFcn = createCallbackFcn(app, @CheckautocorrelogramButtonPushed, true);
            app.CheckautocorrelogramButton.Text = 'Check auto-correlogram';
            app.SelectgoodspikesButton = uibutton(app.Panel, 'push');
            app.SelectgoodspikesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectgoodspikesButtonPushed, true);
            app.SelectgoodspikesButton.Text = 'Select good spikes';
            app.meansubCheckBox = uicheckbox(app.LeftPanel);
            app.meansubCheckBox.Text = 'meansub';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxesColormap = uiaxes(app.RightPanel);
            xlabel(app.UIAxesColormap, 'X')
            ylabel(app.UIAxesColormap, 'Y')
            zlabel(app.UIAxesColormap, 'Z')
            app.UIAxesSpikes = uiaxes(app.RightPanel);
            xlabel(app.UIAxesSpikes, 'X')
            ylabel(app.UIAxesSpikes, 'Y')
            zlabel(app.UIAxesSpikes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CheckSingleSpikes

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end