classdef RemoveSpikeArtifact < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        PrintButton                matlab.ui.control.Button
        ClusterButton              matlab.ui.control.Button
        SaveButton                 matlab.ui.control.Button
        Panel                      matlab.ui.container.Panel
        SelectbadspikesButton      matlab.ui.control.Button
        ClearbadButton             matlab.ui.control.Button
        RemovebadspikesButton      matlab.ui.control.Button
        numberLabel                matlab.ui.control.Label
        AddtobadButton             matlab.ui.control.Button
        OutliersButton             matlab.ui.control.Button
        LowerlimitEditField        matlab.ui.control.NumericEditField
        LowerlimitEditFieldLabel   matlab.ui.control.Label
        UpperlimitEditField        matlab.ui.control.NumericEditField
        UpperlimitEditFieldLabel   matlab.ui.control.Label
        NumofspikesEditField       matlab.ui.control.NumericEditField
        TotalspikesLabel           matlab.ui.control.Label
        NumofspikesEditFieldLabel  matlab.ui.control.Label
        PlotspikesButton           matlab.ui.control.Button
        LoadspikesButton           matlab.ui.control.Button
        LoadtetrodespikesLabel     matlab.ui.control.Label
        TetrodeDropDown            matlab.ui.control.DropDown
        RightPanel                 matlab.ui.container.Panel
        UIAxes_Wave                matlab.ui.control.UIAxes
        UIAxes_Hist                matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadspikesButton
        function LoadspikesButtonPushed(app, event)
                          

            % Load this spike cluster
           indch = app.TetrodeDropDown.Value;
           poly_name = ['polytrode' num2str(indch) '_spikes.mat'];
            polyspikes = load(poly_name); 
            %             No spike sorting information
            %             par: [1×1 struct]
            %             index: [5.3000 35.8333 57.5333 72.4667 92.1333 119.1667 … ]
            %             thr: [13×4 double]
            %             spikes: [320929×256 double]
            app.LoadspikesButton.UserData = polyspikes;
            app.AddtobadButton.UserData = [];

            
        end

        % Button pushed function: PlotspikesButton
        function PlotspikesButtonPushed(app, event)
             
            polyspikes = app.LoadspikesButton.UserData;
            %             par: [1×1 struct]
            %             index: [5.3000 35.8333 57.5333 72.4667 92.1333 119.1667 … ]
            %             thr: [13×4 double]
            %             spikes: [320929×256 double]
            spikeswave_all = polyspikes.spikes;
            plotnum = min([size(spikeswave_all, 1) app.NumofspikesEditField.Value]);
            spikewave_toplot = spikeswave_all(randperm(size(spikeswave_all, 1), plotnum), :);
            tspike = [1:size(spikewave_toplot, 2)];

            plot(app.UIAxes_Wave, tspike, spikewave_toplot, 'color', 'k');
            
            app.UIAxes_Wave.XLabel.String            = 'Time (ms)';
            app.UIAxes_Wave.XLim = [tspike(1) tspike(end)];
            app.UIAxes_Wave.YLim = [1.2*min(spikewave_toplot(:)) 1.2*max(spikewave_toplot(:))];
 
                
            yedges = [min(spikeswave_all(:)):10:max(spikeswave_all(:))]; % 10 uV per bin
            ncountsall = zeros(length(tspike), length(yedges)-1);

            for i =1:length(tspike)                
                ncountsall(i, :) = histcounts(spikeswave_all(:, i), yedges);
            end;

             imagesc(app.UIAxes_Hist, tspike, yedges(1:end)+5, ncountsall');

             app.UIAxes_Hist.XLim =[tspike(1) tspike(end)];
             app.UIAxes_Hist.YLim = [yedges(1) yedges(end)];
             app.UIAxes_Hist.YDir = 'normal';

             app.TotalspikesLabel.Text = num2str(size(spikeswave_all, 1));

             display('Done')


        end

        % Button pushed function: OutliersButton
        function OutliersButtonPushed(app, event)
            
            upper_limit = app.UpperlimitEditField.Value;
            lower_limit = app.LowerlimitEditField.Value;

            polyspikes = app.LoadspikesButton.UserData; 
            spikeswave_all = polyspikes.spikes;
            tspike = [1:size(spikeswave_all, 2)];

            [ia, ~] = find(spikeswave_all> upper_limit | spikeswave_all<lower_limit);

            if ~isempty(ia)

                indoutofbound = unique(ia);
                indbadplot = indoutofbound(randperm(length(indoutofbound), min(100, length(indoutofbound))));
                figure(34); clf(34)

                plot(tspike, spikeswave_all(indbadplot, :), 'color', [0.5 0.5 0.5]);
                app.OutliersButton.UserData = indoutofbound; 
                text(1, 0, num2str(length(indoutofbound)), 'color', 'r', 'fontsize', 15, 'fontweight','bold')

            end;

        end

        % Button pushed function: AddtobadButton
        function AddtobadButtonPushed(app, event)

            newbadindex = app.SelectbadspikesButton.UserData;

            if size(newbadindex, 1)>size(newbadindex, 2)
                newbadindex = newbadindex';
                app.SelectbadspikesButton.UserData = [];
            end;

            newbadindex2 = app.OutliersButton.UserData;

            if size(newbadindex2, 1)>size(newbadindex2, 2)
                newbadindex2 = newbadindex2';
                app.OutliersButton.UserData = [];
            end;

            if isempty(app.AddtobadButton.UserData)
                app.AddtobadButton.UserData = unique([newbadindex newbadindex2]);
            else
                app.AddtobadButton.UserData = unique([app.AddtobadButton.UserData newbadindex newbadindex2]);
            end;
                
            app.numberLabel.Text = num2str(length(app.AddtobadButton.UserData));

        end

        % Button pushed function: ClearbadButton
        function ClearbadButtonPushed(app, event)
            app.AddtobadButton.UserData = [];
            app.numberLabel.Text = '';
            app.OutliersButton.UserData = [];
            app.SelectbadspikesButton.UserData = [];
        end

        % Button pushed function: RemovebadspikesButton
        function RemovebadspikesButtonPushed(app, event)
            
            % remove bad spikes from the current cluster
            indbad =  app.AddtobadButton.UserData;
            polyspikes = app.LoadspikesButton.UserData;
 
%             polyspikes =
%             struct with fields:
%             par: [1×1 struct]
%             index: [3.8333 5.7667 8.9667 12.4667 26.7667 31.6667 37.2000 … ]
%             thr: [13×4 double]
%             spikes: [443901×256 double]

            polyspikes.spikes(indbad, :) =  [];
            polyspikes.index(indbad)      =  [];

            app.AddtobadButton.UserData = [];
            app.LoadspikesButton.UserData = polyspikes;  % save the data
            app.numberLabel.Text = '';

            spikeswave_all = polyspikes.spikes;
            app.TotalspikesLabel.Text = num2str(size(spikeswave_all, 1));
            plotnum = min([size(spikeswave_all, 1) app.NumofspikesEditField.Value]);
            spikewave_toplot = spikeswave_all(randperm(size(spikeswave_all, 1), plotnum), :);

            tspike = [1:size(spikewave_toplot, 2)];

            plot(app.UIAxes_Wave, tspike, spikewave_toplot, 'color', 'k')
            app.UIAxes_Wave.XLabel.String            = 'Time (ms)';

            app.UIAxes_Wave.XLim = [tspike(1) tspike(end)];
            app.UIAxes_Wave.YLim = [1.2*min(spikewave_toplot(:)) 1.2*max(spikewave_toplot(:))];

            axes(app.UIAxes_Wave)
            axis tight  
                
            yedges = [min(spikewave_toplot(:)):10:max(spikewave_toplot(:))]; % 10 uV per bin
            ncountsall = zeros(length(tspike), length(yedges)-1);

            for i =1:length(tspike)                
                ncountsall(i, :) = histcounts(spikewave_toplot(:, i), yedges);
            end;

             imagesc(app.UIAxes_Hist, tspike, yedges(1:end)+5, ncountsall');

             app.UIAxes_Hist.XLim =[tspike(1) tspike(end)];
             app.UIAxes_Hist.YLim = [yedges(1) yedges(end)];
             app.UIAxes_Hist.YDir = 'normal'; 


        end

        % Button pushed function: SelectbadspikesButton
        function SelectbadspikesButtonPushed(app, event)
            
            rect = getrect(app.UIAxes_Hist);

            xrange = [rect(1) rect(1)+rect(3)];
            yrange = [rect(2) rect(2)+rect(4)];

            polyspikes = app.LoadspikesButton.UserData; 
            spikeswave_all = polyspikes.spikes;

            tspike = [1:size(spikeswave_all, 2)];

            spk_tocheck = spikeswave_all(:, tspike>=xrange(1) & tspike<=xrange(2));
            [ia, ~] = find(spk_tocheck>=yrange(1) & spk_tocheck<=yrange(2));

            if ~isempty(ia)
                indbad = unique(ia);
                indbadplot = indbad(randperm(length(indbad), min(100, length(indbad))));

                figure(34); clf(34)
                plot(tspike, spikeswave_all(indbadplot, :), 'color', [0.5 0.5 0.5]);
                app.SelectbadspikesButton.UserData = indbad;
                text(1, 0, num2str(length(indbad)), 'FontSize',15, 'FontWeight','bold', 'Color','r')

            end;

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
          indch = app.TetrodeDropDown.Value;
           poly_name = ['polytrode' num2str(indch) '_spikes.mat'];
       
           %             No spike sorting information
           %             par: [1×1 struct]
           %             index: [5.3000 35.8333 57.5333 72.4667 92.1333 119.1667 … ]
           %             thr: [13×4 double]
           %             spikes: [320929×256 double]

            polyspikes = app.LoadspikesButton.UserData; 

            par = polyspikes.par;
            spikes = polyspikes.spikes;
            index = polyspikes.index;
            thr = polyspikes.thr;
            
            save(poly_name, 'spikes', 'index', 'par', 'thr');

            display('Done saving')


        end

        % Button pushed function: ClusterButton
        function ClusterButtonPushed(app, event)
            % cluster this tetrode

            param.detect_fmin               =       300;               % high pass filter for detection
            param.detect_fmax               =      5000;              % low pass filter for detection (default 1000)
            param.sort_fmin                    =      300;                 % high pass filter for sorting
            param.sort_fmax                    =      5000;                % low pass filter for sorting (default 3000)
            param.stdmin                         =      4;
            param.stdmax                        =      20;
            param.sr                                =      30000;

            indch = app.TetrodeDropDown.Value;
            Do_clustering( str2num(indch),  'par', param)


        end

        % Button pushed function: PrintButton
        function PrintButtonPushed(app, event)
          
            savename = fullfile(pwd, ['RemoveArtifactTetrode'  app.TetrodeDropDown.Value '.png'])
            exportapp(app.UIFigure, savename)

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TetrodeDropDown = uidropdown(app.LeftPanel);
            app.TetrodeDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', ''};
            app.TetrodeDropDown.Value = '1';
            app.LoadtetrodespikesLabel = uilabel(app.LeftPanel);
            app.LoadtetrodespikesLabel.Text = 'Load tetrode spikes';
            app.LoadspikesButton = uibutton(app.LeftPanel, 'push');
            app.LoadspikesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadspikesButtonPushed, true);
            app.LoadspikesButton.Text = 'Load spikes';
            app.PlotspikesButton = uibutton(app.LeftPanel, 'push');
            app.PlotspikesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotspikesButtonPushed, true);
            app.PlotspikesButton.Text = 'Plot spikes';
            app.NumofspikesEditFieldLabel = uilabel(app.LeftPanel);
            app.NumofspikesEditFieldLabel.Text = 'Num of spikes';
            app.TotalspikesLabel = uilabel(app.LeftPanel);
            app.TotalspikesLabel.Text = 'Total spikes';
            app.NumofspikesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumofspikesEditField.Value = 250;
            app.Panel = uipanel(app.LeftPanel);
            app.UpperlimitEditFieldLabel = uilabel(app.Panel);
            app.UpperlimitEditFieldLabel.Text = 'Upper limit';
            app.UpperlimitEditField = uieditfield(app.Panel, 'numeric');
            app.UpperlimitEditField.Value = 1000;
            app.LowerlimitEditFieldLabel = uilabel(app.Panel);
            app.LowerlimitEditFieldLabel.Text = 'Lower limit';
            app.LowerlimitEditField = uieditfield(app.Panel, 'numeric');
            app.LowerlimitEditField.Value = -1000;
            app.OutliersButton = uibutton(app.Panel, 'push');
            app.OutliersButton.ButtonPushedFcn = createCallbackFcn(app, @OutliersButtonPushed, true);
            app.OutliersButton.Text = 'Outliers';
            app.AddtobadButton = uibutton(app.Panel, 'push');
            app.AddtobadButton.ButtonPushedFcn = createCallbackFcn(app, @AddtobadButtonPushed, true);
            app.AddtobadButton.Text = 'Add to bad';
            app.numberLabel = uilabel(app.Panel);
            app.numberLabel.Text = 'number';
            app.RemovebadspikesButton = uibutton(app.Panel, 'push');
            app.RemovebadspikesButton.ButtonPushedFcn = createCallbackFcn(app, @RemovebadspikesButtonPushed, true);
            app.RemovebadspikesButton.Text = 'Remove bad spikes';
            app.ClearbadButton = uibutton(app.Panel, 'push');
            app.ClearbadButton.ButtonPushedFcn = createCallbackFcn(app, @ClearbadButtonPushed, true);
            app.ClearbadButton.Text = 'Clear bad';
            app.SelectbadspikesButton = uibutton(app.Panel, 'push');
            app.SelectbadspikesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectbadspikesButtonPushed, true);
            app.SelectbadspikesButton.Text = 'Select bad spikes';
            app.SaveButton = uibutton(app.LeftPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ClusterButton = uibutton(app.LeftPanel, 'push');
            app.ClusterButton.ButtonPushedFcn = createCallbackFcn(app, @ClusterButtonPushed, true);
            app.ClusterButton.Text = 'Cluster';
            app.PrintButton = uibutton(app.LeftPanel, 'push');
            app.PrintButton.ButtonPushedFcn = createCallbackFcn(app, @PrintButtonPushed, true);
            app.PrintButton.Text = 'Print';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_Hist = uiaxes(app.RightPanel);
            title(app.UIAxes_Hist, 'Histogram')
            xlabel(app.UIAxes_Hist, 'X')
            ylabel(app.UIAxes_Hist, 'Y')
            app.UIAxes_Wave = uiaxes(app.RightPanel);
            title(app.UIAxes_Wave, 'Waveform')
            xlabel(app.UIAxes_Wave, 'X')
            ylabel(app.UIAxes_Wave, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RemoveSpikeArtifact

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end