classdef Spikes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        KornblumPanel                 matlab.ui.container.Panel
        Ramp1unitButton               matlab.ui.control.Button
        LoadRButton                   matlab.ui.control.Button
        UnitDropDown                  matlab.ui.control.DropDown
        UnitDropDownLabel             matlab.ui.control.Label
        PSTHsAllButton                matlab.ui.control.Button
        PSTHs1unitButton              matlab.ui.control.Button
        CompileRButton                matlab.ui.control.Button
        SpikesortingPanel             matlab.ui.container.Panel
        ClusterallButton              matlab.ui.control.Button
        Convertbacktowave_clusButton  matlab.ui.control.Button
        ManualspikesortingusingSpikeCurationButton  matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        SpikesLabel                   matlab.ui.control.Label
        PolytrodeDropDown             matlab.ui.control.DropDown
        PolytrodeDropDownLabel        matlab.ui.control.Label
        OptionalConverttoMUAforsimpleclustButton  matlab.ui.control.Button
        Checkspikesusingwave_clusButton  matlab.ui.control.Button
        RemovespikeartifactandclusterspikesButton  matlab.ui.control.Button
        TetrodenumberDropDown         matlab.ui.control.DropDown
        TetrodenumberDropDownLabel    matlab.ui.control.Label
        RundataextractionandspikedetectionButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RemovespikeartifactandclusterspikesButton
        function RemovespikeartifactandclusterspikesButtonPushed(app, event)
            RemoveSpikeArtifact;
        end

        % Button pushed function: OptionalConverttoMUAforsimpleclustButton
        function OptionalConverttoMUAforsimpleclustButtonPushed(app, event)
            
            polyid = app.PolytrodeDropDown.Value;
            wave_clust2muapoly(polyid);


        end

        % Button pushed function: Checkspikesusingwave_clusButton
        function Checkspikesusingwave_clusButtonPushed(app, event)
            wave_clus;
        end

        % Button pushed function: 
        % ManualspikesortingusingSpikeCurationButton
        function ManualspikesortingusingSpikeCurationButtonPushed(app, event)
            SpikeCuration;
        end

        % Button pushed function: RundataextractionandspikedetectionButton
        function RundataextractionandspikedetectionButtonPushed(app, event)

            %% Step 1: Make polytrode files
            n_tetrodes = app.TetrodenumberDropDown.Value;
            switch n_tetrodes
                case '4'
                    TetrodesMap = [
                        1 3 5 7               % tetrode 1
                        2 4 6 8               % tetrode 2
                        9 11 13 15          % tetrode 3
                        10 12 14 16       % tetrode4
                        %     17 19 21 23 % tetrode 5
                        %     18 20 22 24 % tetrode 6
                        %     25 27 29 31 % tetrode 7
                        %     26 28 30 32 % tetrode 8
                        ];

                case '8'
                    TetrodesMap = [
                        1 3 5 7               % tetrode 1
                        2 4 6 8               % tetrode 2
                        9 11 13 15          % tetrode 3
                        10 12 14 16       % tetrode4
                        17 19 21 23 % tetrode 5
                        18 20 22 24 % tetrode 6
                        25 27 29 31 % tetrode 7
                        26 28 30 32 % tetrode 8
                        ];
            end;

            N_tetrodes = str2num(n_tetrodes);

            for i =1:N_tetrodes % 4 tetrodes, 16 channels/wires
                fid = fopen(['polytrode'  num2str(i) '.txt'], 'wt');
                wires = TetrodesMap(i, :);
                for k =1:length(wires)
                    this_channel = wires(k)
                    if this_channel>0
                        fprintf(fid, 'chdat%d.mat\n', this_channel);
                    end;
                end;
                fclose(fid);
            end;

            %% Step2: Extract raw data from ns6 files

            %% 2.1 Read NS6 file.
            ns6_files = dir('*.ns6');
            if length(ns6_files)>0
                for i =1:length(ns6_files)
                    if i ==1
                        openNSx(ns6_files(i).name, 'read', 'report')
                        NS6all= NS6;

                    else
                        openNSx(ns6_files(i).name, 'read', 'report')
                        NS6all(i)= NS6;
                    end;
                end;
            else
                return
            end;


            %% 2.2 Define channels
            Nch = length(TetrodesMap(:));
            EphysChs =[1:Nch];
            LiveChs = EphysChs;
            Fs = 30000; % this is the sampling rate

            data_avg = [];
            ndata = 0;

            %% 2.3 Extract ephys data
            pardata = 1;
            if pardata ==1
                filelist =[];
                for i =1:length(EphysChs)
                    ii = EphysChs(i);
                    data = [];
                    index =[];
                    for k = 1:length(NS6all)
                        NS6 = NS6all(k);
                        if k>1
                            dt_i =NS6all(k).MetaTags.DateTimeRaw-NS6all(1).MetaTags.DateTimeRaw; % start time of this session relative to the first session
                            dBlockOnset=dt_i(end)+dt_i(end-1)*1000+dt_i(end-2)*1000*60+dt_i(end-3)*1000*60*60;  % convert time to ms
                        else
                            dBlockOnset=0; % define the starting time of the first session as 0
                        end;
                        if iscell(NS6.Data)
                            for k =1:length(NS6.Data)
                                % this is time in ms
                                index_k = [0:length(double(NS6.Data{k}(ii, :)))-1]*1000/Fs+NS6.MetaTags.Timestamp(k)*1000/Fs+dBlockOnset;
                                % this is data of channel LiveChs(i)
                                data_k =  double(NS6.Data{k}(ii, :));
                                if k==1;
                                    data=   [data data_k]; % data is a necessary vector
                                    index = [index index_k ];
                                else
                                    % this is to take care of an old issue
                                    data_k = data_k (index_k> index(end)+0.03);
                                    index_k = index_k(index_k> index(end)+0.03);
                                    data=   [data data_k]; % data is a necessary vector
                                    index = [index index_k];
                                end;
                            end;
                        else
                            index = [index [0:length(double(NS6.Data(ii, :)))-1]*1000/Fs+NS6.MetaTags.Timestamp*1000/Fs+dBlockOnset];
                            data =  [data double(NS6.Data(ii, :))];
                        end;
                    end;

                    savefile = ['chdat' num2str(ii) '.mat']; % name of raw data files
                    save(savefile, 'data', 'index');

                    if isempty(data_avg)
                        ndata =1;
                        data_avg = data;
                    else
                        data_avg = (data_avg*ndata + data)/(ndata+1);
                        ndata = ndata +1;
                    end;
                end
            end;

            save('AvgData', 'data_avg');

            for i =1:length(EphysChs)
                ii = EphysChs(i);
                savefile = ['chdat' num2str(ii) '.mat']; % name of raw data files
                load(savefile);
                data = round(data - data_avg);
                save(savefile, 'data', 'index');
            end;


            %% 2.4  Extract analog input
            data = [];
            index =[];

            SignalName = 'ForceSensor';
            ind_ai_1 = 22;
            for k = 1:length(NS6all)
                NS6 = NS6all(k);
                if k>1
                    dt_i =NS6all(k).MetaTags.DateTimeRaw-NS6all(1).MetaTags.DateTimeRaw; % start time of this session relative to the first session
                    dBlockOnset=dt_i(end)+dt_i(end-1)*1000+dt_i(end-2)*1000*60+dt_i(end-3)*1000*60*60;  % convert time to ms
                else
                    dBlockOnset=0; % define the starting time of the first session as 0
                end;
                index = [index [0:length(double(NS6.Data(ind_ai_1, :)))-1]*1000/Fs+NS6.MetaTags.Timestamp*1000/Fs+dBlockOnset];
                data =  [data double(NS6.Data(ind_ai_1, :))];
            end;

            savefile = [SignalName , '.mat']; % this is the force sensor data
            save(savefile, 'data', 'index');


            % SignalName = 'LaserSignal';
            % data = [];
            % index =[];
            % ind_ai_2 = 23;
            % for k = 1:length(NS6all)
            %     NS6 = NS6all(k);
            %     if k>1
            %         dt_i =NS6all(k).MetaTags.DateTimeRaw-NS6all(1).MetaTags.DateTimeRaw; % start time of this session relative to the first session
            %         dBlockOnset=dt_i(end)+dt_i(end-1)*1000+dt_i(end-2)*1000*60+dt_i(end-3)*1000*60*60;  % convert time to ms
            %     else
            %         dBlockOnset=0; % define the starting time of the first session as 0
            %     end;
            %     index = [index [0:length(double(NS6.Data(ind_ai_2, :)))-1]*1000/Fs+NS6.MetaTags.Timestamp*1000/Fs+dBlockOnset];
            %     data =  [data double(NS6.Data(ind_ai_2, :))];
            % end;
            %
            % savefile = [SignalName , '.mat']; % this is the force sensor data
            % save(savefile, 'data', 'index');

            %% Step 3: Spike detection and sorting
            param.detection = 'both';

            param.detect_fmin               =       300;               % high pass filter for detection
            param.detect_fmax               =      5000;              % low pass filter for detection (default 1000)
            param.sort_fmin                    =      300;                 % high pass filter for sorting
            param.sort_fmax                    =      5000;                % low pass filter for sorting (default 3000)
            param.stdmin                         =      4;
            param.stdmax                        =      50;
            param.sr                                =      30000;
            %

            Get_spikes_pol(1:N_tetrodes,  'par', param);
        end

        % Button pushed function: CompileRButton
        function CompileRButtonPushed(app, event)
            BuildR4Tetrodes;
        end

        % Button pushed function: PSTHsAllButton
        function PSTHsAllButtonPushed(app, event)
            r=app.LoadRButton.UserData;
            for i =1: length(r.Units.SpikeTimes)
                KornblumSpikes(r, i);
                KornblumRamping(r,i)
                close all;
            end;
        end

        % Button pushed function: LoadRButton
        function LoadRButtonPushed(app, event)
            load('RTarrayAll.mat')
            app.LoadRButton.UserData = r;
            n_units = length( r.Units.SpikeTimes);
            app.UnitDropDown.Items = string([1:n_units]);
            app.LoadRButton.BackgroundColor = [0.3 0.8 0.5];
    
        end

        % Button pushed function: PSTHs1unitButton
        function PSTHs1unitButtonPushed(app, event)
            r=app.LoadRButton.UserData;
            n_unit = app.UnitDropDown.Value;
            KornblumSpikes(r, str2num(n_unit));  

        end

        % Button pushed function: ClusterallButton
        function ClusterallButtonPushed(app, event)
            
            n_tetrodes = app.TetrodenumberDropDown.Value;

            % cluster this tetrode

            param.detect_fmin               =       300;               % high pass filter for detection
            param.detect_fmax               =      5000;              % low pass filter for detection (default 1000)
            param.sort_fmin                    =      300;                 % high pass filter for sorting
            param.sort_fmax                    =      5000;                % low pass filter for sorting (default 3000)
            param.stdmin                         =      4;
            param.stdmax                        =      20;
            param.sr                                =      30000;

            for i =1:str2num(n_tetrodes)
                Do_clustering(i,  'par', param)
            end;
 

        end

        % Button pushed function: Ramp1unitButton
        function Ramp1unitButtonPushed(app, event)
            r=app.LoadRButton.UserData;
            n_unit = app.UnitDropDown.Value;
            KornblumRamping(r, str2num(n_unit));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SpikesortingPanel = uipanel(app.UIFigure);
            app.SpikesortingPanel.Title = 'Spike sorting';
            app.RundataextractionandspikedetectionButton = uibutton(app.SpikesortingPanel, 'push');
            app.RundataextractionandspikedetectionButton.ButtonPushedFcn = createCallbackFcn(app, @RundataextractionandspikedetectionButtonPushed, true);
            app.RundataextractionandspikedetectionButton.Text = 'Run data extraction and spike detection ';
            app.TetrodenumberDropDownLabel = uilabel(app.SpikesortingPanel);
            app.TetrodenumberDropDownLabel.Text = 'Tetrode number';
            app.TetrodenumberDropDown = uidropdown(app.SpikesortingPanel);
            app.TetrodenumberDropDown.Items = {'4', '8'};
            app.TetrodenumberDropDown.Value = '4';
            app.RemovespikeartifactandclusterspikesButton = uibutton(app.SpikesortingPanel, 'push');
            app.RemovespikeartifactandclusterspikesButton.ButtonPushedFcn = createCallbackFcn(app, @RemovespikeartifactandclusterspikesButtonPushed, true);
            app.RemovespikeartifactandclusterspikesButton.Text = 'Remove spike artifact and cluster spikes';
            app.Checkspikesusingwave_clusButton = uibutton(app.SpikesortingPanel, 'push');
            app.Checkspikesusingwave_clusButton.ButtonPushedFcn = createCallbackFcn(app, @Checkspikesusingwave_clusButtonPushed, true);
            app.Checkspikesusingwave_clusButton.Text = 'Check spikes using wave_clus';
            app.OptionalConverttoMUAforsimpleclustButton = uibutton(app.SpikesortingPanel, 'push');
            app.OptionalConverttoMUAforsimpleclustButton.ButtonPushedFcn = createCallbackFcn(app, @OptionalConverttoMUAforsimpleclustButtonPushed, true);
            app.OptionalConverttoMUAforsimpleclustButton.Text = 'Optional: Convert to MUA for simpleclust';
            app.PolytrodeDropDownLabel = uilabel(app.SpikesortingPanel);
            app.PolytrodeDropDownLabel.Text = 'Polytrode';
            app.PolytrodeDropDown = uidropdown(app.SpikesortingPanel);
            app.PolytrodeDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.PolytrodeDropDown.Value = '1';
            app.SpikesLabel = uilabel(app.SpikesortingPanel);
            app.SpikesLabel.Text = 'Spikes';
            app.Image = uiimage(app.SpikesortingPanel);
            app.Image.ImageSource = 'spikepic.png';
            app.ManualspikesortingusingSpikeCurationButton = uibutton(app.SpikesortingPanel, 'push');
            app.ManualspikesortingusingSpikeCurationButton.ButtonPushedFcn = createCallbackFcn(app, @ManualspikesortingusingSpikeCurationButtonPushed, true);
            app.ManualspikesortingusingSpikeCurationButton.Text = 'Manual spike sorting using ''SpikeCuration''';
            app.Convertbacktowave_clusButton = uibutton(app.SpikesortingPanel, 'push');
            app.Convertbacktowave_clusButton.Text = 'Convert back to wave_clus';
            app.ClusterallButton = uibutton(app.SpikesortingPanel, 'push');
            app.ClusterallButton.ButtonPushedFcn = createCallbackFcn(app, @ClusterallButtonPushed, true);
            app.ClusterallButton.Text = 'Cluster all';
            app.KornblumPanel = uipanel(app.UIFigure);
            app.KornblumPanel.Title = 'Kornblum';
            app.CompileRButton = uibutton(app.KornblumPanel, 'push');
            app.CompileRButton.ButtonPushedFcn = createCallbackFcn(app, @CompileRButtonPushed, true);
            app.CompileRButton.Text = 'Compile R';
            app.PSTHs1unitButton = uibutton(app.KornblumPanel, 'push');
            app.PSTHs1unitButton.ButtonPushedFcn = createCallbackFcn(app, @PSTHs1unitButtonPushed, true);
            app.PSTHs1unitButton.Text = 'PSTHs 1 unit';
            app.PSTHsAllButton = uibutton(app.KornblumPanel, 'push');
            app.PSTHsAllButton.ButtonPushedFcn = createCallbackFcn(app, @PSTHsAllButtonPushed, true);
            app.PSTHsAllButton.Text = 'PSTHs All';
            app.UnitDropDownLabel = uilabel(app.KornblumPanel);
            app.UnitDropDownLabel.Text = 'Unit';
            app.UnitDropDown = uidropdown(app.KornblumPanel);
            app.UnitDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '21', '32'};
            app.UnitDropDown.Value = '1';
            app.LoadRButton = uibutton(app.KornblumPanel, 'push');
            app.LoadRButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRButtonPushed, true);
            app.LoadRButton.Text = 'Load R';
            app.Ramp1unitButton = uibutton(app.KornblumPanel, 'push');
            app.Ramp1unitButton.ButtonPushedFcn = createCallbackFcn(app, @Ramp1unitButtonPushed, true);
            app.Ramp1unitButton.Text = 'Ramp 1 unit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Spikes

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end