classdef TetrodeSpikeSorting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        ProcessallButton            matlab.ui.control.Button
        PrintthisgraphButton        matlab.ui.control.Button
        ChanneltetrodeLabel         matlab.ui.control.Label
        ExtractdataButton           matlab.ui.control.Button
        ExperimenterEditField       matlab.ui.control.EditField
        ExperimenterEditFieldLabel  matlab.ui.control.Label
        SegmentsDropDown            matlab.ui.control.DropDown
        SegmentsDropDownLabel       matlab.ui.control.Label
        RatnameEditField            matlab.ui.control.EditField
        RatnameEditFieldLabel       matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        MeansubtractedCheckBox      matlab.ui.control.CheckBox
        SamplingRateEditField       matlab.ui.control.NumericEditField
        SamplingRateEditFieldLabel  matlab.ui.control.Label
        DetectionDropDown           matlab.ui.control.DropDown
        DetectionDropDownLabel      matlab.ui.control.Label
        stdmaxEditField             matlab.ui.control.NumericEditField
        stdmaxEditFieldLabel        matlab.ui.control.Label
        stdminEditField             matlab.ui.control.NumericEditField
        stdminEditFieldLabel        matlab.ui.control.Label
        fmaxEditField               matlab.ui.control.NumericEditField
        fmaxEditFieldLabel          matlab.ui.control.Label
        fminEditField               matlab.ui.control.NumericEditField
        fminEditFieldLabel          matlab.ui.control.Label
        TrackalltetrodesButton      matlab.ui.control.Button
        Tracktetrode4Button         matlab.ui.control.Button
        Tracktetrode3Button         matlab.ui.control.Button
        Tracktetrode2Button         matlab.ui.control.Button
        Tracktetrode1Button         matlab.ui.control.Button
        MakepolytrodefilesButton    matlab.ui.control.Button
        ai3EditField                matlab.ui.control.EditField
        ai_3CheckBox                matlab.ui.control.CheckBox
        ai2EditField                matlab.ui.control.EditField
        ai_2CheckBox                matlab.ui.control.CheckBox
        ai1EditField                matlab.ui.control.EditField
        ai_1CheckBox                matlab.ui.control.CheckBox
        Tetrode4Panel               matlab.ui.container.Panel
        Tetrode1DropDown_16         matlab.ui.control.DropDown
        Tetrode1DropDown_15         matlab.ui.control.DropDown
        Tetrode1DropDown_14         matlab.ui.control.DropDown
        Tetrode1DropDown_13         matlab.ui.control.DropDown
        Tetrode3Panel               matlab.ui.container.Panel
        Tetrode1DropDown_12         matlab.ui.control.DropDown
        Tetrode1DropDown_11         matlab.ui.control.DropDown
        Tetrode1DropDown_10         matlab.ui.control.DropDown
        Tetrode1DropDown_9          matlab.ui.control.DropDown
        Tetrode2Panel               matlab.ui.container.Panel
        Tetrode1DropDown_8          matlab.ui.control.DropDown
        Tetrode1DropDown_7          matlab.ui.control.DropDown
        Tetrode1DropDown_6          matlab.ui.control.DropDown
        Tetrode1DropDown_5          matlab.ui.control.DropDown
        Tetrode1Panel               matlab.ui.container.Panel
        Tetrode1DropDown_4          matlab.ui.control.DropDown
        Tetrode1DropDown_3          matlab.ui.control.DropDown
        Tetrode1DropDown_2          matlab.ui.control.DropDown
        Tetrode1DropDown            matlab.ui.control.DropDown
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Drop down opening function: SegmentsDropDown
        function SegmentsDropDownOpening(app, event)
            % search for available ns6 files.

            x = dir('datafile*.ns6');
            AllSegments = arrayfun(@(x)x.name, x, 'UniformOutput', false);
            app.SegmentsDropDown.Items = AllSegments;


        end

        % Button pushed function: ExtractdataButton
        function ExtractdataButtonPushed(app, event)

            ns6_files = app.SegmentsDropDown.Items;
            if length(ns6_files)>0
                for i =1:length(ns6_files)
                    if i ==1
                        openNSx(ns6_files{i}, 'read', 'report')
                        NS6all= NS6;
                    else
                        openNSx(ns6_files{i}, 'read', 'report')
                        NS6all(i)= NS6;
                    end;
                end;
            else
                return
            end;

            %% Define channels
            Nch = 16;
            EphysChs =[1:Nch];
            LiveChs = EphysChs; 
            Fs = 30000; % this is the sampling rate
            RMS_Chs = zeros(1, 32);  % we use this to select "good" chs which will be used later to construct a common avg.

            data_avg = [];
            ndata = 0;

            %% Extract ephys data
            pardata = 1;
            if pardata ==1
                filelist =[];
                for i =1:length(EphysChs)
                    ii = EphysChs(i);
                    data = [];
                    index =[];
                    for k = 1:length(NS6all)
                        NS6 = NS6all(k);
                        if k>1
                            dt_i =NS6all(k).MetaTags.DateTimeRaw-NS6all(1).MetaTags.DateTimeRaw; % start time of this session relative to the first session
                            dBlockOnset=dt_i(end)+dt_i(end-1)*1000+dt_i(end-2)*1000*60+dt_i(end-3)*1000*60*60;  % convert time to ms
                        else
                            dBlockOnset=0; % define the starting time of the first session as 0
                        end;
                        if iscell(NS6.Data)
                            for k =1:length(NS6.Data)
                                % this is time in ms
                                index_k = [0:length(double(NS6.Data{k}(ii, :)))-1]*1000/Fs+NS6.MetaTags.Timestamp(k)*1000/Fs+dBlockOnset;
                                % this is data of channel LiveChs(i)
                                data_k =  double(NS6.Data{k}(ii, :));
                                if k==1;
                                    data=   [data data_k]; % data is a necessary vector
                                    index = [index index_k ];
                                else
                                    % this is to take care of an old issue
                                    data_k = data_k (index_k> index(end)+0.03);
                                    index_k = index_k(index_k> index(end)+0.03);
                                    data=   [data data_k]; % data is a necessary vector
                                    index = [index index_k];
                                end;
                            end;
                        else
                            index = [index [0:length(double(NS6.Data(ii, :)))-1]*1000/Fs+NS6.MetaTags.Timestamp*1000/Fs+dBlockOnset];
                            data =  [data double(NS6.Data(ii, :))];
                        end;
                    end;

                    savefile = ['chdat' num2str(ii) '.mat']; % name of raw data files
                    save(savefile, 'data', 'index');

                    if isempty(data_avg)
                        ndata =1;
                        data_avg = data; 
                    else
                        data_avg = (data_avg*ndata + data)/(ndata+1);
                        ndata = ndata +1;
                    end;
                end
            end;

            save('AvgData', 'data_avg');

            for i =1:length(EphysChs)
                ii = EphysChs(i);
                savefile = ['chdat' num2str(ii) '.mat']; % name of raw data files
                load(savefile);
                data = round(data - data_avg);
                save(savefile, 'data', 'index');
            end;


                %% Extract analog input
                data = [];
                index =[];

                if app.ai_1CheckBox.Value
                    SignalName = app.ai1EditField.Value;
                    ind_ai_1 = 22;
                    for k = 1:length(NS6all)
                        NS6 = NS6all(k);
                        if k>1
                            dt_i =NS6all(k).MetaTags.DateTimeRaw-NS6all(1).MetaTags.DateTimeRaw; % start time of this session relative to the first session
                            dBlockOnset=dt_i(end)+dt_i(end-1)*1000+dt_i(end-2)*1000*60+dt_i(end-3)*1000*60*60;  % convert time to ms
                        else
                            dBlockOnset=0; % define the starting time of the first session as 0
                        end;
                        index = [index [0:length(double(NS6.Data(ind_ai_1, :)))-1]*1000/Fs+NS6.MetaTags.Timestamp*1000/Fs+dBlockOnset];
                        data =  [data double(NS6.Data(ind_ai_1, :))];
                    end;

                    savefile = [SignalName , '.mat']; % this is the force sensor data
                    save(savefile, 'data', 'index');
                end;


                if app.ai_2CheckBox.Value
                    SignalName = app.ai2EditField.Value;
                    data = [];
                    index =[];
                    ind_ai_2 = 23;
                    for k = 1:length(NS6all)
                        NS6 = NS6all(k);
                        if k>1
                            dt_i =NS6all(k).MetaTags.DateTimeRaw-NS6all(1).MetaTags.DateTimeRaw; % start time of this session relative to the first session
                            dBlockOnset=dt_i(end)+dt_i(end-1)*1000+dt_i(end-2)*1000*60+dt_i(end-3)*1000*60*60;  % convert time to ms
                        else
                            dBlockOnset=0; % define the starting time of the first session as 0
                        end;
                        index = [index [0:length(double(NS6.Data(ind_ai_2, :)))-1]*1000/Fs+NS6.MetaTags.Timestamp*1000/Fs+dBlockOnset];
                        data =  [data double(NS6.Data(ind_ai_2, :))];
                    end;

                    savefile = [SignalName , '.mat']; % this is the force sensor data
                    save(savefile, 'data', 'index');
                end;


        end

        % Button pushed function: MakepolytrodefilesButton
        function MakepolytrodefilesButtonPushed(app, event)
            
            % make polytrode files
            for i =1:4 % 4 tetrodes, 16 channels/wires

                fid = fopen(['polytrode'  num2str(i) '.txt'], 'wt' );
                wires = eval(['app.Tetrode' num2str(i) 'Panel.Children']);
                for k =1:length(wires)
                    this_channel = wires(k).Value;
                    if ~strcmp(this_channel, 'Void')
                    fprintf( fid, 'chdat%s.mat\n', this_channel(3:end));
                    end;
                end;
                fclose(fid);
            end;

        end

        % Button pushed function: Tracktetrode1Button
        function Tracktetrode1ButtonPushed(app, event)
            % Track this tetrode
            param.detection = app.DetectionDropDown.Value;

            param.detect_fmin               =       app.fminEditField.Value;               % high pass filter for detection
            param.detect_fmax               =      app.fmaxEditField.Value;              % low pass filter for detection (default 1000)
            param.sort_fmin                    =         app.fminEditField.Value;                 % high pass filter for sorting
            param.sort_fmax                    =        app.fmaxEditField.Value;                % low pass filter for sorting (default 3000)
            param.stdmin                         =          app.stdminEditField.Value;
            param.stdmax                        =       app.stdmaxEditField.Value;
            param.sr                                =        app.SamplingRateEditField.Value;
 
            Get_spikes_pol(1, 'par', param);
            Do_clustering(1, 'par', param)


        end

        % Button pushed function: Tracktetrode2Button
        function Tracktetrode2ButtonPushed(app, event)
                        % Track this tetrode
            param.detection = app.DetectionDropDown.Value;

            param.detect_fmin               =       app.fminEditField.Value;               % high pass filter for detection
            param.detect_fmax               =      app.fmaxEditField.Value;              % low pass filter for detection (default 1000)
            param.sort_fmin                    =         app.fminEditField.Value;                 % high pass filter for sorting
            param.sort_fmax                    =        app.fmaxEditField.Value;                % low pass filter for sorting (default 3000)
            param.stdmin                         =          app.stdminEditField.Value;
            param.stdmax                        =       app.stdmaxEditField.Value;
            param.sr                                =        app.SamplingRateEditField.Value;
            
            Get_spikes_pol(2, 'par', param);
            Do_clustering(2, 'par', param)

        end

        % Button pushed function: Tracktetrode3Button
        function Tracktetrode3ButtonPushed(app, event)
            % Track this tetrode
            param.detection = app.DetectionDropDown.Value;

            param.detect_fmin               =       app.fminEditField.Value;               % high pass filter for detection
            param.detect_fmax               =      app.fmaxEditField.Value;              % low pass filter for detection (default 1000)
            param.sort_fmin                    =         app.fminEditField.Value;                 % high pass filter for sorting
            param.sort_fmax                    =        app.fmaxEditField.Value;                % low pass filter for sorting (default 3000)
            param.stdmin                         =          app.stdminEditField.Value;
            param.stdmax                        =       app.stdmaxEditField.Value;
            param.sr                                =        app.SamplingRateEditField.Value;
            Get_spikes_pol(3,  'par', param);
            Do_clustering(3,  'par', param)
        end

        % Button pushed function: Tracktetrode4Button
        function Tracktetrode4ButtonPushed(app, event)
                                    % Track this tetrode
            param.detection = app.DetectionDropDown.Value;

            param.detect_fmin               =       app.fminEditField.Value;               % high pass filter for detection
            param.detect_fmax               =      app.fmaxEditField.Value;              % low pass filter for detection (default 1000)
            param.sort_fmin                    =         app.fminEditField.Value;                 % high pass filter for sorting
            param.sort_fmax                    =        app.fmaxEditField.Value;                % low pass filter for sorting (default 3000)
            param.stdmin                         =          app.stdminEditField.Value;
            param.stdmax                        =       app.stdmaxEditField.Value;
            param.sr                                =        app.SamplingRateEditField.Value;
            Get_spikes_pol(4,  'par', param);
            Do_clustering(4,  'par', param)
        end

        % Button pushed function: TrackalltetrodesButton
        function TrackalltetrodesButtonPushed(app, event)
                                    % Track this tetrode
            param.detection = app.DetectionDropDown.Value;

            param.detect_fmin               =       app.fminEditField.Value;               % high pass filter for detection
            param.detect_fmax               =      app.fmaxEditField.Value;              % low pass filter for detection (default 1000)
            param.sort_fmin                    =         app.fminEditField.Value;                 % high pass filter for sorting
            param.sort_fmax                    =        app.fmaxEditField.Value;                % low pass filter for sorting (default 3000)
            param.stdmin                         =          app.stdminEditField.Value;
            param.stdmax                        =       app.stdmaxEditField.Value;
            param.sr                                =        app.SamplingRateEditField.Value;
            
            Get_spikes_pol(1:4,  'par', param);
            Do_clustering(1:4,  'par', param)
        end

        % Button pushed function: PrintthisgraphButton
        function PrintthisgraphButtonPushed(app, event)
                exportapp(app.UIFigure,'TetrodeSorting.png')
                exportapp(app.UIFigure,'TetrodeSorting.pdf')
        end

        % Button pushed function: ProcessallButton
        function ProcessallButtonPushed(app, event)
            
            ns6_files = app.SegmentsDropDown.Items;
            if length(ns6_files)>0
                for i =1:length(ns6_files)
                    if i ==1
                        openNSx(ns6_files{i}, 'read', 'report')
                        NS6all= NS6;
                    else
                        openNSx(ns6_files{i}, 'read', 'report')
                        NS6all(i)= NS6;
                    end;
                end;
            else
                return
            end;

            %% Define channels
            Nch = 16;
            EphysChs =[1:Nch];
            LiveChs = EphysChs; 
            Fs = 30000; % this is the sampling rate
            RMS_Chs = zeros(1, 32);  % we use this to select "good" chs which will be used later to construct a common avg.
            %% Extract ephys data
            pardata = 1;
            if pardata ==1
                filelist =[];
                for i =1:length(EphysChs)
                    ii = EphysChs(i);
                    data = [];
                    index =[];
                    for k = 1:length(NS6all)
                        NS6 = NS6all(k);
                        if k>1
                            dt_i =NS6all(k).MetaTags.DateTimeRaw-NS6all(1).MetaTags.DateTimeRaw; % start time of this session relative to the first session
                            dBlockOnset=dt_i(end)+dt_i(end-1)*1000+dt_i(end-2)*1000*60+dt_i(end-3)*1000*60*60;  % convert time to ms
                        else
                            dBlockOnset=0; % define the starting time of the first session as 0
                        end;
                        if iscell(NS6.Data)
                            for k =1:length(NS6.Data)
                                % this is time in ms
                                index_k = [0:length(double(NS6.Data{k}(ii, :)))-1]*1000/Fs+NS6.MetaTags.Timestamp(k)*1000/Fs+dBlockOnset;
                                % this is data of channel LiveChs(i)
                                data_k =  double(NS6.Data{k}(ii, :));
                                if k==1;
                                    data=   [data data_k]; % data is a necessary vector
                                    index = [index index_k ];
                                else
                                    % this is to take care of an old issue
                                    data_k = data_k (index_k> index(end)+0.03);
                                    index_k = index_k(index_k> index(end)+0.03);
                                    data=   [data data_k]; % data is a necessary vector
                                    index = [index index_k];
                                end;
                            end;
                        else
                            index = [index [0:length(double(NS6.Data(ii, :)))-1]*1000/Fs+NS6.MetaTags.Timestamp*1000/Fs+dBlockOnset];
                            data =  [data double(NS6.Data(ii, :))];
                        end;
                    end;

                    savefile = ['chdat' num2str(ii) '.mat']; % name of raw data files
                    save(savefile, 'data', 'index');
                end
            end;


                %% Extract analog input
                data = [];
                index =[];

                if app.ai_1CheckBox.Value
                    SignalName = app.ai1EditField.Value;
                    ind_ai_1 = 22;
                    for k = 1:length(NS6all)
                        NS6 = NS6all(k);
                        if k>1
                            dt_i =NS6all(k).MetaTags.DateTimeRaw-NS6all(1).MetaTags.DateTimeRaw; % start time of this session relative to the first session
                            dBlockOnset=dt_i(end)+dt_i(end-1)*1000+dt_i(end-2)*1000*60+dt_i(end-3)*1000*60*60;  % convert time to ms
                        else
                            dBlockOnset=0; % define the starting time of the first session as 0
                        end;
                        index = [index [0:length(double(NS6.Data(ind_ai_1, :)))-1]*1000/Fs+NS6.MetaTags.Timestamp*1000/Fs+dBlockOnset];
                        data =  [data double(NS6.Data(ind_ai_1, :))];
                    end;

                    savefile = [SignalName , '.mat']; % this is the force sensor data
                    save(savefile, 'data', 'index');
                end;


                if app.ai_2CheckBox.Value
                    SignalName = app.ai2EditField.Value;
                    data = [];
                    index =[];
                    ind_ai_2 = 23;
                    for k = 1:length(NS6all)
                        NS6 = NS6all(k);
                        if k>1
                            dt_i =NS6all(k).MetaTags.DateTimeRaw-NS6all(1).MetaTags.DateTimeRaw; % start time of this session relative to the first session
                            dBlockOnset=dt_i(end)+dt_i(end-1)*1000+dt_i(end-2)*1000*60+dt_i(end-3)*1000*60*60;  % convert time to ms
                        else
                            dBlockOnset=0; % define the starting time of the first session as 0
                        end;
                        index = [index [0:length(double(NS6.Data(ind_ai_2, :)))-1]*1000/Fs+NS6.MetaTags.Timestamp*1000/Fs+dBlockOnset];
                        data =  [data double(NS6.Data(ind_ai_2, :))];
                    end;

                    savefile = [SignalName , '.mat']; % this is the force sensor data
                    save(savefile, 'data', 'index');
                end;


            % make polytrode files
            for i =1:4 % 4 tetrodes, 16 channels/wires

                fid = fopen(['polytrode'  num2str(i) '.txt'], 'wt' );
                wires = eval(['app.Tetrode' num2str(i) 'Panel.Children']);
                for k =1:length(wires)
                    this_channel = wires(k).Value;
                    if ~strcmp(this_channel, 'Void')
                    fprintf( fid, 'chdat%s.mat\n', this_channel(3:end));
                    end;
                end;
                fclose(fid);
            end;


                                                % Track this tetrode
            param.detection = app.DetectionDropDown.Value;

            param.detect_fmin               =       app.fminEditField.Value;               % high pass filter for detection
            param.detect_fmax               =      app.fmaxEditField.Value;              % low pass filter for detection (default 1000)
            param.sort_fmin                    =         app.fminEditField.Value;                 % high pass filter for sorting
            param.sort_fmax                    =        app.fmaxEditField.Value;                % low pass filter for sorting (default 3000)
            param.stdmin                         =          app.stdminEditField.Value;
            param.stdmax                        =       app.stdmaxEditField.Value;
            param.sr                                =        app.SamplingRateEditField.Value;

            Get_spikes_pol(1:4,  'par', param);
            Do_clustering(1:4,  'par', param)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {459, 459};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RatnameEditFieldLabel = uilabel(app.LeftPanel);
            app.RatnameEditFieldLabel.Text = 'Rat name';
            app.RatnameEditField = uieditfield(app.LeftPanel, 'text');
            app.SegmentsDropDownLabel = uilabel(app.LeftPanel);
            app.SegmentsDropDownLabel.Text = 'Segments';
            app.SegmentsDropDown = uidropdown(app.LeftPanel);
            app.SegmentsDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SegmentsDropDownOpening, true);
            app.ExperimenterEditFieldLabel = uilabel(app.LeftPanel);
            app.ExperimenterEditFieldLabel.Text = 'Experimenter';
            app.ExperimenterEditField = uieditfield(app.LeftPanel, 'text');
            app.ExtractdataButton = uibutton(app.LeftPanel, 'push');
            app.ExtractdataButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractdataButtonPushed, true);
            app.ExtractdataButton.Text = 'Extract data';
            app.ChanneltetrodeLabel = uilabel(app.LeftPanel);
            app.ChanneltetrodeLabel.Text = '16-Channel tetrode';
            app.PrintthisgraphButton = uibutton(app.LeftPanel, 'push');
            app.PrintthisgraphButton.ButtonPushedFcn = createCallbackFcn(app, @PrintthisgraphButtonPushed, true);
            app.PrintthisgraphButton.Text = 'Print this graph';
            app.ProcessallButton = uibutton(app.LeftPanel, 'push');
            app.ProcessallButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessallButtonPushed, true);
            app.ProcessallButton.Text = 'Process all';
            app.RightPanel = uipanel(app.GridLayout);
            app.Tetrode1Panel = uipanel(app.RightPanel);
            app.Tetrode1Panel.Title = 'Tetrode 1';
            app.Tetrode1DropDown = uidropdown(app.Tetrode1Panel);
            app.Tetrode1DropDown.Items = {'Ch1', 'Ch2', 'Ch3', 'Ch4', 'Ch5', 'Ch6', 'Ch7', 'Ch8', 'Ch9', 'Ch10', 'Ch11', 'Ch12', 'Ch13', 'Ch14', 'Ch15', 'Ch16', 'Void'};
            app.Tetrode1DropDown.Value = 'Ch1';
            app.Tetrode1DropDown_2 = uidropdown(app.Tetrode1Panel);
            app.Tetrode1DropDown_2.Items = {'Ch1', 'Ch2', 'Ch3', 'Ch4', 'Ch5', 'Ch6', 'Ch7', 'Ch8', 'Ch9', 'Ch10', 'Ch11', 'Ch12', 'Ch13', 'Ch14', 'Ch15', 'Ch16'};
            app.Tetrode1DropDown_2.Value = 'Ch3';
            app.Tetrode1DropDown_3 = uidropdown(app.Tetrode1Panel);
            app.Tetrode1DropDown_3.Items = {'Ch1', 'Ch2', 'Ch3', 'Ch4', 'Ch5', 'Ch6', 'Ch7', 'Ch8', 'Ch9', 'Ch10', 'Ch11', 'Ch12', 'Ch13', 'Ch14', 'Ch15', 'Ch16'};
            app.Tetrode1DropDown_3.Value = 'Ch5';
            app.Tetrode1DropDown_4 = uidropdown(app.Tetrode1Panel);
            app.Tetrode1DropDown_4.Items = {'Ch1', 'Ch2', 'Ch3', 'Ch4', 'Ch5', 'Ch6', 'Ch7', 'Ch8', 'Ch9', 'Ch10', 'Ch11', 'Ch12', 'Ch13', 'Ch14', 'Ch15', 'Ch16'};
            app.Tetrode1DropDown_4.Value = 'Ch7';
            app.Tetrode2Panel = uipanel(app.RightPanel);
            app.Tetrode2Panel.Title = 'Tetrode 2';
            app.Tetrode1DropDown_5 = uidropdown(app.Tetrode2Panel);
            app.Tetrode1DropDown_5.Items = {'Ch1', 'Ch2', 'Ch3', 'Ch4', 'Ch5', 'Ch6', 'Ch7', 'Ch8', 'Ch9', 'Ch10', 'Ch11', 'Ch12', 'Ch13', 'Ch14', 'Ch15', 'Ch16'};
            app.Tetrode1DropDown_5.Value = 'Ch2';
            app.Tetrode1DropDown_6 = uidropdown(app.Tetrode2Panel);
            app.Tetrode1DropDown_6.Items = {'Ch1', 'Ch2', 'Ch3', 'Ch4', 'Ch5', 'Ch6', 'Ch7', 'Ch8', 'Ch9', 'Ch10', 'Ch11', 'Ch12', 'Ch13', 'Ch14', 'Ch15', 'Ch16'};
            app.Tetrode1DropDown_6.Value = 'Ch4';
            app.Tetrode1DropDown_7 = uidropdown(app.Tetrode2Panel);
            app.Tetrode1DropDown_7.Items = {'Ch1', 'Ch2', 'Ch3', 'Ch4', 'Ch5', 'Ch6', 'Ch7', 'Ch8', 'Ch9', 'Ch10', 'Ch11', 'Ch12', 'Ch13', 'Ch14', 'Ch15', 'Ch16'};
            app.Tetrode1DropDown_7.Value = 'Ch6';
            app.Tetrode1DropDown_8 = uidropdown(app.Tetrode2Panel);
            app.Tetrode1DropDown_8.Items = {'Ch1', 'Ch2', 'Ch3', 'Ch4', 'Ch5', 'Ch6', 'Ch7', 'Ch8', 'Ch9', 'Ch10', 'Ch11', 'Ch12', 'Ch13', 'Ch14', 'Ch15', 'Ch16', 'Void'};
            app.Tetrode1DropDown_8.Value = 'Ch8';
            app.Tetrode3Panel = uipanel(app.RightPanel);
            app.Tetrode3Panel.Title = 'Tetrode 3';
            app.Tetrode1DropDown_9 = uidropdown(app.Tetrode3Panel);
            app.Tetrode1DropDown_9.Items = {'Ch1', 'Ch2', 'Ch3', 'Ch4', 'Ch5', 'Ch6', 'Ch7', 'Ch8', 'Ch9', 'Ch10', 'Ch11', 'Ch12', 'Ch13', 'Ch14', 'Ch15', 'Ch16', 'Void'};
            app.Tetrode1DropDown_9.Value = 'Ch9';
            app.Tetrode1DropDown_10 = uidropdown(app.Tetrode3Panel);
            app.Tetrode1DropDown_10.Items = {'Ch1', 'Ch2', 'Ch3', 'Ch4', 'Ch5', 'Ch6', 'Ch7', 'Ch8', 'Ch9', 'Ch10', 'Ch11', 'Ch12', 'Ch13', 'Ch14', 'Ch15', 'Ch16', 'Void'};
            app.Tetrode1DropDown_10.Value = 'Ch11';
            app.Tetrode1DropDown_11 = uidropdown(app.Tetrode3Panel);
            app.Tetrode1DropDown_11.Items = {'Ch1', 'Ch2', 'Ch3', 'Ch4', 'Ch5', 'Ch6', 'Ch7', 'Ch8', 'Ch9', 'Ch10', 'Ch11', 'Ch12', 'Ch13', 'Ch14', 'Ch15', 'Ch16', 'Void'};
            app.Tetrode1DropDown_11.Value = 'Ch13';
            app.Tetrode1DropDown_12 = uidropdown(app.Tetrode3Panel);
            app.Tetrode1DropDown_12.Items = {'Ch1', 'Ch2', 'Ch3', 'Ch4', 'Ch5', 'Ch6', 'Ch7', 'Ch8', 'Ch9', 'Ch10', 'Ch11', 'Ch12', 'Ch13', 'Ch14', 'Ch15', 'Ch16', 'Void'};
            app.Tetrode1DropDown_12.Value = 'Ch15';
            app.Tetrode4Panel = uipanel(app.RightPanel);
            app.Tetrode4Panel.Title = 'Tetrode 4';
            app.Tetrode1DropDown_13 = uidropdown(app.Tetrode4Panel);
            app.Tetrode1DropDown_13.Items = {'Ch1', 'Ch2', 'Ch3', 'Ch4', 'Ch5', 'Ch6', 'Ch7', 'Ch8', 'Ch9', 'Ch10', 'Ch11', 'Ch12', 'Ch13', 'Ch14', 'Ch15', 'Ch16', 'Void'};
            app.Tetrode1DropDown_13.Value = 'Ch10';
            app.Tetrode1DropDown_14 = uidropdown(app.Tetrode4Panel);
            app.Tetrode1DropDown_14.Items = {'Ch1', 'Ch2', 'Ch3', 'Ch4', 'Ch5', 'Ch6', 'Ch7', 'Ch8', 'Ch9', 'Ch10', 'Ch11', 'Ch12', 'Ch13', 'Ch14', 'Ch15', 'Ch16', 'Void'};
            app.Tetrode1DropDown_14.Value = 'Ch12';
            app.Tetrode1DropDown_15 = uidropdown(app.Tetrode4Panel);
            app.Tetrode1DropDown_15.Items = {'Ch1', 'Ch2', 'Ch3', 'Ch4', 'Ch5', 'Ch6', 'Ch7', 'Ch8', 'Ch9', 'Ch10', 'Ch11', 'Ch12', 'Ch13', 'Ch14', 'Ch15', 'Ch16', 'Void'};
            app.Tetrode1DropDown_15.Value = 'Ch14';
            app.Tetrode1DropDown_16 = uidropdown(app.Tetrode4Panel);
            app.Tetrode1DropDown_16.Items = {'Ch1', 'Ch2', 'Ch3', 'Ch4', 'Ch5', 'Ch6', 'Ch7', 'Ch8', 'Ch9', 'Ch10', 'Ch11', 'Ch12', 'Ch13', 'Ch14', 'Ch15', 'Ch16', 'Void'};
            app.Tetrode1DropDown_16.Value = 'Ch16';
            app.ai_1CheckBox = uicheckbox(app.RightPanel);
            app.ai_1CheckBox.Text = 'ai_1';
            app.ai1EditField = uieditfield(app.RightPanel, 'text');
            app.ai1EditField.Value = 'ForceSensor';
            app.ai_2CheckBox = uicheckbox(app.RightPanel);
            app.ai_2CheckBox.Text = 'ai_2';
            app.ai2EditField = uieditfield(app.RightPanel, 'text');
            app.ai2EditField.Value = 'LaserSignal';
            app.ai_3CheckBox = uicheckbox(app.RightPanel);
            app.ai_3CheckBox.Text = 'ai_3';
            app.ai3EditField = uieditfield(app.RightPanel, 'text');
            app.MakepolytrodefilesButton = uibutton(app.RightPanel, 'push');
            app.MakepolytrodefilesButton.ButtonPushedFcn = createCallbackFcn(app, @MakepolytrodefilesButtonPushed, true);
            app.MakepolytrodefilesButton.Text = 'Make polytrode files';
            app.Tracktetrode1Button = uibutton(app.RightPanel, 'push');
            app.Tracktetrode1Button.ButtonPushedFcn = createCallbackFcn(app, @Tracktetrode1ButtonPushed, true);
            app.Tracktetrode1Button.Text = 'Track tetrode 1';
            app.Tracktetrode2Button = uibutton(app.RightPanel, 'push');
            app.Tracktetrode2Button.ButtonPushedFcn = createCallbackFcn(app, @Tracktetrode2ButtonPushed, true);
            app.Tracktetrode2Button.Text = 'Track tetrode 2';
            app.Tracktetrode3Button = uibutton(app.RightPanel, 'push');
            app.Tracktetrode3Button.ButtonPushedFcn = createCallbackFcn(app, @Tracktetrode3ButtonPushed, true);
            app.Tracktetrode3Button.Text = 'Track tetrode 3';
            app.Tracktetrode4Button = uibutton(app.RightPanel, 'push');
            app.Tracktetrode4Button.ButtonPushedFcn = createCallbackFcn(app, @Tracktetrode4ButtonPushed, true);
            app.Tracktetrode4Button.Text = 'Track tetrode 4';
            app.TrackalltetrodesButton = uibutton(app.RightPanel, 'push');
            app.TrackalltetrodesButton.ButtonPushedFcn = createCallbackFcn(app, @TrackalltetrodesButtonPushed, true);
            app.TrackalltetrodesButton.Text = 'Track all tetrodes';
            app.fminEditFieldLabel = uilabel(app.RightPanel);
            app.fminEditFieldLabel.Text = 'fmin';
            app.fminEditField = uieditfield(app.RightPanel, 'numeric');
            app.fminEditField.Value = 300;
            app.fmaxEditFieldLabel = uilabel(app.RightPanel);
            app.fmaxEditFieldLabel.Text = 'fmax';
            app.fmaxEditField = uieditfield(app.RightPanel, 'numeric');
            app.fmaxEditField.Value = 5000;
            app.stdminEditFieldLabel = uilabel(app.RightPanel);
            app.stdminEditFieldLabel.Text = 'stdmin';
            app.stdminEditField = uieditfield(app.RightPanel, 'numeric');
            app.stdminEditField.Value = 4;
            app.stdmaxEditFieldLabel = uilabel(app.RightPanel);
            app.stdmaxEditFieldLabel.Text = 'stdmax';
            app.stdmaxEditField = uieditfield(app.RightPanel, 'numeric');
            app.stdmaxEditField.Value = 50;
            app.DetectionDropDownLabel = uilabel(app.RightPanel);
            app.DetectionDropDownLabel.Text = 'Detection';
            app.DetectionDropDown = uidropdown(app.RightPanel);
            app.DetectionDropDown.Items = {'neg', 'pos', 'both'};
            app.DetectionDropDown.Value = 'neg';
            app.SamplingRateEditFieldLabel = uilabel(app.RightPanel);
            app.SamplingRateEditFieldLabel.Text = 'SamplingRate';
            app.SamplingRateEditField = uieditfield(app.RightPanel, 'numeric');
            app.SamplingRateEditField.Value = 30000;
            app.MeansubtractedCheckBox = uicheckbox(app.RightPanel);
            app.MeansubtractedCheckBox.Text = 'Mean-subtracted';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TetrodeSpikeSorting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end