classdef make_project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Menu                 matlab.ui.container.Menu
        jpgMenu              matlab.ui.container.Menu
        aviMenu              matlab.ui.container.Menu
        Menu_2               matlab.ui.container.Menu
        ButtonOK             matlab.ui.control.Button
        Label                matlab.ui.control.Label
        TextArea             matlab.ui.control.TextArea
        Button_2             matlab.ui.control.Button
        Button_3             matlab.ui.control.Button
        TextArea_2           matlab.ui.control.TextArea
        Button_seq           matlab.ui.control.Button
        Button_jpg           matlab.ui.control.Button
        TextArea_3Label      matlab.ui.control.Label
        TextArea_3           matlab.ui.control.TextArea
        TextArea_4Label      matlab.ui.control.Label
        Textxname            matlab.ui.control.TextArea
        Textx1               matlab.ui.control.TextArea
        Textx2               matlab.ui.control.TextArea
        TextArea_7Label      matlab.ui.control.Label
        Textyname            matlab.ui.control.TextArea
        Texty1               matlab.ui.control.TextArea
        Texty2               matlab.ui.control.TextArea
        Button_area          matlab.ui.control.Button
        Button_avi           matlab.ui.control.Button
        TextArea_4Label_2    matlab.ui.control.Label
        Textmed              matlab.ui.control.TextArea
        Textbpod             matlab.ui.control.TextArea
        Button_med           matlab.ui.control.Button
        Button_bpod          matlab.ui.control.Button
        Button_movefile      matlab.ui.control.Button
        TextArea_7Label_2    matlab.ui.control.Label
        Textlabel_threshold  matlab.ui.control.TextArea
        TextArea_7Label_3    matlab.ui.control.Label
        Text_threshold       matlab.ui.control.TextArea
        ButtonGroup          matlab.ui.container.ButtonGroup
        Button_4             matlab.ui.control.RadioButton
        Button_5             matlab.ui.control.RadioButton
        Button_6             matlab.ui.control.RadioButton
        Textlabel_framerate  matlab.ui.control.TextArea
        Text_framerate       matlab.ui.control.TextArea
        Textlabel_secbefore  matlab.ui.control.TextArea
        Text_secbefore       matlab.ui.control.TextArea
        Textlabel_secafter   matlab.ui.control.TextArea
        Text_secafter        matlab.ui.control.TextArea
        Button_undomovefile  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            file_path = uigetdir('请选择文件夹');
            app.TextArea.Value = file_path;
            app.TextArea.Tag = '1';
        end

        % Button pushed function: ButtonOK
        function ButtonOKPushed(app, event)
            global path_project
            if strcmp('1',app.TextArea_2.Tag)
                path_project = cell2mat(app.TextArea_2.Value);
                app.jpgMenu.Enable = true;
            elseif strcmp('1',app.TextArea.Tag)
                path_project = cell2mat(app.TextArea.Value);
                make_config;
                path_project = cell2mat(app.TextArea.Value);
                mkdir(path_project);
                save_config;
                app.jpgMenu.Enable = true;
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)

            file_path = uigetdir('请选择文件夹');
            if file_path ~= 0
                app.TextArea_2.Value = file_path;
                app.TextArea_2.Tag = '1';
            end
        end

        % Menu selected function: jpgMenu
        function jpgMenuSelected(app, event)
            global path_project
            app.Menu.Enable = false;
            app.TextArea.Visible = false;
            app.TextArea_2.Visible = false;
            app.Button_2.Visible = false;
            app.Button_3.Visible = false;
            app.ButtonOK.Visible = false;
            app.Button_seq.Visible = true;
            app.Button_jpg.Visible = true;
            app.TextArea_3.Visible = true;
            load([path_project,'\','config.mat']);
            app.TextArea_3.Value = path_seq;
        end

        % Button pushed function: Button_seq
        function Button_seqPushed(app, event)
            global path_project
            [filename,filedir] = uigetfile('*.seq','请选择seq文件');
            app.TextArea_3.Value = [filedir,filename];
        end

        % Button pushed function: Button_jpg
        function Button_jpgPushed(app, event)
            global path_project
            load([path_project,'\','config.mat'])
            path_seq = cell2mat(app.TextArea_3.Value);
            path_jpg = [path_project,'\',path_seq(end-24:end-8)];
            path_timestamp_mat = [path_project,'\',path_seq(end-24:end-8),'.mat'];
            save_config;
            SEQtoJPG(path_seq,path_jpg,end_reading);
            txt2dat(path_jpg);
            app.aviMenu.Enable = true;
        end

        % Menu selected function: aviMenu
        function aviMenuSelected(app, event)
            global path_project
            app.Button_seq.Visible = false;
            app.Button_jpg.Visible = false;
            app.TextArea_3.Visible = false;
            app.Textxname.Visible = true;
            app.Textyname.Visible = true;
            app.Textx1.Visible = true;
            app.Textx2.Visible = true;
            app.Texty1.Visible = true;
            app.Texty2.Visible = true;
            app.Button_avi.Visible = true;
            app.Button_area.Visible = true;
            
            app.ButtonGroup.Visible = true;
            
            app.Text_threshold.Visible = true;
            app.Textlabel_threshold.Visible = true;
            app.Textlabel_framerate.Visible = true;
            app.Textlabel_secafter.Visible = true;
            app.Textlabel_secbefore.Visible = true;
            app.Text_framerate.Visible = true;
            app.Text_secafter.Visible = true;
            app.Text_secbefore.Visible = true;
            
            app.jpgMenu.Enable = false;
            load([path_project,'\','config.mat']);
            if isa(x_pos,'double') && isa(y_pos,'double')
                app.Textx1.Value = num2str(x_pos(1));
                app.Textx2.Value = num2str(x_pos(2));
                app.Texty1.Value = num2str(y_pos(1));
                app.Texty2.Value = num2str(y_pos(2));
            end
            
            if strcmp(threshold_type,'手动设定')
                app.ButtonGroup.SelectedObject = app.Button_6;
                app.Text_threshold.Value = num2str(threshold);
            elseif strcmp(threshold_type,'随后设定')
                app.ButtonGroup.SelectedObject = app.Button_5;
            end
            app.Text_framerate.Value = num2str(frame_rate);
            app.Text_secbefore.Value = num2str(sec_before);
            app.Text_secafter.Value = num2str(sec_after);
        end

        % Button pushed function: Button_area
        function Button_areaPushed(app, event)
            global path_project
            load([path_project,'\','config.mat']);
            [x,y] = find_pos(path_jpg);
            app.Textx1.Value = num2str(x(1));
            app.Textx2.Value = num2str(x(2));
            app.Texty1.Value = num2str(y(1));
            app.Texty2.Value = num2str(y(2));            
        end

        % Button pushed function: Button_avi
        function Button_aviPushed(app, event)
            global path_project
            if ~strcmp(cell2mat(app.Textx1.Value),'') && ~strcmp(cell2mat(app.Textx2.Value),'') && ~strcmp(cell2mat(app.Texty1.Value),'') && ~strcmp(cell2mat(app.Texty2.Value),'')
                load([path_project,'\','config.mat'])
                x_pos = [];
                y_pos = [];
                x_pos(1) = round(str2double(cell2mat(app.Textx1.Value)));
                x_pos(2) = round(str2double(cell2mat(app.Textx2.Value)));
                y_pos(1) = round(str2double(cell2mat(app.Texty1.Value)));
                y_pos(2) = round(str2double(cell2mat(app.Texty2.Value)));
                threshold_type = app.ButtonGroup.SelectedObject.Text;
                if strcmp(threshold_type,'手动设定')
                    threshold = str2double(cell2mat(app.Text_threshold.Value));
                end
                
                frame_rate = str2double(cell2mat(app.Text_framerate.Value));
                sec_before = str2double(cell2mat(app.Text_secbefore.Value));
                sec_after = str2double(cell2mat(app.Text_secafter.Value));
                
                
                path_avi = [path_jpg,'_cut'];
                save_config;
                cut_video(path_jpg,x_pos,y_pos,threshold_type,threshold,frame_rate,sec_before,sec_after);
                app.Menu_2.Enable = true;
            end
        end

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            global path_project
            app.Textxname.Visible = false;
            app.Textyname.Visible = false;
            app.Textx1.Visible = false;
            app.Textx2.Visible = false;
            app.Texty1.Visible = false;
            app.Texty2.Visible = false;
            app.Button_avi.Visible = false;
            app.Button_area.Visible = false;
             
            app.ButtonGroup.Visible = false;
            app.Button_4.Visible = false;
            app.Button_5.Visible = false;
            app.Button_6.Visible = false;
            app.Text_threshold.Visible = false;
            app.Textlabel_threshold.Visible = false;
            
            app.Textlabel_framerate.Visible = false;
            app.Textlabel_secafter.Visible = false;
            app.Textlabel_secbefore.Visible = false;
            app.Text_framerate.Visible = false;
            app.Text_secafter.Visible = false;
            app.Text_secbefore.Visible = false;
            
            app.Button_bpod.Visible = true;
            app.Button_med.Visible = true;
            app.Button_movefile.Visible = true;
            app.Textbpod.Visible = true;
            app.Textmed.Visible = true;
            app.Button_undomovefile.Visible = true;
            app.aviMenu.Enable = false;
            load([path_project,'\','config.mat']);
            app.Textbpod.Value = path_bpod;
            app.Textmed.Value = path_med;
        end

        % Button pushed function: Button_bpod
        function Button_bpodPushed(app, event)
            [path_bpod,dir_bpod] = uigetfile('*.mat','添加bpod文件');
            app.Textbpod.Value = [dir_bpod,path_bpod];
        end

        % Button pushed function: Button_med
        function Button_medPushed(app, event)
            [path_med,dir_med] = uigetfile('*.txt','添加med文件');
            app.Textmed.Value = [dir_med,path_med];
        end

        % Button pushed function: Button_movefile
        function Button_movefilePushed(app, event)
            global path_project
            load([path_project,'\','config.mat']);
            path_bpod = cell2mat(app.Textbpod.Value);
            path_med = cell2mat(app.Textmed.Value);
            save_config;
            if strfind(path_bpod,'Approaching')
                cd approach\
                move_video(path_avi,path_med,path_bpod);
            else
                move_video(path_avi,path_med,path_bpod);
            end
            
        end

        % Callback function
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            disp(selectedButton)
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged2(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'手动设定')
                app.Text_threshold.Editable = true;
            else
                app.Text_threshold.Editable = false;
            end
        end

        % Button pushed function: Button_undomovefile
        function Button_undomovefilePushed(app, event)
            global path_project
            load([path_project,'\','config.mat']);
            undo_movefile(path_avi);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '建立项目';
            app.jpgMenu = uimenu(app.UIFigure);
            app.jpgMenu.MenuSelectedFcn = createCallbackFcn(app, @jpgMenuSelected, true);
            app.jpgMenu.Enable = 'off';
            app.jpgMenu.Text = '转jpg';
            app.aviMenu = uimenu(app.UIFigure);
            app.aviMenu.MenuSelectedFcn = createCallbackFcn(app, @aviMenuSelected, true);
            app.aviMenu.Enable = 'off';
            app.aviMenu.Text = '剪切视频avi';
            app.Menu_2 = uimenu(app.UIFigure);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Enable = 'off';
            app.Menu_2.Text = '视频分类';
            app.ButtonOK = uibutton(app.UIFigure, 'push');
            app.ButtonOK.ButtonPushedFcn = createCallbackFcn(app, @ButtonOKPushed, true);
            app.ButtonOK.Text = 'OK';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '新建项目';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '加载项目';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.Button_seq = uibutton(app.UIFigure, 'push');
            app.Button_seq.ButtonPushedFcn = createCallbackFcn(app, @Button_seqPushed, true);
            app.Button_seq.Visible = 'off';
            app.Button_seq.Text = 'seq文件';
            app.Button_jpg = uibutton(app.UIFigure, 'push');
            app.Button_jpg.ButtonPushedFcn = createCallbackFcn(app, @Button_jpgPushed, true);
            app.Button_jpg.Visible = 'off';
            app.Button_jpg.Text = '转换为jpg';
            app.TextArea_3Label = uilabel(app.UIFigure);
            app.TextArea_3Label.Text = '';
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.Visible = 'off';
            app.TextArea_4Label = uilabel(app.UIFigure);
            app.TextArea_4Label.Visible = 'off';
            app.TextArea_4Label.Text = '';
            app.Textxname = uitextarea(app.UIFigure);
            app.Textxname.Editable = 'off';
            app.Textxname.Visible = 'off';
            app.Textxname.Value = {'X区域'};
            app.Textx1 = uitextarea(app.UIFigure);
            app.Textx1.Visible = 'off';
            app.Textx2 = uitextarea(app.UIFigure);
            app.Textx2.Visible = 'off';
            app.TextArea_7Label = uilabel(app.UIFigure);
            app.TextArea_7Label.Visible = 'off';
            app.TextArea_7Label.Text = '';
            app.Textyname = uitextarea(app.UIFigure);
            app.Textyname.Editable = 'off';
            app.Textyname.Visible = 'off';
            app.Textyname.Value = {'Y区域'};
            app.Texty1 = uitextarea(app.UIFigure);
            app.Texty1.Visible = 'off';
            app.Texty2 = uitextarea(app.UIFigure);
            app.Texty2.Visible = 'off';
            app.Button_area = uibutton(app.UIFigure, 'push');
            app.Button_area.ButtonPushedFcn = createCallbackFcn(app, @Button_areaPushed, true);
            app.Button_area.Visible = 'off';
            app.Button_area.Text = '寻找灯位置';
            app.Button_avi = uibutton(app.UIFigure, 'push');
            app.Button_avi.ButtonPushedFcn = createCallbackFcn(app, @Button_aviPushed, true);
            app.Button_avi.Visible = 'off';
            app.Button_avi.Text = '开始剪切';
            app.TextArea_4Label_2 = uilabel(app.UIFigure);
            app.TextArea_4Label_2.Text = '';
            app.Textmed = uitextarea(app.UIFigure);
            app.Textmed.Visible = 'off';
            app.Textbpod = uitextarea(app.UIFigure);
            app.Textbpod.Visible = 'off';
            app.Button_med = uibutton(app.UIFigure, 'push');
            app.Button_med.ButtonPushedFcn = createCallbackFcn(app, @Button_medPushed, true);
            app.Button_med.Visible = 'off';
            app.Button_med.Text = 'med文件';
            app.Button_bpod = uibutton(app.UIFigure, 'push');
            app.Button_bpod.ButtonPushedFcn = createCallbackFcn(app, @Button_bpodPushed, true);
            app.Button_bpod.Visible = 'off';
            app.Button_bpod.Text = 'bpod文件';
            app.Button_movefile = uibutton(app.UIFigure, 'push');
            app.Button_movefile.ButtonPushedFcn = createCallbackFcn(app, @Button_movefilePushed, true);
            app.Button_movefile.Visible = 'off';
            app.Button_movefile.Text = '开始分类';
            app.TextArea_7Label_2 = uilabel(app.UIFigure);
            app.TextArea_7Label_2.Visible = 'off';
            app.TextArea_7Label_2.Text = '';
            app.Textlabel_threshold = uitextarea(app.UIFigure);
            app.Textlabel_threshold.Editable = 'off';
            app.Textlabel_threshold.Visible = 'off';
            app.Textlabel_threshold.Value = {'阈值'};
            app.TextArea_7Label_3 = uilabel(app.UIFigure);
            app.TextArea_7Label_3.Visible = 'off';
            app.TextArea_7Label_3.Text = '';
            app.Text_threshold = uitextarea(app.UIFigure);
            app.Text_threshold.Editable = 'off';
            app.Text_threshold.Visible = 'off';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged2, true);
            app.ButtonGroup.Visible = 'off';
            app.Button_4 = uiradiobutton(app.ButtonGroup);
            app.Button_4.Text = '自动设定';
            app.Button_4.Value = true;
            app.Button_5 = uiradiobutton(app.ButtonGroup);
            app.Button_5.Text = '随后设定';
            app.Button_6 = uiradiobutton(app.ButtonGroup);
            app.Button_6.Text = '手动设定';
            app.Textlabel_framerate = uitextarea(app.UIFigure);
            app.Textlabel_framerate.Editable = 'off';
            app.Textlabel_framerate.Visible = 'off';
            app.Textlabel_framerate.Value = {'帧率'};
            app.Text_framerate = uitextarea(app.UIFigure);
            app.Text_framerate.Visible = 'off';
            app.Textlabel_secbefore = uitextarea(app.UIFigure);
            app.Textlabel_secbefore.Editable = 'off';
            app.Textlabel_secbefore.Visible = 'off';
            app.Textlabel_secbefore.Value = {'向前(s)'};
            app.Text_secbefore = uitextarea(app.UIFigure);
            app.Text_secbefore.Visible = 'off';
            app.Textlabel_secafter = uitextarea(app.UIFigure);
            app.Textlabel_secafter.Editable = 'off';
            app.Textlabel_secafter.Visible = 'off';
            app.Textlabel_secafter.Value = {'向后(s)'};
            app.Text_secafter = uitextarea(app.UIFigure);
            app.Text_secafter.Visible = 'off';
            app.Button_undomovefile = uibutton(app.UIFigure, 'push');
            app.Button_undomovefile.ButtonPushedFcn = createCallbackFcn(app, @Button_undomovefilePushed, true);
            app.Button_undomovefile.Visible = 'off';
            app.Button_undomovefile.Text = '撤回分类';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = make_project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end